classdef BEARapp20a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BEARinterfaceR2020aUIFigure     matlab.ui.Figure
        ImportMenu                      matlab.ui.container.Menu
        ReplicationsMenu                matlab.ui.container.Menu
        AAU2009Menu                     matlab.ui.container.Menu
        BBE2005Menu                     matlab.ui.container.Menu
        BvV2018Menu                     matlab.ui.container.Menu
        CH2019Menu                      matlab.ui.container.Menu
        WGP2016Menu                     matlab.ui.container.Menu
        FromMATfileMenu                 matlab.ui.container.Menu
        FromworkspacevariableMenu       matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        ToMATfileMenu                   matlab.ui.container.Menu
        ToworkspacevariableMenu         matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        AboutBEARMenu                   matlab.ui.container.Menu
        TabGrid                         matlab.ui.container.GridLayout
        GridLayout23                    matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        GridLayout5                     matlab.ui.container.GridLayout
        DataFrequencyDropDownLabel      matlab.ui.control.Label
        frequency_cp                    matlab.ui.control.DropDown
        GridLayout6                     matlab.ui.container.GridLayout
        EstimationStartDateEditFieldLabel  matlab.ui.control.Label
        startdate_cp                    matlab.ui.control.EditField
        EstimationEndDateEditFieldLabel  matlab.ui.control.Label
        enddate_cp                      matlab.ui.control.EditField
        GridLayout7                     matlab.ui.container.GridLayout
        EnterthelistofendogeneousvariablesTextAreaLabel  matlab.ui.control.Label
        varendo_cp                      matlab.ui.control.TextArea
        EnterthelistofexogeneousvariablesTextAreaLabel  matlab.ui.control.Label
        varexo_cp                       matlab.ui.control.TextArea
        GridLayout8                     matlab.ui.container.GridLayout
        lags_cp                         matlab.ui.control.NumericEditField
        LagsEditFieldLabel              matlab.ui.control.Label
        const_cp                        matlab.ui.control.Switch
        IncludeconstantSwitchLabel      matlab.ui.control.Label
        GridLayout9                     matlab.ui.container.GridLayout
        results                         matlab.ui.control.CheckBox
        plot                            matlab.ui.control.CheckBox
        GridLayout18                    matlab.ui.container.GridLayout
        RUNButton                       matlab.ui.control.Button
        QuickExporttoWorkspaceButton    matlab.ui.control.Button
        GridLayout24                    matlab.ui.container.GridLayout
        results_location                matlab.ui.control.EditField
        ResultsfilenameLabel            matlab.ui.control.Label
        results_sub                     matlab.ui.control.EditField
        ChangeresultsfolderButton       matlab.ui.control.Button
        GridLayout25                    matlab.ui.container.GridLayout
        SelectExcelfilewithinputsButton  matlab.ui.control.Button
        excelFile                       matlab.ui.control.EditField
        TabGroup                        matlab.ui.container.TabGroup
        SPECIFICATIONTab                matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout11                    matlab.ui.container.GridLayout
        SELECTVARTYPELabel              matlab.ui.control.Label
        VARtypeTab                      matlab.ui.container.TabGroup
        BayesianTab                     matlab.ui.container.Tab
        BayesianGrid                    matlab.ui.container.GridLayout
        Iterations                      matlab.ui.container.Panel
        GridLayout18_4                  matlab.ui.container.GridLayout
        Bu_bvar                         matlab.ui.control.NumericEditField
        It_bvar                         matlab.ui.control.NumericEditField
        NumberofburniniterationsEditFieldLabel_4  matlab.ui.control.Label
        TotalnumberofiterationsEditFieldLabel_4  matlab.ui.control.Label
        OptionsPanel                    matlab.ui.container.Panel
        GridLayout17_5                  matlab.ui.container.GridLayout
        GridsearchonexcelSwitchLabel    matlab.ui.control.Label
        hogs_bvar                       matlab.ui.control.Switch
        BlockexogeneityonexcelSwitchLabel  matlab.ui.control.Label
        bex_bvar                        matlab.ui.control.Switch
        DummyobservationextensionsLabel  matlab.ui.control.Label
        scoeff_bvar                     matlab.ui.control.CheckBox
        iobs_bvar                       matlab.ui.control.CheckBox
        lrp_bvar                        matlab.ui.control.CheckBox
        HyperparametersPanel_3          matlab.ui.container.Panel
        GridLayout17_6                  matlab.ui.container.GridLayout
        lambda2_bvar                    matlab.ui.control.NumericEditField
        lambda1_bvar                    matlab.ui.control.NumericEditField
        ar_bvar                         matlab.ui.control.NumericEditField
        ARcoefficientonresidualvarianceEditFieldLabel_6  matlab.ui.control.Label
        IGscaleonresidualvarianceEditFieldLabel_6  matlab.ui.control.Label
        IGshapeonresidualvarianceEditFieldLabel_6  matlab.ui.control.Label
        LagdecayEditField_2Label        matlab.ui.control.Label
        lambda3_bvar                    matlab.ui.control.NumericEditField
        PriorExcel_bvar                 matlab.ui.control.CheckBox
        ExogenousvariableEditFieldLabel  matlab.ui.control.Label
        lambda4_bvar                    matlab.ui.control.NumericEditField
        BlockexogenetyshrinkageEditFieldLabel  matlab.ui.control.Label
        lambda5_bvar                    matlab.ui.control.NumericEditField
        SumofcoefficienttightnessEditFieldLabel  matlab.ui.control.Label
        lambda6_bvar                    matlab.ui.control.NumericEditField
        DummyinitialobservationtightnessLabel  matlab.ui.control.Label
        lambda7_bvar                    matlab.ui.control.NumericEditField
        LongrunpriortightnessEditFieldLabel  matlab.ui.control.Label
        lambda8_bvar                    matlab.ui.control.NumericEditField
        ExogenousvariablesSwitchLabel   matlab.ui.control.Label
        priorsexogenous_bvar            matlab.ui.control.Switch
        prior                           matlab.ui.container.ButtonGroup
        MinnesotaButton                 matlab.ui.control.RadioButton
        NormalDiffuseButton             matlab.ui.control.RadioButton
        DummyObservationsButton         matlab.ui.control.RadioButton
        NormalWishartButton             matlab.ui.control.RadioButton
        IndependentNormalWishartButton  matlab.ui.control.RadioButton
        DeterministicButton             matlab.ui.control.RadioButton
        FAVAROptionsPanel               matlab.ui.container.Panel
        FAVARCheckBox2                  matlab.ui.control.CheckBox
        FAVAROptionsButton2             matlab.ui.control.Button
        OLSTab                          matlab.ui.container.Tab
        FAVARCheckBox1                  matlab.ui.control.CheckBox
        FAVAROptionsButton1             matlab.ui.control.Button
        PanelTab                        matlab.ui.container.Tab
        PanelGrid                       matlab.ui.container.GridLayout
        HyperparametersPanelpanel       matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        v0_panel                        matlab.ui.control.NumericEditField
        s0_panel                        matlab.ui.control.NumericEditField
        lambda4_panel                   matlab.ui.control.NumericEditField
        lambda3_panel                   matlab.ui.control.NumericEditField
        lambda2_panel                   matlab.ui.control.NumericEditField
        lambda1_panel                   matlab.ui.control.NumericEditField
        ar_panel                        matlab.ui.control.NumericEditField
        IGscaleonoveralltightnessEditFieldLabel  matlab.ui.control.Label
        IGshapeonoveralltightnessEditFieldLabel  matlab.ui.control.Label
        ExogenousvariabletightnessEditFieldLabel  matlab.ui.control.Label
        LagdecayEditFieldLabel          matlab.ui.control.Label
        CrossvariableweightingEditFieldLabel  matlab.ui.control.Label
        OveralltightnessEditFieldLabel  matlab.ui.control.Label
        AutoregressivecoefficientEditFieldLabel  matlab.ui.control.Label
        HyperparametersPanel            matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        psi_panel                       matlab.ui.control.NumericEditField
        rho_panel                       matlab.ui.control.NumericEditField
        b0_panel                        matlab.ui.control.NumericEditField
        a0_panel                        matlab.ui.control.NumericEditField
        gamma_panel                     matlab.ui.control.NumericEditField
        delta0_panel                    matlab.ui.control.NumericEditField
        alpha0_panel                    matlab.ui.control.NumericEditField
        VarianceonMetropolisdrawEditFieldLabel  matlab.ui.control.Label
        ARcoefficientonfactorEditFieldLabel  matlab.ui.control.Label
        IGscaleonfactorvarianceEditFieldLabel  matlab.ui.control.Label
        IGshapeonfactorvarianceEditFieldLabel  matlab.ui.control.Label
        ARcoefficientonresidualvarianceEditFieldLabel  matlab.ui.control.Label
        IGscaleonresidualvarianceEditFieldLabel  matlab.ui.control.Label
        IGshapeonresidualvarianceEditFieldLabel  matlab.ui.control.Label
        EnterlistofunitsPanel           matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        unitnames_panel                 matlab.ui.control.EditField
        Iterations_2                    matlab.ui.container.Panel
        GridLayout18_5                  matlab.ui.container.GridLayout
        Bu_panel                        matlab.ui.control.NumericEditField
        It_panel                        matlab.ui.control.NumericEditField
        NumberofburniniterationsEditFieldLabel_5  matlab.ui.control.Label
        TotalnumberofiterationsEditFieldLabel_5  matlab.ui.control.Label
        panel                           matlab.ui.container.ButtonGroup
        MeangroupEstimatorButton        matlab.ui.control.RadioButton
        RandomEffectZellnerHongButton   matlab.ui.control.RadioButton
        StaticStructureFactorButton     matlab.ui.control.RadioButton
        PooledEstimatorButton           matlab.ui.control.RadioButton
        RandomEffectHierarchicalButton  matlab.ui.control.RadioButton
        DynamicStructureFactorButton    matlab.ui.control.RadioButton
        StochasticVolatilityTab         matlab.ui.container.Tab
        SVGrid                          matlab.ui.container.GridLayout
        HyperparametersstochasticPanel  matlab.ui.container.Panel
        GridLayout17_3                  matlab.ui.container.GridLayout
        zeta0_sv                        matlab.ui.control.NumericEditField
        gamma0_sv                       matlab.ui.control.NumericEditField
        delta0_sv                       matlab.ui.control.NumericEditField
        alpha0_sv                       matlab.ui.control.NumericEditField
        gamma_sv                        matlab.ui.control.NumericEditField
        IGscaleonfactorvarianceEditFieldLabel_3  matlab.ui.control.Label
        IGshapeonfactorvarianceEditFieldLabel_3  matlab.ui.control.Label
        ARcoefficientonresidualvarianceEditFieldLabel_3  matlab.ui.control.Label
        IGscaleonresidualvarianceEditFieldLabel_3  matlab.ui.control.Label
        IGshapeonresidualvarianceEditFieldLabel_3  matlab.ui.control.Label
        alltirf_sv                      matlab.ui.control.CheckBox
        stvol                           matlab.ui.container.ButtonGroup
        StandardButton                  matlab.ui.control.RadioButton
        RandomInertiaButton             matlab.ui.control.RadioButton
        LargeVARButton                  matlab.ui.control.RadioButton
        StochasticButton                matlab.ui.control.RadioButton
        Iterations_3                    matlab.ui.container.Panel
        GridLayout18_6                  matlab.ui.container.GridLayout
        Bu_sv                           matlab.ui.control.NumericEditField
        It_sv                           matlab.ui.control.NumericEditField
        NumberofburniniterationsEditFieldLabel_6  matlab.ui.control.Label
        TotalnumberofiterationsEditFieldLabel_6  matlab.ui.control.Label
        HyperparametersPanel_4          matlab.ui.container.Panel
        GridLayout17_7                  matlab.ui.container.GridLayout
        lambda2_sv                      matlab.ui.control.NumericEditField
        lambda1_sv                      matlab.ui.control.NumericEditField
        ar_sv                           matlab.ui.control.NumericEditField
        ARcoefficientonresidualvarianceEditFieldLabel_7  matlab.ui.control.Label
        IGscaleonresidualvarianceEditFieldLabel_7  matlab.ui.control.Label
        IGshapeonresidualvarianceEditFieldLabel_7  matlab.ui.control.Label
        LagdecayEditField_2Label_2      matlab.ui.control.Label
        lambda3_sv                      matlab.ui.control.NumericEditField
        PriorExcel_sv                   matlab.ui.control.CheckBox
        ExogenousvariableEditFieldLabel_2  matlab.ui.control.Label
        lambda4_sv                      matlab.ui.control.NumericEditField
        BlockexogenetyshrinkageEditFieldLabel_2  matlab.ui.control.Label
        lambda5_sv                      matlab.ui.control.NumericEditField
        ExogenousvariablesSwitchLabel_2  matlab.ui.control.Label
        priorsexogenous_sv              matlab.ui.control.Switch
        FAVAROptionsSV                  matlab.ui.container.Panel
        FAVARCheckBox5                  matlab.ui.control.CheckBox
        FAVAROptionsButton5             matlab.ui.control.Button
        TimeVaryingTab                  matlab.ui.container.Tab
        TVPGrid                         matlab.ui.container.GridLayout
        HyperparameterstimevaryingPanel  matlab.ui.container.Panel
        GridLayout17_2                  matlab.ui.container.GridLayout
        delta0_tvp                      matlab.ui.control.NumericEditField
        alpha0_tvp                      matlab.ui.control.NumericEditField
        gamma_tvp                       matlab.ui.control.NumericEditField
        ARcoefficientonresidualvarianceEditFieldLabel_2  matlab.ui.control.Label
        IGscaleonresidualvarianceEditFieldLabel_2  matlab.ui.control.Label
        IGshapeonresidualvarianceEditFieldLabel_2  matlab.ui.control.Label
        alltirf_tvp                     matlab.ui.control.CheckBox
        tvbvar                          matlab.ui.container.ButtonGroup
        VARcoefficientsButton           matlab.ui.control.RadioButton
        GeneralButton                   matlab.ui.control.RadioButton
        Iterations_4                    matlab.ui.container.Panel
        GridLayout18_7                  matlab.ui.container.GridLayout
        Bu_tvp                          matlab.ui.control.NumericEditField
        It_tvp                          matlab.ui.control.NumericEditField
        NumberofburniniterationsEditFieldLabel_7  matlab.ui.control.Label
        TotalnumberofiterationsEditFieldLabel_7  matlab.ui.control.Label
        FAVAROptionsTVP                 matlab.ui.container.Panel
        FAVARCheckBox6                  matlab.ui.control.CheckBox
        FAVAROptionsButton6             matlab.ui.control.Button
        MixedFrequencyTab               matlab.ui.container.Tab
        GridLayout26                    matlab.ui.container.GridLayout
        Iterations_5                    matlab.ui.container.Panel
        GridLayout18_8                  matlab.ui.container.GridLayout
        Bu_mfavar                       matlab.ui.control.NumericEditField
        It_mfavar                       matlab.ui.control.NumericEditField
        NumberofburniniterationsEditFieldLabel_8  matlab.ui.control.Label
        TotalnumberofiterationsEditFieldLabel_8  matlab.ui.control.Label
        OptionsPanel_2                  matlab.ui.container.Panel
        GridLayout17_8                  matlab.ui.container.GridLayout
        GridsearchonexcelSwitchLabel_2  matlab.ui.control.Label
        hogs_mfavar                     matlab.ui.control.Switch
        BlockexogeneityonexcelSwitchLabel_2  matlab.ui.control.Label
        bex_mfavar                      matlab.ui.control.Switch
        DummyobservationextensionsLabel_2  matlab.ui.control.Label
        scoeff_mfavar                   matlab.ui.control.CheckBox
        iobs_mfavar                     matlab.ui.control.CheckBox
        lrp_mfavar                      matlab.ui.control.CheckBox
        HyperparametersPanel_5          matlab.ui.container.Panel
        GridLayout17_9                  matlab.ui.container.GridLayout
        lambda2_mfavar                  matlab.ui.control.NumericEditField
        lambda1_mfavar                  matlab.ui.control.NumericEditField
        ar_mfavar                       matlab.ui.control.NumericEditField
        ARcoefficientonresidualvarianceEditFieldLabel_8  matlab.ui.control.Label
        IGscaleonresidualvarianceEditFieldLabel_8  matlab.ui.control.Label
        IGshapeonresidualvarianceEditFieldLabel_8  matlab.ui.control.Label
        LagdecayEditField_2Label_3      matlab.ui.control.Label
        lambda3_mfavar                  matlab.ui.control.NumericEditField
        ExogenousvariableEditFieldLabel_3  matlab.ui.control.Label
        lambda4_mfavar                  matlab.ui.control.NumericEditField
        BlockexogenetyshrinkageEditFieldLabel_3  matlab.ui.control.Label
        lambda5_mfavar                  matlab.ui.control.NumericEditField
        APPLICATIONSTab                 matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        Applicationoptions              matlab.ui.container.Panel
        GridLayout22                    matlab.ui.container.GridLayout
        FEVD_cp                         matlab.ui.control.Switch
        ForecasterrorvarianceSwitchLabel  matlab.ui.control.Label
        CF_cp                           matlab.ui.control.Switch
        ConditionalforecastsSwitchLabel  matlab.ui.control.Label
        HD_cp                           matlab.ui.control.Switch
        HistoricaldecompositionsSwitchLabel  matlab.ui.control.Label
        F_cp                            matlab.ui.control.Switch
        UnconditionalforecastsSwitchLabel  matlab.ui.control.Label
        IRF_cp                          matlab.ui.control.Switch
        ImpulseresponsefunctionsSwitchLabel  matlab.ui.control.Label
        Periodoptions                   matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        cband_cp                        matlab.ui.control.NumericEditField
        VARcoefficientsEditFieldLabel   matlab.ui.control.Label
        Fstartdate_cp                   matlab.ui.control.EditField
        ForecastsStartdateEditFieldLabel  matlab.ui.control.Label
        Fendsmpl_cp                     matlab.ui.control.Switch
        ForecastsafterlastsampleperiodSwitchLabel  matlab.ui.control.Label
        IRFperiods_cp                   matlab.ui.control.NumericEditField
        IRFperiodsEditFieldLabel        matlab.ui.control.Label
        Fenddate_cp                     matlab.ui.control.EditField
        ForecastsEnddateEditFieldLabel  matlab.ui.control.Label
        CFt                             matlab.ui.container.ButtonGroup
        Standardallshocks               matlab.ui.control.RadioButton
        Standardshockspecific           matlab.ui.control.RadioButton
        Tiltingmedian                   matlab.ui.control.RadioButton
        Tiltinginterval                 matlab.ui.control.RadioButton
        Estimationoptions               matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        evaluation_size_cp              matlab.ui.control.NumericEditField
        EvaluationSizeEditFieldLabel    matlab.ui.control.Label
        window_size_cp                  matlab.ui.control.NumericEditField
        RollingWindow0forfullsampleEditFieldLabel  matlab.ui.control.Label
        hstep_cp                        matlab.ui.control.NumericEditField
        ForecaststepaheadevaluationsEditFieldLabel  matlab.ui.control.Label
        Feval_cp                        matlab.ui.control.Switch
        ForecastevaluationsSwitchLabel  matlab.ui.control.Label
        ProxyVars                       matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        CorrelInstrument                matlab.ui.control.EditField
        CorrelInstrumentLabel           matlab.ui.control.Label
        CorrelShock                     matlab.ui.control.EditField
        CorrelShockLabel                matlab.ui.control.Label
        prior_type_proxy                matlab.ui.control.Switch
        HighrelevancepriorLabel         matlab.ui.control.Label
        prior_type_reduced_form         matlab.ui.control.Switch
        FlatreducedformpriorLabel       matlab.ui.control.Label
        Instrument                      matlab.ui.control.EditField
        enddateIV                       matlab.ui.control.EditField
        InstrumentEnddateLabel          matlab.ui.control.Label
        startdateIV                     matlab.ui.control.EditField
        InstrumentstartdateLabel        matlab.ui.control.Label
        InstrumentLabel                 matlab.ui.control.Label
        IRFt                            matlab.ui.container.ButtonGroup
        Noidentification                matlab.ui.control.RadioButton
        Choleskifactorisation           matlab.ui.control.RadioButton
        Triangularfactorisation         matlab.ui.control.RadioButton
        Signrestrictions                matlab.ui.control.RadioButton
        Proxy                           matlab.ui.control.RadioButton
        Proxysign                       matlab.ui.control.RadioButton
    end


    properties (Access = private)
        opts (1,1) bear.app.optionsProxyForApp % BEAR settings object
        favarSettingsApp                       % Favar Settings dialog box
    end

    methods (Access = private)

        function changeAppAuto(app, se, cue)
            % Gett app properties that need to change
            mcls = meta.class.fromName(mfilename);

            idx = contains({mcls.PropertyList.Name}, cue);
            prop = mcls.PropertyList(idx);
            prop = {prop.Name};

            for i = 1 : length(prop)

                pname = extractBefore(prop{i}, cue);
                try
                    app.(prop{i}).Value = se.(pname);
                catch
                end

            end

            switch se.VARtype

                case 2

                    switch se.prior
                        case "Minnesota_univariate"
                            app.prior.SelectedObject = app.MinnesotaButton;
                        case "Nd"
                            app.prior.SelectedObject = app.NormalDiffuseButton;
                        case "Dummy"
                            app.prior.SelectedObject = app.DummyObservationsButton;
                        case "Nw_univariate"
                            app.prior.SelectedObject = app.NormalWishartButton;
                        case "Inw_univariate"
                            app.prior.SelectedObject = app.IndependentNormalWishartButton;
                        case "Madj"
                            app.prior.SelectedObject = app.DeterministicButton;
                    end
                    app.priorChanged(app.prior.SelectedObject.Text)

                case 4

                    switch se.panel

                        case "Mge"
                            app.panel.SelectedObject = app.MeangroupEstimatorButton;
                        case "Random_zh"
                            app.panel.SelectedObject = app.RandomEffectZellnerHongButton;
                        case "Factor_static"
                            app.panel.SelectedObject = app.StaticStructureFactorButton;
                        case "Pooled"
                            app.panel.SelectedObject = app.PooledEstimatorButton;
                        case "Random_hierarchical"
                            app.panel.SelectedObject = app.RandomEffectHierarchicalButton;
                        case "Factor_dynamic"
                            app.panel.SelectedObject = app.DynamicStructureFactorButton;
                    end
                    app.panelChanged(app.panel.SelectedObject.Text)

                case 5

                    switch se.stvol

                        case "Standard"
                            app.stvol.SelectedObject = app.StandardButton;
                        case "Random_scaling"
                            app.stvol.SelectedObject = app.RandomInertiaButton;
                        case "Large_BVAR"
                            app.stvol.SelectedObject = app.LargeVARButton;
                        case "LMM"
                            app.stvol.SelectedObject = app.StochasticButton;
                    end
                    app.stvolChanged(app.stvol.SelectedObject.Text)

                case 6

                    switch se.tvbvar
                        case 'TVP'
                            app.tvbvar.SelectedObject = app.VARcoefficientsButton;
                        case 'TVP_SV'
                            app.tvbvar.SelectedObject = app.GeneralButton;
                    end
                    app.tvpChanged(app.tvbvar.SelectedObject.Text)

            end

        end

        function loadCommonProps(app, s)

            % Set common properties
            app.changeAppAuto(s, '_cp')

            % set radio buttons
            switch s.CFt

                case 'Standard_all'
                    app.CFt.SelectedObject = app.Standardallshocks;
                case 'Standard_specific'
                    app.CFt.SelectedObject = app.Standardshockspecific;
                case 'Tilting_median'
                    app.CFt.SelectedObject = app.Tiltingmedian;
                case 'Tilting_interval'
                    app.CFt.SelectedObject = app.Tiltinginterval;
            end

            switch s.IRFt

                case 'Reduced'
                    app.IRFt.SelectedObject = app.Noidentification;
                case 'Cholesky'
                    app.IRFt.SelectedObject = app.Choleskifactorisation;
                case 'Triangular'
                    app.IRFt.SelectedObject = app.Triangularfactorisation;
                case 'Sign'
                    app.IRFt.SelectedObject = app.Signrestrictions;
                case 'IV'
                    app.IRFt.SelectedObject = app.Proxy;
                case 'IV_Sign'
                    app.IRFt.SelectedObject = app.Proxysign;
            end

            % Set preferences
            app.excelFile.Value    = s.excelFile;
            app.results_location.Value = fullfile(app.opts.opts.results_path, app.opts.opts.results_sub + ".xlsx");
            app.results_sub.Value  = s.results_sub;
            app.results.Value      = s.results;
            app.plot.Value         = s.plot;

            % Structident
            if isprop(s,'strctident')

                prop = properties(s.strctident);
                for i = 1 : length(prop)
                    try
                        app.(prop{i}).Value = s.strctident.(prop{i});
                    catch
                    end
                end

            end

            % callbacks
            app.lambda6_bvar.Enable = app.scoeff_bvar.Value;
            app.lambda7_bvar.Enable = app.iobs_bvar.Value;
            app.lambda8_bvar.Enable = app.lrp_bvar.Value;
        end

        function loadSpecificOpts(app, s)

            switch s.VARtype
                case 1
                    app.VARtypeTab.SelectedTab = app.OLSTab;
                case 2
                    app.changeAppAuto(s, '_bvar' )
                    app.VARtypeTab.SelectedTab = app.BayesianTab;
                case 4
                    app.changeAppAuto(s, '_panel' )
                    app.VARtypeTab.SelectedTab = app.PanelTab;
                case 5
                    app.changeAppAuto(s, '_sv')
                    app.VARtypeTab.SelectedTab = app.StochasticVolatilityTab;
                case 6
                    app.changeAppAuto(s, '_tvp')
                    app.VARtypeTab.SelectedTab = app.TimeVaryingTab;
                case 7
                    app.changeAppAuto(s, '_mfavar')
                    app.VARtypeTab.SelectedTab = app.MixedFrequencyTab;
            end
            
            if isprop(s, 'favar')
                if ~isa(app.opts.opts.favar, 'bear.settings.favar.NullFAVAR')
                    app.("FAVARCheckBox" + double(app.opts.VARtype)).Value = app.opts.opts.favar.FAVAR;
                    app.favarChanged(double(app.opts.VARtype), app.opts.opts.favar.FAVAR)
                end
            end

        end
        
        function priorChanged(app, value)
            
            switch value
                case "Minnesota"
                    pr = bear.PRIORtype('Minnesota_univariate');
                    app.FAVAROptionsPanel.Visible = 'on';
                case "Normal Diffuse"
                    pr = bear.PRIORtype('Nd');
                    app.FAVAROptionsPanel.Visible = 'on';
                case "Dummy Observations"
                    pr = bear.PRIORtype('Dummy');
                    app.FAVAROptionsPanel.Visible = 'on';
                case "Normal Wishart"
                    pr = bear.PRIORtype('Nw_univariate');
                    app.FAVAROptionsPanel.Visible = 'on';
                case "Independent Normal Wishart"
                    pr = bear.PRIORtype('Inw_univariate');
                    app.FAVAROptionsPanel.Visible = 'on';
                case "Deterministic"
                    pr = bear.PRIORtype('Madj');
                    app.FAVAROptionsPanel.Visible = 'off';
            end
            app.opts.setProp('prior', pr);
        end
        
        
        function stvolChanged(app, value)
            
             switch value
                case "Standard"
                    app.HyperparametersstochasticPanel.Visible = 'on';
                    app.IGshapeonresidualvarianceEditFieldLabel_3.Enable = 'on';
                    app.gamma_sv.Enable = 'on';
                    app.IGscaleonfactorvarianceEditFieldLabel_3.Enable = 'off';
                    app.zeta0_sv.Enable = 'off';
                    app.IGshapeonfactorvarianceEditFieldLabel_3.Enable = 'off';
                    app.gamma0_sv.Enable = 'off';
                    app.FAVAROptionsSV.Visible = 'on';
                    app.opts.setProp('stvol', 'Standard');
                case "Random Inertia"
                    app.HyperparametersstochasticPanel.Visible = 'on';
                    app.IGshapeonresidualvarianceEditFieldLabel_3.Enable = 'off';
                    app.gamma_sv.Enable = 'off';
                    app.IGscaleonfactorvarianceEditFieldLabel_3.Enable = 'on';
                    app.zeta0_sv.Enable = 'on';
                    app.IGshapeonfactorvarianceEditFieldLabel_3.Enable = 'on';
                    app.gamma0_sv.Enable = 'on';
                    app.FAVAROptionsSV.Visible = 'on';
                    app.opts.setProp('stvol', 'Random_scaling');
                case "Large VAR"
                    app.HyperparametersstochasticPanel.Visible = 'on';
                    app.IGshapeonresidualvarianceEditFieldLabel_3.Enable = 'on';
                    app.gamma_sv.Enable = 'on';
                    app.IGscaleonfactorvarianceEditFieldLabel_3.Enable = 'off';
                    app.zeta0_sv.Enable = 'off';
                    app.IGshapeonfactorvarianceEditFieldLabel_3.Enable = 'off';
                    app.gamma0_sv.Enable = 'off';
                    app.FAVAROptionsSV.Visible = 'on';
                    app.opts.setProp('stvol', 'Large_BVAR');
                case "Stochastic"
                    app.HyperparametersstochasticPanel.Visible = 'off';
                    app.IGscaleonfactorvarianceEditFieldLabel_3.Enable = 'on';
                    app.zeta0_sv.Enable = 'on';
                    app.IGshapeonfactorvarianceEditFieldLabel_3.Enable = 'on';
                    app.gamma0_sv.Enable = 'on';                    
                    app.FAVAROptionsSV.Visible = 'off';
                    app.opts.setProp('stvol', 'LMM');
            end
            
        end
        
        function tvpChanged(app, value)           
            
            switch value
                case "General"
                    app.HyperparameterstimevaryingPanel.Visible = 'on';
                    app.opts.setProp('tvbvar', 'TVP_SV');
                case "VAR coefficients"
                    app.HyperparameterstimevaryingPanel.Visible = 'off';
                    app.opts.setProp('tvbvar', 'TVP');
            end
            
        end
        
        function panelChanged(app, value)
            switch value
                case "Mean group Estimator"
                    pn = bear.PANELtype('Mge');
                case "Random Effect (Zellner-Hong)"
                    pn = bear.PANELtype('Random_zh');
                case "Static Structure Factor"
                    pn = bear.PANELtype('Factor_static');
                case "Pooled Estimator"
                    pn = bear.PANELtype('Pooled');
                case "Random Effect (Hierarchical)"
                    pn = bear.PANELtype('Random_hierarchical');
                case "Dynamic Structure Factor"
                    pn = bear.PANELtype('Factor_dynamic');
            end
            app.opts.setProp('panel', pn);
        end
        
        function favarChanged(app, vartype, value)            
            app.("FAVAROptionsButton" + vartype).Enable = value;
            app.opts.opts.favar.FAVAR = value;
        end
    end
    
    methods (Access = public)

        function setProp(app, prop, newValue)
            app.opts.setProp(prop, newValue);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            if isdeployed
                app.QuickExporttoWorkspaceButton.Enable = 'off';
                app.FromworkspacevariableMenu.Enable = 'off';
                app.ToworkspacevariableMenu.Enable = 'off';
            end

            app.results_location.Value = fullfile(app.opts.opts.results_path, app.opts.opts.results_sub + ".xlsx");

        end

        % Selection changed function: tvbvar
        function tvbvarSelectionChanged(app, event)
            selectedButton = app.tvbvar.SelectedObject;
            app.tvpChanged(selectedButton.Text)
        end

        % Selection changed function: stvol
        function stvolSelectionChanged(app, event)
            selectedButton = app.stvol.SelectedObject;
            app.stvolChanged(selectedButton.Text)           
        end

        % Selection change function: VARtypeTab
        function VARtypeTabSelectionChanged(app, event)
            selectedTab = app.VARtypeTab.SelectedTab;
            switch selectedTab.Title
                case "OLS"
                    app.opts.VARtype = 1;
                case "Bayesian"
                    app.opts.VARtype = 2;
                case "Panel"
                    app.opts.VARtype = 4;
                case "Stochastic Volatility"
                    app.opts.VARtype = 5;
                case "Time Varying"
                    app.opts.VARtype = 6;
                case "Mixed Frequency"
                    app.opts.VARtype = 7;
            end
            app.loadSpecificOpts(app.opts.opts);
            app.loadCommonProps(app.opts.opts)
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            try
                d = uiprogressdlg(app.BEARinterfaceR2020aUIFigure, ...
                    'Message','Running BEAR', ...
                    'Indeterminate', 'on');
                BEARmain(app.opts.opts)
                delete(d);
            catch e
                uialert(app.BEARinterfaceR2020aUIFigure, e.message, 'Something went wrong!')
            end
        end

        % Callback function
        function EstimationEndDateDatePicker_2ValueChanged(app, event)
            value = app.EstimationEndDateDatePicker_2.Value;
            app.opts.setCommonProp('enddate', strrep(char(value),'Q','q'));
        end

        % Button pushed function: QuickExporttoWorkspaceButton
        function QuickExporttoWorkspaceButtonPushed(app, event)
            assignin("base", 'opts', app.opts.opts)
        end

        % Button pushed function: SelectExcelfilewithinputsButton
        function SelectExcelfilewithinputsButtonPushed(app, event)
            [file,path] = uigetfile('*.xlsx');
            if ~isequal(file,0)
                app.excelFile.Value = fullfile(path,file);
                app.opts.setCommonProp('excelFile', fullfile(path,file));
            end
        end

        % Value changed function: CF_cp, FEVD_cp, F_cp, 
        % Fenddate_cp, Fendsmpl_cp, Fstartdate_cp, HD_cp, IRF_cp, 
        % IRFperiods_cp, cband_cp, const_cp, enddate_cp, 
        % evaluation_size_cp, frequency_cp, hstep_cp, lags_cp, 
        % plot, results, startdate_cp, varendo_cp, varexo_cp, 
        % window_size_cp
        function setCommonProp(app, event)
            if isa(event.Source, 'matlab.ui.control.TextArea')
                app.opts.setCommonProp(event.Source.Tag, event.Value{1});
            else
                app.opts.setCommonProp(event.Source.Tag, event.Value);
            end
        end

        % Selection changed function: prior
        function priorSelectionChanged(app, event)
            app.priorChanged(event.NewValue.Text)
        end

        % Selection changed function: panel
        function panelSelectionChanged(app, event)
            app.panelChanged(event.NewValue.Text)            
        end

        % Value changed function: Bu_bvar, Bu_panel, Bu_sv, Bu_tvp, 
        % It_bvar, It_panel, It_sv, It_tvp, PriorExcel_bvar, 
        % PriorExcel_sv, a0_panel, alltirf_sv, alltirf_tvp, 
        % alpha0_panel, alpha0_sv, alpha0_tvp, ar_bvar, ar_mfavar, 
        % ar_panel, b0_panel, bex_bvar, bex_mfavar, delta0_panel, 
        % delta0_sv, delta0_tvp, gamma0_sv, gamma_panel, gamma_sv, 
        % gamma_tvp, hogs_bvar, hogs_mfavar, iobs_mfavar, 
        % lambda1_bvar, lambda1_mfavar, lambda1_panel, lambda1_sv, 
        % lambda2_bvar, lambda2_mfavar, lambda2_panel, lambda2_sv, 
        % lambda3_bvar, lambda3_mfavar, lambda3_panel, lambda3_sv, 
        % lambda4_bvar, lambda4_mfavar, lambda4_panel, lambda4_sv, 
        % lambda5_bvar, lambda5_mfavar, lambda5_sv, lambda6_bvar, 
        % lambda7_bvar, lambda8_bvar, lrp_mfavar, 
        % priorsexogenous_bvar, priorsexogenous_sv, psi_panel, 
        % rho_panel, s0_panel, scoeff_mfavar, unitnames_panel, 
        % v0_panel, zeta0_sv
        function setPropCallback(app, event)
            try
                app.setProp(event.Source.Tag, event.Source.Value)
            catch e
                app.opts.opts.(event.Source.Tag) = event.PreviousValue;
                event.Source.Value = event.PreviousValue;
                uialert(app.BEARinterfaceR2020aUIFigure, e.message, "Something went wrong!")
            end
        end

        % Selection changed function: CFt
        function CFtSelectionChanged(app, event)
            switch event.NewValue.Text
                case "Standard (all shocks)"
                    ct = bear.CFtype('Standard_all');
                case "Standard (shock specific)"
                    ct = bear.CFtype('Standard_specific');
                case "Titling (median)"
                    ct = bear.CFtype('Tilting_median');
                case "Titling (interval)"
                    ct = bear.CFtype('Tilting_interval');
            end
            app.opts.setCommonProp('CFt', ct);
        end

        % Selection changed function: IRFt
        function IRFtSelectionChanged(app, event)
            switch event.NewValue.Text
                case "None"
                    ct = bear.IRFtype('Reduced');
                case "Triangular"
                    ct = bear.IRFtype('Triangular');
                case "Cholesky"
                    ct = bear.IRFtype('Cholesky');
                case "Sign restrictions"
                    ct = bear.IRFtype('Sign');
                case "Proxy SVAR"
                    ct = bear.IRFtype('IV');
                case "Sign and proxy"
                    ct = bear.IRFtype('IV_Sign');
            end
            
            try
                app.opts.setCommonProp('IRFt', ct);
                if isprop(app.opts.opts, 'strctident')
                    for p = properties(app.opts.opts.strctident)'
                        prop = p{1};
                        try
                            app.opts.opts.strctident.(prop) = app.(prop).Value;
                        catch
                        end

                    end
                end
            catch ME
                app.IRFt.SelectedObject = event.OldValue;
                uialert(app.BEARinterfaceR2020aUIFigure, ME.message, 'Wrong setting')
            end
        end

        % Value changed function: CorrelInstrument, CorrelShock, 
        % Instrument, enddateIV, prior_type_proxy, 
        % prior_type_reduced_form, startdateIV
        function StructidentValueChanged(app, event)
            if isprop(app.opts.opts, 'strctident') && ~isempty(app.opts.opts.strctident)
                strctident = app.opts.opts.strctident;
                strctident.(event.Source.Tag) = event.Value;
                app.opts.setCommonProp('strctident', strctident);
            end
        end

        % Value changed function: scoeff_bvar
        function scoeff_bvarValueChanged(app, event)
            value = app.scoeff_bvar.Value;
            app.lambda6_bvar.Enable = value;
            app.opts.setProp(event.Source.Tag, value)
        end

        % Value changed function: iobs_bvar
        function iobs_bvarValueChanged(app, event)
            value = app.iobs_bvar.Value;
            app.lambda7_bvar.Enable = value;
            app.opts.setProp(event.Source.Tag, value)
        end

        % Value changed function: lrp_bvar
        function lrp_bvarValueChanged(app, event)
            value = app.lrp_bvar.Value;
            app.lambda8_bvar.Enable = value;
            app.opts.setProp(event.Source.Tag, value)
        end

        % Menu selected function: AboutBEARMenu
        function AboutBEARMenuSelected(app, event)
            bear.app.About2020a();
        end

        % Menu selected function: ToMATfileMenu
        function ToMATfileMenuSelected(app, event)
            [file,path] = uiputfile('appsettings.mat');

            if ~isequal(file,0)
                opts = app.opts.opts; %#ok<ADPROPLC>
                save(fullfile(path, file), 'opts')
            end

        end

        % Menu selected function: FromMATfileMenu
        function FromMATfileMenuSelected(app, event)

            [file,path] = uigetfile('*.mat');

            if ~isequal(file,0)
                % Load previous session
                se = load(fullfile(path,file));

                try
                    se = se.opts;
                    if ~isa(se, 'bear.app.optionsProxyForApp') && ~isa(se, 'bear.settings.BASEsettings')
                        error('BEARapp:InvalidSession','invalid opts type')
                    end
                catch
                    uialert(app.BEARinterfaceR2020aUIFigure, ...
                        'The .mat file needs to contain a single variable with an app session or a BEAR settings object named "opts"', ...
                        'Incorrect Variable Type');
                    return
                end
                if isa(se, 'bear.app.optionsProxyForApp')
                    app.opts = se;


                    app.loadSpecificOpts(se.opts)
                    app.loadCommonProps(se.opts)

                    % Change properties based on proxy object
                    pr = ["_bvar", "_panel", "_sv", "_tvp"];
                    vt = [2 4 5 6];

                    for k = 1 : length(vt)
                        se.VARtype = vt(k);
                        changeAppAuto(app, se.opts, pr(k))
                    end
                else
                    app.opts.opts = se;

                    app.loadSpecificOpts(se)
                    app.loadCommonProps(se)
                end
            end
        end

        % Menu selected function: ToworkspacevariableMenu
        function ToworkspacevariableMenuSelected(app, event)
            prompt = {'Please select the settings name'};
            dlgtitle = 'Export BEAR settings';
            definput = {'opts'};
            dims = [1 40];
            varname = inputdlg(prompt,dlgtitle,dims,definput);

            if ~isempty(varname)
                assignin("base", varname{1}, app.opts.opts)
            end
        end

        % Menu selected function: AAU2009Menu, BBE2005Menu, 
        % BvV2018Menu, CH2019Menu, WGP2016Menu
        function loadReplication(app, event)
            s = getReplicationSettings(event.Source.Text);
            app.opts.opts = s;
            app.loadSpecificOpts(s);
            app.loadCommonProps(s)

        end

        % Menu selected function: FromworkspacevariableMenu
        function FromworkspacevariableMenuSelected(app, event)
            prompt = {'Please specify the name of the variable'};
            dlgtitle = 'Save BEAR session';
            definput = {'opts'};
            dims = [1 40];
            s = inputdlg(prompt,dlgtitle,dims,definput);
            if ~isempty(s)
                s = evalin('base', s{1});
                app.opts.opts = s;
                app.loadSpecificOpts(s);
                app.loadCommonProps(s)
            end
        end

        % Value changed function: results_sub
        function results_subValueChanged(app, event)
            value = app.results_sub.Value;
            app.opts.setCommonProp('results_sub', value);
            app.results_location.Value = fullfile(app.opts.opts.results_path, value + ".xlsx");
        end

        % Value changed function: FAVARCheckBox1
        function FAVARCheckBox1ValueChanged(app, event)
            value = app.FAVARCheckBox1.Value;
            app.favarChanged(1, value)
        end

        % Close request function: BEARinterfaceR2020aUIFigure
        function BEARinterfaceR2020aUIFigureCloseRequest(app, event)
            % Delete both apps
            delete(app.favarSettingsApp)
            delete(app)
        end

        % Button pushed function: FAVAROptionsButton2
        function FAVAROptionsButton2Pushed(app, event)
            app.FAVAROptionsButton2.Enable = false;
            bear.app.favarSettings(app.opts.opts.favar, app, 'FAVAROptionsButton2')
        end

        % Value changed function: FAVARCheckBox2
        function FAVARCheckBox2ValueChanged(app, event)
            value = app.FAVARCheckBox2.Value;
            app.favarChanged(2, value)
        end

        % Button pushed function: FAVAROptionsButton1
        function FAVAROptionsButton1Pushed(app, event)
            app.FAVAROptionsButton1.Enable = false;
            bear.app.favarSettings(app.opts.opts.favar, app, 'FAVAROptionsButton1')
        end

        % Value changed function: FAVARCheckBox5
        function FAVARCheckBox5ValueChanged(app, event)
            value = app.FAVARCheckBox5.Value;
            app.favarChanged(5, value)
        end

        % Button pushed function: FAVAROptionsButton5
        function FAVAROptionsButton5Pushed(app, event)
            app.FAVAROptionsButton5.Enable = false;
            bear.app.favarSettings(app.opts.opts.favar, app, 'FAVAROptionsButton4')
        end

        % Value changed function: FAVARCheckBox6
        function FAVARCheckBox6ValueChanged(app, event)
            value = app.FAVARCheckBox6.Value;
            app.favarChanged(6, value)
        end

        % Button pushed function: FAVAROptionsButton6
        function FAVAROptionsButton6Pushed(app, event)
            app.FAVAROptionsButton6.Enable = false;
            bear.app.favarSettings(app.opts.opts.favar, app, 'FAVAROptionsButton6')
        end

        % Button pushed function: ChangeresultsfolderButton
        function ChangeresultsfolderButtonPushed(app, event)
            selpath = uigetdir(pwd);
            if ~isequal(selpath,0)
                if isempty(app.results_sub.Value)
                    uialert(app.BEARinterfaceR2020aUIFigure, 'Please set a name to the results file')
                end
                app.opts.setCommonProp('results_path', selpath);
                app.results_location.Value = fullfile(selpath, app.results_sub.Value + ".xlsx");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BEARinterfaceR2020aUIFigure = uifigure('Visible', 'off');
            app.BEARinterfaceR2020aUIFigure.Name = 'BEAR interface R2020a';
            app.BEARinterfaceR2020aUIFigure.CloseRequestFcn = createCallbackFcn(app, @BEARinterfaceR2020aUIFigureCloseRequest, true);
            app.ImportMenu = uimenu(app.BEARinterfaceR2020aUIFigure);
            app.ImportMenu.Text = 'Import';
            app.ReplicationsMenu = uimenu(app.ImportMenu);
            app.ReplicationsMenu.Text = 'Replications';
            app.AAU2009Menu = uimenu(app.ReplicationsMenu);
            app.AAU2009Menu.MenuSelectedFcn = createCallbackFcn(app, @loadReplication, true);
            app.AAU2009Menu.Text = 'AAU2009';
            app.BBE2005Menu = uimenu(app.ReplicationsMenu);
            app.BBE2005Menu.MenuSelectedFcn = createCallbackFcn(app, @loadReplication, true);
            app.BBE2005Menu.Text = 'BBE2005';
            app.BvV2018Menu = uimenu(app.ReplicationsMenu);
            app.BvV2018Menu.MenuSelectedFcn = createCallbackFcn(app, @loadReplication, true);
            app.BvV2018Menu.Text = 'BvV2018';
            app.CH2019Menu = uimenu(app.ReplicationsMenu);
            app.CH2019Menu.MenuSelectedFcn = createCallbackFcn(app, @loadReplication, true);
            app.CH2019Menu.Text = 'CH2019';
            app.WGP2016Menu = uimenu(app.ReplicationsMenu);
            app.WGP2016Menu.MenuSelectedFcn = createCallbackFcn(app, @loadReplication, true);
            app.WGP2016Menu.Text = 'WGP2016';
            app.FromMATfileMenu = uimenu(app.ImportMenu);
            app.FromMATfileMenu.MenuSelectedFcn = createCallbackFcn(app, @FromMATfileMenuSelected, true);
            app.FromMATfileMenu.Text = 'From .MAT file';
            app.FromworkspacevariableMenu = uimenu(app.ImportMenu);
            app.FromworkspacevariableMenu.MenuSelectedFcn = createCallbackFcn(app, @FromworkspacevariableMenuSelected, true);
            app.FromworkspacevariableMenu.Text = 'From workspace variable';
            app.ExportMenu = uimenu(app.BEARinterfaceR2020aUIFigure);
            app.ExportMenu.Text = 'Export';
            app.ToMATfileMenu = uimenu(app.ExportMenu);
            app.ToMATfileMenu.MenuSelectedFcn = createCallbackFcn(app, @ToMATfileMenuSelected, true);
            app.ToMATfileMenu.Text = 'To .MAT file';
            app.ToworkspacevariableMenu = uimenu(app.ExportMenu);
            app.ToworkspacevariableMenu.MenuSelectedFcn = createCallbackFcn(app, @ToworkspacevariableMenuSelected, true);
            app.ToworkspacevariableMenu.Text = 'To workspace variable';
            app.AboutMenu = uimenu(app.BEARinterfaceR2020aUIFigure);
            app.AboutMenu.Text = 'About';
            app.AboutBEARMenu = uimenu(app.AboutMenu);
            app.AboutBEARMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutBEARMenuSelected, true);
            app.AboutBEARMenu.Text = 'About BEAR';
            app.TabGrid = uigridlayout(app.BEARinterfaceR2020aUIFigure);
            app.GridLayout23 = uigridlayout(app.TabGrid);
            app.GridLayout4 = uigridlayout(app.GridLayout23);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.DataFrequencyDropDownLabel = uilabel(app.GridLayout5);
            app.DataFrequencyDropDownLabel.Text = 'Data Frequency';
            app.frequency_cp = uidropdown(app.GridLayout5);
            app.frequency_cp.Items = {'yearly', 'quarterly', 'monthly', 'weekly', 'daily', 'undated'};
            app.frequency_cp.ItemsData = [1 2 3 4 5 6];
            app.frequency_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.frequency_cp.Value = 2;
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.EstimationStartDateEditFieldLabel = uilabel(app.GridLayout6);
            app.EstimationStartDateEditFieldLabel.Text = 'Estimation Start Date';
            app.startdate_cp = uieditfield(app.GridLayout6, 'text');
            app.startdate_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.startdate_cp.Tag = 'startdate';
            app.startdate_cp.Tooltip = {'startdate'};
            app.startdate_cp.Value = '1974q1';
            app.EstimationEndDateEditFieldLabel = uilabel(app.GridLayout6);
            app.EstimationEndDateEditFieldLabel.Text = 'Estimation End Date';
            app.enddate_cp = uieditfield(app.GridLayout6, 'text');
            app.enddate_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.enddate_cp.Tag = 'enddate';
            app.enddate_cp.Tooltip = {'enddate'};
            app.enddate_cp.Value = '2014q4';
            app.GridLayout7 = uigridlayout(app.GridLayout4);
            app.EnterthelistofendogeneousvariablesTextAreaLabel = uilabel(app.GridLayout7);
            app.EnterthelistofendogeneousvariablesTextAreaLabel.Text = 'Enter the list of endogeneous variables';
            app.varendo_cp = uitextarea(app.GridLayout7);
            app.varendo_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.varendo_cp.Tag = 'varendo';
            app.varendo_cp.Tooltip = {'varendo'};
            app.varendo_cp.Value = {'DOM_GDP DOM_CPI STN'};
            app.EnterthelistofexogeneousvariablesTextAreaLabel = uilabel(app.GridLayout7);
            app.EnterthelistofexogeneousvariablesTextAreaLabel.Text = 'Enter the list of exogeneous variables';
            app.varexo_cp = uitextarea(app.GridLayout7);
            app.varexo_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.varexo_cp.Tag = 'varexo';
            app.varexo_cp.Tooltip = {'varexo'};
            app.GridLayout8 = uigridlayout(app.GridLayout4);
            app.lags_cp = uieditfield(app.GridLayout8, 'numeric');
            app.lags_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.lags_cp.Tag = 'lags';
            app.lags_cp.Tooltip = {'lags'};
            app.lags_cp.Value = 4;
            app.LagsEditFieldLabel = uilabel(app.GridLayout8);
            app.LagsEditFieldLabel.Text = 'Lags';
            app.const_cp = uiswitch(app.GridLayout8, 'slider');
            app.const_cp.ItemsData = [0 1];
            app.const_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.const_cp.Tag = 'const';
            app.const_cp.Tooltip = {'const'};
            app.const_cp.Value = 1;
            app.IncludeconstantSwitchLabel = uilabel(app.GridLayout8);
            app.IncludeconstantSwitchLabel.Text = 'Include constant';
            app.GridLayout9 = uigridlayout(app.GridLayout4);
            app.results = uicheckbox(app.GridLayout9);
            app.results.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.results.Tag = 'results';
            app.results.Tooltip = {'results'};
            app.results.Text = 'Output in Excel';
            app.plot = uicheckbox(app.GridLayout9);
            app.plot.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.plot.Tag = 'plot';
            app.plot.Tooltip = {'plot'};
            app.plot.Text = 'Produce Figures';
            app.GridLayout18 = uigridlayout(app.GridLayout4);
            app.RUNButton = uibutton(app.GridLayout18, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Icon = 'BEAR.PNG';
            app.RUNButton.Text = 'RUN';
            app.QuickExporttoWorkspaceButton = uibutton(app.GridLayout18, 'push');
            app.QuickExporttoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @QuickExporttoWorkspaceButtonPushed, true);
            app.QuickExporttoWorkspaceButton.Tooltip = {'Export settings object into MATLAB workspace'};
            app.QuickExporttoWorkspaceButton.Text = 'Quick Export to Workspace';
            app.GridLayout24 = uigridlayout(app.GridLayout4);
            app.results_location = uieditfield(app.GridLayout24, 'text');
            app.results_location.Tag = 'results_path';
            app.results_location.Editable = 'off';
            app.results_location.Tooltip = {'results_path'};
            app.ResultsfilenameLabel = uilabel(app.GridLayout24);
            app.ResultsfilenameLabel.Text = 'Results file name:';
            app.results_sub = uieditfield(app.GridLayout24, 'text');
            app.results_sub.ValueChangedFcn = createCallbackFcn(app, @results_subValueChanged, true);
            app.results_sub.Tag = 'results_sub';
            app.results_sub.Tooltip = {'results_sub'};
            app.results_sub.Value = 'results';
            app.ChangeresultsfolderButton = uibutton(app.GridLayout24, 'push');
            app.ChangeresultsfolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeresultsfolderButtonPushed, true);
            app.ChangeresultsfolderButton.Text = 'Change results folder';
            app.GridLayout25 = uigridlayout(app.GridLayout4);
            app.SelectExcelfilewithinputsButton = uibutton(app.GridLayout25, 'push');
            app.SelectExcelfilewithinputsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExcelfilewithinputsButtonPushed, true);
            app.SelectExcelfilewithinputsButton.Text = 'Select Excel file with inputs';
            app.excelFile = uieditfield(app.GridLayout25, 'text');
            app.excelFile.Tag = 'excelFile';
            app.excelFile.Editable = 'off';
            app.excelFile.Tooltip = {'excelFile'};
            app.TabGroup = uitabgroup(app.GridLayout23);
            app.SPECIFICATIONTab = uitab(app.TabGroup);
            app.SPECIFICATIONTab.Title = 'SPECIFICATION';
            app.GridLayout2 = uigridlayout(app.SPECIFICATIONTab);
            app.GridLayout11 = uigridlayout(app.GridLayout2);
            app.SELECTVARTYPELabel = uilabel(app.GridLayout11);
            app.SELECTVARTYPELabel.Text = 'SELECT VAR TYPE';
            app.VARtypeTab = uitabgroup(app.GridLayout11);
            app.VARtypeTab.SelectionChangedFcn = createCallbackFcn(app, @VARtypeTabSelectionChanged, true);
            app.BayesianTab = uitab(app.VARtypeTab);
            app.BayesianTab.Title = 'Bayesian';
            app.BayesianGrid = uigridlayout(app.BayesianTab);
            app.Iterations = uipanel(app.BayesianGrid);
            app.Iterations.Title = 'Iterations';
            app.GridLayout18_4 = uigridlayout(app.Iterations);
            app.Bu_bvar = uieditfield(app.GridLayout18_4, 'numeric');
            app.Bu_bvar.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.Bu_bvar.Tag = 'Bu';
            app.Bu_bvar.Tooltip = {'Bu'};
            app.Bu_bvar.Value = 1000;
            app.It_bvar = uieditfield(app.GridLayout18_4, 'numeric');
            app.It_bvar.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.It_bvar.Tag = 'It';
            app.It_bvar.Tooltip = {'It'};
            app.It_bvar.Value = 2000;
            app.NumberofburniniterationsEditFieldLabel_4 = uilabel(app.GridLayout18_4);
            app.NumberofburniniterationsEditFieldLabel_4.Text = 'Number of burn-in iterations';
            app.TotalnumberofiterationsEditFieldLabel_4 = uilabel(app.GridLayout18_4);
            app.TotalnumberofiterationsEditFieldLabel_4.Text = 'Total number of iterations:';
            app.OptionsPanel = uipanel(app.BayesianGrid);
            app.OptionsPanel.Title = 'Options';
            app.GridLayout17_5 = uigridlayout(app.OptionsPanel);
            app.GridsearchonexcelSwitchLabel = uilabel(app.GridLayout17_5);
            app.GridsearchonexcelSwitchLabel.Text = 'Grid search (on excel)';
            app.hogs_bvar = uiswitch(app.GridLayout17_5, 'slider');
            app.hogs_bvar.Items = {'No', 'Yes'};
            app.hogs_bvar.ItemsData = [0 1];
            app.hogs_bvar.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.hogs_bvar.Tag = 'hogs';
            app.hogs_bvar.Tooltip = {'hogs'};
            app.hogs_bvar.Value = 0;
            app.BlockexogeneityonexcelSwitchLabel = uilabel(app.GridLayout17_5);
            app.BlockexogeneityonexcelSwitchLabel.Text = 'Block exogeneity (on excel)';
            app.bex_bvar = uiswitch(app.GridLayout17_5, 'slider');
            app.bex_bvar.Items = {'No', 'Yes'};
            app.bex_bvar.ItemsData = [0 1];
            app.bex_bvar.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.bex_bvar.Tag = 'bex';
            app.bex_bvar.Tooltip = {'bex'};
            app.bex_bvar.Value = 0;
            app.DummyobservationextensionsLabel = uilabel(app.GridLayout17_5);
            app.DummyobservationextensionsLabel.Text = 'Dummy observation extensions';
            app.scoeff_bvar = uicheckbox(app.GridLayout17_5);
            app.scoeff_bvar.ValueChangedFcn = createCallbackFcn(app, @scoeff_bvarValueChanged, true);
            app.scoeff_bvar.Tag = 'scoeff';
            app.scoeff_bvar.Tooltip = {'scoeff'};
            app.scoeff_bvar.Text = 'Sum of coefficients';
            app.iobs_bvar = uicheckbox(app.GridLayout17_5);
            app.iobs_bvar.ValueChangedFcn = createCallbackFcn(app, @iobs_bvarValueChanged, true);
            app.iobs_bvar.Tag = 'iobs';
            app.iobs_bvar.Tooltip = {'iobs'};
            app.iobs_bvar.Text = 'Dummy initial observations';
            app.lrp_bvar = uicheckbox(app.GridLayout17_5);
            app.lrp_bvar.ValueChangedFcn = createCallbackFcn(app, @lrp_bvarValueChanged, true);
            app.lrp_bvar.Tag = 'lrp';
            app.lrp_bvar.Tooltip = {'lrp'};
            app.lrp_bvar.Text = 'Long-run priors';
            app.HyperparametersPanel_3 = uipanel(app.BayesianGrid);
            app.HyperparametersPanel_3.Title = 'Hyperparameters';
            app.GridLayout17_6 = uigridlayout(app.HyperparametersPanel_3);
            app.lambda2_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.lambda2_bvar.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.lambda2_bvar.Tag = 'lambda2';
            app.lambda2_bvar.Tooltip = {'lambda2'};
            app.lambda2_bvar.Value = 0.5;
            app.lambda1_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.lambda1_bvar.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.lambda1_bvar.Tag = 'lambda1';
            app.lambda1_bvar.Tooltip = {'lambda1'};
            app.lambda1_bvar.Value = 0.1;
            app.ar_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.ar_bvar.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.ar_bvar.Tag = 'ar';
            app.ar_bvar.Tooltip = {'ar'};
            app.ar_bvar.Value = 1;
            app.ARcoefficientonresidualvarianceEditFieldLabel_6 = uilabel(app.GridLayout17_6);
            app.ARcoefficientonresidualvarianceEditFieldLabel_6.Text = 'Cross-variable weighting';
            app.IGscaleonresidualvarianceEditFieldLabel_6 = uilabel(app.GridLayout17_6);
            app.IGscaleonresidualvarianceEditFieldLabel_6.Text = 'Overall tightness';
            app.IGshapeonresidualvarianceEditFieldLabel_6 = uilabel(app.GridLayout17_6);
            app.IGshapeonresidualvarianceEditFieldLabel_6.Text = 'Autoregresive coefficient';
            app.LagdecayEditField_2Label = uilabel(app.GridLayout17_6);
            app.LagdecayEditField_2Label.Text = 'Lag decay';
            app.lambda3_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.lambda3_bvar.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.lambda3_bvar.Tag = 'lambda3';
            app.lambda3_bvar.Tooltip = {'lambda3'};
            app.lambda3_bvar.Value = 1;
            app.PriorExcel_bvar = uicheckbox(app.GridLayout17_6);
            app.PriorExcel_bvar.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.PriorExcel_bvar.Tag = 'PriorExcel';
            app.PriorExcel_bvar.Tooltip = {'PriorExcel'};
            app.PriorExcel_bvar.Text = 'Excel';
            app.ExogenousvariableEditFieldLabel = uilabel(app.GridLayout17_6);
            app.ExogenousvariableEditFieldLabel.Text = 'Exogenous variable';
            app.lambda4_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.lambda4_bvar.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.lambda4_bvar.Tag = 'lambda4';
            app.lambda4_bvar.Tooltip = {'lambda4'};
            app.lambda4_bvar.Value = 100;
            app.BlockexogenetyshrinkageEditFieldLabel = uilabel(app.GridLayout17_6);
            app.BlockexogenetyshrinkageEditFieldLabel.Text = 'Block exogenety shrinkage';
            app.lambda5_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.lambda5_bvar.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.lambda5_bvar.Tag = 'lambda5';
            app.lambda5_bvar.Tooltip = {'lambda5'};
            app.lambda5_bvar.Value = 0.001;
            app.SumofcoefficienttightnessEditFieldLabel = uilabel(app.GridLayout17_6);
            app.SumofcoefficienttightnessEditFieldLabel.Text = 'Sum of coefficient tightness';
            app.lambda6_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.lambda6_bvar.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.lambda6_bvar.Tag = 'lambda6';
            app.lambda6_bvar.Enable = 'off';
            app.lambda6_bvar.Tooltip = {'lambda6'};
            app.lambda6_bvar.Value = 0.1;
            app.DummyinitialobservationtightnessLabel = uilabel(app.GridLayout17_6);
            app.DummyinitialobservationtightnessLabel.Text = 'Dummy initial observation tightness';
            app.lambda7_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.lambda7_bvar.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.lambda7_bvar.Tag = 'lambda7';
            app.lambda7_bvar.Enable = 'off';
            app.lambda7_bvar.Tooltip = {'lambda7'};
            app.lambda7_bvar.Value = 0.001;
            app.LongrunpriortightnessEditFieldLabel = uilabel(app.GridLayout17_6);
            app.LongrunpriortightnessEditFieldLabel.Text = 'Long-run prior tightness';
            app.lambda8_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.lambda8_bvar.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.lambda8_bvar.Tag = 'lambda8';
            app.lambda8_bvar.Enable = 'off';
            app.lambda8_bvar.Tooltip = {'lambda8'};
            app.lambda8_bvar.Value = 1;
            app.ExogenousvariablesSwitchLabel = uilabel(app.GridLayout17_6);
            app.ExogenousvariablesSwitchLabel.Text = 'Exogenous variables';
            app.priorsexogenous_bvar = uiswitch(app.GridLayout17_6, 'slider');
            app.priorsexogenous_bvar.Items = {'Excel', 'Default'};
            app.priorsexogenous_bvar.ItemsData = [1 0];
            app.priorsexogenous_bvar.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.priorsexogenous_bvar.Tag = 'priorsexogenous';
            app.priorsexogenous_bvar.Tooltip = {'priorsexogenous'};
            app.priorsexogenous_bvar.Value = 1;
            app.prior = uibuttongroup(app.BayesianGrid);
            app.prior.SelectionChangedFcn = createCallbackFcn(app, @priorSelectionChanged, true);
            app.prior.Title = 'Bayesian VARs Priors';
            app.MinnesotaButton = uiradiobutton(app.prior);
            app.MinnesotaButton.Tooltip = {'Minnesota'};
            app.MinnesotaButton.Text = 'Minnesota';
            app.MinnesotaButton.Value = true;
            app.NormalDiffuseButton = uiradiobutton(app.prior);
            app.NormalDiffuseButton.Tooltip = {'Normal Diffuse'};
            app.NormalDiffuseButton.Text = 'Normal Diffuse';
            app.DummyObservationsButton = uiradiobutton(app.prior);
            app.DummyObservationsButton.Tooltip = {'Dummy'};
            app.DummyObservationsButton.Text = 'Dummy Observations';
            app.NormalWishartButton = uiradiobutton(app.prior);
            app.NormalWishartButton.Tooltip = {'Nw_univariate'};
            app.NormalWishartButton.Text = 'Normal Wishart';
            app.IndependentNormalWishartButton = uiradiobutton(app.prior);
            app.IndependentNormalWishartButton.Tooltip = {'Inw_univariate'};
            app.IndependentNormalWishartButton.Text = 'Independent Normal Wishart';
            app.DeterministicButton = uiradiobutton(app.prior);
            app.DeterministicButton.Tooltip = {'Madj'};
            app.DeterministicButton.Text = 'Deterministic';
            app.FAVAROptionsPanel = uipanel(app.BayesianGrid);
            app.FAVAROptionsPanel.Title = 'FAVAR Options';
            app.FAVARCheckBox2 = uicheckbox(app.FAVAROptionsPanel);
            app.FAVARCheckBox2.ValueChangedFcn = createCallbackFcn(app, @FAVARCheckBox2ValueChanged, true);
            app.FAVARCheckBox2.Text = 'FAVAR';
            app.FAVAROptionsButton2 = uibutton(app.FAVAROptionsPanel, 'push');
            app.FAVAROptionsButton2.ButtonPushedFcn = createCallbackFcn(app, @FAVAROptionsButton2Pushed, true);
            app.FAVAROptionsButton2.Enable = 'off';
            app.FAVAROptionsButton2.Text = 'FAVAR Options';
            app.OLSTab = uitab(app.VARtypeTab);
            app.OLSTab.Title = 'OLS';
            app.FAVARCheckBox1 = uicheckbox(app.OLSTab);
            app.FAVARCheckBox1.ValueChangedFcn = createCallbackFcn(app, @FAVARCheckBox1ValueChanged, true);
            app.FAVARCheckBox1.Text = 'FAVAR';
            app.FAVAROptionsButton1 = uibutton(app.OLSTab, 'push');
            app.FAVAROptionsButton1.ButtonPushedFcn = createCallbackFcn(app, @FAVAROptionsButton1Pushed, true);
            app.FAVAROptionsButton1.Enable = 'off';
            app.FAVAROptionsButton1.Text = 'FAVAR Options';
            app.PanelTab = uitab(app.VARtypeTab);
            app.PanelTab.Title = 'Panel';
            app.PanelGrid = uigridlayout(app.PanelTab);
            app.HyperparametersPanelpanel = uipanel(app.PanelGrid);
            app.HyperparametersPanelpanel.Title = 'Hyperparameters';
            app.GridLayout15 = uigridlayout(app.HyperparametersPanelpanel);
            app.v0_panel = uieditfield(app.GridLayout15, 'numeric');
            app.v0_panel.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.v0_panel.Tag = 'v0';
            app.v0_panel.Tooltip = {'v0'};
            app.v0_panel.Value = 0.001;
            app.s0_panel = uieditfield(app.GridLayout15, 'numeric');
            app.s0_panel.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.s0_panel.Tag = 's0';
            app.s0_panel.Tooltip = {'s0'};
            app.s0_panel.Value = 0.001;
            app.lambda4_panel = uieditfield(app.GridLayout15, 'numeric');
            app.lambda4_panel.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.lambda4_panel.Tag = 'lambda4';
            app.lambda4_panel.Tooltip = {'lambda4'};
            app.lambda4_panel.Value = 100;
            app.lambda3_panel = uieditfield(app.GridLayout15, 'numeric');
            app.lambda3_panel.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.lambda3_panel.Tag = 'lambda3';
            app.lambda3_panel.Tooltip = {'lambda3'};
            app.lambda3_panel.Value = 1;
            app.lambda2_panel = uieditfield(app.GridLayout15, 'numeric');
            app.lambda2_panel.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.lambda2_panel.Tag = 'lambda2';
            app.lambda2_panel.Tooltip = {'lambda2'};
            app.lambda2_panel.Value = 0.5;
            app.lambda1_panel = uieditfield(app.GridLayout15, 'numeric');
            app.lambda1_panel.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.lambda1_panel.Tag = 'lambda1';
            app.lambda1_panel.Tooltip = {'lambda1'};
            app.lambda1_panel.Value = 0.1;
            app.ar_panel = uieditfield(app.GridLayout15, 'numeric');
            app.ar_panel.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.ar_panel.Tag = 'ar';
            app.ar_panel.Tooltip = {'ar'};
            app.ar_panel.Value = 0.8;
            app.IGscaleonoveralltightnessEditFieldLabel = uilabel(app.GridLayout15);
            app.IGscaleonoveralltightnessEditFieldLabel.Text = 'IG scale on overall tightness';
            app.IGshapeonoveralltightnessEditFieldLabel = uilabel(app.GridLayout15);
            app.IGshapeonoveralltightnessEditFieldLabel.Text = 'IG shape on overall tightness';
            app.ExogenousvariabletightnessEditFieldLabel = uilabel(app.GridLayout15);
            app.ExogenousvariabletightnessEditFieldLabel.Text = 'Exogenous variable tightness';
            app.LagdecayEditFieldLabel = uilabel(app.GridLayout15);
            app.LagdecayEditFieldLabel.Text = 'Lag decay';
            app.CrossvariableweightingEditFieldLabel = uilabel(app.GridLayout15);
            app.CrossvariableweightingEditFieldLabel.Text = 'Cross-variable weighting';
            app.OveralltightnessEditFieldLabel = uilabel(app.GridLayout15);
            app.OveralltightnessEditFieldLabel.Text = 'Overall tightness';
            app.AutoregressivecoefficientEditFieldLabel = uilabel(app.GridLayout15);
            app.AutoregressivecoefficientEditFieldLabel.Text = 'Autoregressive coefficient';
            app.HyperparametersPanel = uipanel(app.PanelGrid);
            app.HyperparametersPanel.Title = 'Hyperparameters';
            app.GridLayout17 = uigridlayout(app.HyperparametersPanel);
            app.psi_panel = uieditfield(app.GridLayout17, 'numeric');
            app.psi_panel.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.psi_panel.Tag = 'psi';
            app.psi_panel.Tooltip = {'psi'};
            app.psi_panel.Value = 0.1;
            app.rho_panel = uieditfield(app.GridLayout17, 'numeric');
            app.rho_panel.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.rho_panel.Tag = 'rho';
            app.rho_panel.Tooltip = {'rho'};
            app.rho_panel.Value = 0.75;
            app.b0_panel = uieditfield(app.GridLayout17, 'numeric');
            app.b0_panel.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.b0_panel.Tag = 'b0';
            app.b0_panel.Tooltip = {'b0'};
            app.b0_panel.Value = 1;
            app.a0_panel = uieditfield(app.GridLayout17, 'numeric');
            app.a0_panel.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.a0_panel.Tag = 'a0';
            app.a0_panel.Tooltip = {'a0'};
            app.a0_panel.Value = 1000;
            app.gamma_panel = uieditfield(app.GridLayout17, 'numeric');
            app.gamma_panel.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.gamma_panel.Tag = 'gamma';
            app.gamma_panel.Tooltip = {'gamma'};
            app.gamma_panel.Value = 0.85;
            app.delta0_panel = uieditfield(app.GridLayout17, 'numeric');
            app.delta0_panel.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.delta0_panel.Tag = 'delta0';
            app.delta0_panel.Tooltip = {'delta0'};
            app.delta0_panel.Value = 1;
            app.alpha0_panel = uieditfield(app.GridLayout17, 'numeric');
            app.alpha0_panel.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.alpha0_panel.Tag = 'alpha0';
            app.alpha0_panel.Tooltip = {'alpha0'};
            app.alpha0_panel.Value = 1000;
            app.VarianceonMetropolisdrawEditFieldLabel = uilabel(app.GridLayout17);
            app.VarianceonMetropolisdrawEditFieldLabel.Tooltip = {'psi'};
            app.VarianceonMetropolisdrawEditFieldLabel.Text = 'Variance on Metropolis draw';
            app.ARcoefficientonfactorEditFieldLabel = uilabel(app.GridLayout17);
            app.ARcoefficientonfactorEditFieldLabel.Tooltip = {'rho'};
            app.ARcoefficientonfactorEditFieldLabel.Text = 'AR coefficient on factor';
            app.IGscaleonfactorvarianceEditFieldLabel = uilabel(app.GridLayout17);
            app.IGscaleonfactorvarianceEditFieldLabel.Tooltip = {'b0'};
            app.IGscaleonfactorvarianceEditFieldLabel.Text = 'IG scale on factor variance';
            app.IGshapeonfactorvarianceEditFieldLabel = uilabel(app.GridLayout17);
            app.IGshapeonfactorvarianceEditFieldLabel.Tooltip = {'a0'};
            app.IGshapeonfactorvarianceEditFieldLabel.Text = 'IG shape on factor variance';
            app.ARcoefficientonresidualvarianceEditFieldLabel = uilabel(app.GridLayout17);
            app.ARcoefficientonresidualvarianceEditFieldLabel.Tooltip = {'gamma'};
            app.ARcoefficientonresidualvarianceEditFieldLabel.Text = 'AR coefficient on residual variance';
            app.IGscaleonresidualvarianceEditFieldLabel = uilabel(app.GridLayout17);
            app.IGscaleonresidualvarianceEditFieldLabel.Tooltip = {'delta0'};
            app.IGscaleonresidualvarianceEditFieldLabel.Text = 'IG scale on residual variance';
            app.IGshapeonresidualvarianceEditFieldLabel = uilabel(app.GridLayout17);
            app.IGshapeonresidualvarianceEditFieldLabel.Tooltip = {'alpha0'};
            app.IGshapeonresidualvarianceEditFieldLabel.Text = 'IG shape on residual variance';
            app.EnterlistofunitsPanel = uipanel(app.PanelGrid);
            app.EnterlistofunitsPanel.Title = 'Enter list of units';
            app.GridLayout16 = uigridlayout(app.EnterlistofunitsPanel);
            app.unitnames_panel = uieditfield(app.GridLayout16, 'text');
            app.unitnames_panel.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.unitnames_panel.Tag = 'unitnames';
            app.unitnames_panel.Tooltip = {'unitnames'};
            app.Iterations_2 = uipanel(app.PanelGrid);
            app.Iterations_2.Title = 'Iterations';
            app.GridLayout18_5 = uigridlayout(app.Iterations_2);
            app.Bu_panel = uieditfield(app.GridLayout18_5, 'numeric');
            app.Bu_panel.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.Bu_panel.Tag = 'Bu';
            app.Bu_panel.Tooltip = {'Bu'};
            app.Bu_panel.Value = 1000;
            app.It_panel = uieditfield(app.GridLayout18_5, 'numeric');
            app.It_panel.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.It_panel.Tag = 'It';
            app.It_panel.Tooltip = {'It'};
            app.It_panel.Value = 2000;
            app.NumberofburniniterationsEditFieldLabel_5 = uilabel(app.GridLayout18_5);
            app.NumberofburniniterationsEditFieldLabel_5.Text = 'Number of burn-in iterations';
            app.TotalnumberofiterationsEditFieldLabel_5 = uilabel(app.GridLayout18_5);
            app.TotalnumberofiterationsEditFieldLabel_5.Text = 'Total number of iterations';
            app.panel = uibuttongroup(app.PanelGrid);
            app.panel.SelectionChangedFcn = createCallbackFcn(app, @panelSelectionChanged, true);
            app.panel.Title = 'Bayesian Panel VARs Priors';
            app.MeangroupEstimatorButton = uiradiobutton(app.panel);
            app.MeangroupEstimatorButton.Text = 'Mean group Estimator';
            app.MeangroupEstimatorButton.Value = true;
            app.RandomEffectZellnerHongButton = uiradiobutton(app.panel);
            app.RandomEffectZellnerHongButton.Text = 'Random Effect (Zellner-Hong)';
            app.StaticStructureFactorButton = uiradiobutton(app.panel);
            app.StaticStructureFactorButton.Text = 'Static Structure Factor';
            app.PooledEstimatorButton = uiradiobutton(app.panel);
            app.PooledEstimatorButton.Text = 'Pooled Estimator';
            app.RandomEffectHierarchicalButton = uiradiobutton(app.panel);
            app.RandomEffectHierarchicalButton.Text = 'Random Effect (Hierarchical)';
            app.DynamicStructureFactorButton = uiradiobutton(app.panel);
            app.DynamicStructureFactorButton.Text = 'Dynamic Structure Factor';
            app.StochasticVolatilityTab = uitab(app.VARtypeTab);
            app.StochasticVolatilityTab.Title = 'Stochastic Volatility';
            app.SVGrid = uigridlayout(app.StochasticVolatilityTab);
            app.HyperparametersstochasticPanel = uipanel(app.SVGrid);
            app.HyperparametersstochasticPanel.Title = 'Hyperparameters stochastic';
            app.GridLayout17_3 = uigridlayout(app.HyperparametersstochasticPanel);
            app.zeta0_sv = uieditfield(app.GridLayout17_3, 'numeric');
            app.zeta0_sv.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.zeta0_sv.Tag = 'zeta0';
            app.zeta0_sv.Tooltip = {'zeta0'};
            app.zeta0_sv.Value = 10000;
            app.gamma0_sv = uieditfield(app.GridLayout17_3, 'numeric');
            app.gamma0_sv.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.gamma0_sv.Tag = 'gamma0';
            app.gamma0_sv.Tooltip = {'gamma0'};
            app.delta0_sv = uieditfield(app.GridLayout17_3, 'numeric');
            app.delta0_sv.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.delta0_sv.Tag = 'delta0';
            app.delta0_sv.Tooltip = {'delta0'};
            app.delta0_sv.Value = 0.001;
            app.alpha0_sv = uieditfield(app.GridLayout17_3, 'numeric');
            app.alpha0_sv.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.alpha0_sv.Tag = 'alpha0';
            app.alpha0_sv.Tooltip = {'alpha0'};
            app.alpha0_sv.Value = 0.001;
            app.gamma_sv = uieditfield(app.GridLayout17_3, 'numeric');
            app.gamma_sv.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.gamma_sv.Tag = 'gamma';
            app.gamma_sv.Tooltip = {'gamma'};
            app.gamma_sv.Value = 1;
            app.IGscaleonfactorvarianceEditFieldLabel_3 = uilabel(app.GridLayout17_3);
            app.IGscaleonfactorvarianceEditFieldLabel_3.Tooltip = {'zeta0'};
            app.IGscaleonfactorvarianceEditFieldLabel_3.Text = 'Prior variance on inertia';
            app.IGshapeonfactorvarianceEditFieldLabel_3 = uilabel(app.GridLayout17_3);
            app.IGshapeonfactorvarianceEditFieldLabel_3.Tooltip = {'gamma0'};
            app.IGshapeonfactorvarianceEditFieldLabel_3.Text = 'Prior mean on inertia';
            app.ARcoefficientonresidualvarianceEditFieldLabel_3 = uilabel(app.GridLayout17_3);
            app.ARcoefficientonresidualvarianceEditFieldLabel_3.Tooltip = {'delta0'};
            app.ARcoefficientonresidualvarianceEditFieldLabel_3.Text = 'IG scale on residual variance';
            app.IGscaleonresidualvarianceEditFieldLabel_3 = uilabel(app.GridLayout17_3);
            app.IGscaleonresidualvarianceEditFieldLabel_3.Tooltip = {'alpha0'};
            app.IGscaleonresidualvarianceEditFieldLabel_3.Text = 'IG shape on residual variance';
            app.IGshapeonresidualvarianceEditFieldLabel_3 = uilabel(app.GridLayout17_3);
            app.IGshapeonresidualvarianceEditFieldLabel_3.Text = 'AR coefficient on residual variance';
            app.alltirf_sv = uicheckbox(app.GridLayout17_3);
            app.alltirf_sv.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.alltirf_sv.Tag = 'alltirf';
            app.alltirf_sv.Tooltip = {'alltirf'};
            app.alltirf_sv.Text = 'Time-varying IRFs';
            app.alltirf_sv.Value = true;
            app.stvol = uibuttongroup(app.SVGrid);
            app.stvol.SelectionChangedFcn = createCallbackFcn(app, @stvolSelectionChanged, true);
            app.stvol.Title = 'Stochastic Volatility';
            app.StandardButton = uiradiobutton(app.stvol);
            app.StandardButton.Text = 'Standard';
            app.StandardButton.Value = true;
            app.RandomInertiaButton = uiradiobutton(app.stvol);
            app.RandomInertiaButton.Text = 'Random Inertia';
            app.LargeVARButton = uiradiobutton(app.stvol);
            app.LargeVARButton.Text = 'Large VAR';
            app.StochasticButton = uiradiobutton(app.stvol);
            app.StochasticButton.Text = 'Stochastic';
            app.Iterations_3 = uipanel(app.SVGrid);
            app.Iterations_3.Title = 'Iterations';
            app.GridLayout18_6 = uigridlayout(app.Iterations_3);
            app.Bu_sv = uieditfield(app.GridLayout18_6, 'numeric');
            app.Bu_sv.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.Bu_sv.Tag = 'Bu';
            app.Bu_sv.Tooltip = {'Bu'};
            app.Bu_sv.Value = 1000;
            app.It_sv = uieditfield(app.GridLayout18_6, 'numeric');
            app.It_sv.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.It_sv.Tag = 'It';
            app.It_sv.Tooltip = {'It'};
            app.It_sv.Value = 2000;
            app.NumberofburniniterationsEditFieldLabel_6 = uilabel(app.GridLayout18_6);
            app.NumberofburniniterationsEditFieldLabel_6.Text = 'Number of burn-in iterations:';
            app.TotalnumberofiterationsEditFieldLabel_6 = uilabel(app.GridLayout18_6);
            app.TotalnumberofiterationsEditFieldLabel_6.Text = 'Total number of iterations:';
            app.HyperparametersPanel_4 = uipanel(app.SVGrid);
            app.HyperparametersPanel_4.Title = 'Hyperparameters';
            app.GridLayout17_7 = uigridlayout(app.HyperparametersPanel_4);
            app.lambda2_sv = uieditfield(app.GridLayout17_7, 'numeric');
            app.lambda2_sv.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.lambda2_sv.Tag = 'lambda2';
            app.lambda2_sv.Tooltip = {'lambda2'};
            app.lambda2_sv.Value = 0.5;
            app.lambda1_sv = uieditfield(app.GridLayout17_7, 'numeric');
            app.lambda1_sv.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.lambda1_sv.Tag = 'lambda1';
            app.lambda1_sv.Tooltip = {'lambda1'};
            app.lambda1_sv.Value = 0.1;
            app.ar_sv = uieditfield(app.GridLayout17_7, 'numeric');
            app.ar_sv.Tag = 'ar';
            app.ar_sv.Tooltip = {'ar'};
            app.ar_sv.Value = 1;
            app.ARcoefficientonresidualvarianceEditFieldLabel_7 = uilabel(app.GridLayout17_7);
            app.ARcoefficientonresidualvarianceEditFieldLabel_7.Text = 'Cross-variable weighting';
            app.IGscaleonresidualvarianceEditFieldLabel_7 = uilabel(app.GridLayout17_7);
            app.IGscaleonresidualvarianceEditFieldLabel_7.Text = 'Overall tightness';
            app.IGshapeonresidualvarianceEditFieldLabel_7 = uilabel(app.GridLayout17_7);
            app.IGshapeonresidualvarianceEditFieldLabel_7.Text = 'Autoregresive coefficient';
            app.LagdecayEditField_2Label_2 = uilabel(app.GridLayout17_7);
            app.LagdecayEditField_2Label_2.Text = 'Lag decay';
            app.lambda3_sv = uieditfield(app.GridLayout17_7, 'numeric');
            app.lambda3_sv.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.lambda3_sv.Tag = 'lambda3';
            app.lambda3_sv.Tooltip = {'lambda3'};
            app.lambda3_sv.Value = 1;
            app.PriorExcel_sv = uicheckbox(app.GridLayout17_7);
            app.PriorExcel_sv.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.PriorExcel_sv.Tag = 'PriorExcel';
            app.PriorExcel_sv.Tooltip = {'PriorExcel'};
            app.PriorExcel_sv.Text = 'Excel';
            app.ExogenousvariableEditFieldLabel_2 = uilabel(app.GridLayout17_7);
            app.ExogenousvariableEditFieldLabel_2.Text = 'Exogenous variable';
            app.lambda4_sv = uieditfield(app.GridLayout17_7, 'numeric');
            app.lambda4_sv.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.lambda4_sv.Tag = 'lambda4';
            app.lambda4_sv.Tooltip = {'lambda4'};
            app.lambda4_sv.Value = 100;
            app.BlockexogenetyshrinkageEditFieldLabel_2 = uilabel(app.GridLayout17_7);
            app.BlockexogenetyshrinkageEditFieldLabel_2.Text = 'Block exogenety shrinkage';
            app.lambda5_sv = uieditfield(app.GridLayout17_7, 'numeric');
            app.lambda5_sv.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.lambda5_sv.Tag = 'lambda5';
            app.lambda5_sv.Tooltip = {'lambda5'};
            app.lambda5_sv.Value = 0.001;
            app.ExogenousvariablesSwitchLabel_2 = uilabel(app.GridLayout17_7);
            app.ExogenousvariablesSwitchLabel_2.Text = 'Exogenous variables';
            app.priorsexogenous_sv = uiswitch(app.GridLayout17_7, 'slider');
            app.priorsexogenous_sv.Items = {'Excel', 'Default'};
            app.priorsexogenous_sv.ItemsData = [1 0];
            app.priorsexogenous_sv.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.priorsexogenous_sv.Tag = 'priorsexogenous';
            app.priorsexogenous_sv.Tooltip = {'priorsexogenous'};
            app.priorsexogenous_sv.Value = 0;
            app.FAVAROptionsSV = uipanel(app.SVGrid);
            app.FAVAROptionsSV.Title = 'FAVAR Options';
            app.FAVARCheckBox5 = uicheckbox(app.FAVAROptionsSV);
            app.FAVARCheckBox5.ValueChangedFcn = createCallbackFcn(app, @FAVARCheckBox5ValueChanged, true);
            app.FAVARCheckBox5.Text = 'FAVAR';
            app.FAVAROptionsButton5 = uibutton(app.FAVAROptionsSV, 'push');
            app.FAVAROptionsButton5.ButtonPushedFcn = createCallbackFcn(app, @FAVAROptionsButton5Pushed, true);
            app.FAVAROptionsButton5.Enable = 'off';
            app.FAVAROptionsButton5.Text = 'FAVAR Options';
            app.TimeVaryingTab = uitab(app.VARtypeTab);
            app.TimeVaryingTab.Title = 'Time Varying';
            app.TVPGrid = uigridlayout(app.TimeVaryingTab);
            app.HyperparameterstimevaryingPanel = uipanel(app.TVPGrid);
            app.HyperparameterstimevaryingPanel.Title = 'Hyperparameters time varying';
            app.GridLayout17_2 = uigridlayout(app.HyperparameterstimevaryingPanel);
            app.delta0_tvp = uieditfield(app.GridLayout17_2, 'numeric');
            app.delta0_tvp.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.delta0_tvp.Tag = 'delta0';
            app.delta0_tvp.Tooltip = {'delta0'};
            app.delta0_tvp.Value = 0.001;
            app.alpha0_tvp = uieditfield(app.GridLayout17_2, 'numeric');
            app.alpha0_tvp.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.alpha0_tvp.Tag = 'alpha0';
            app.alpha0_tvp.Tooltip = {'alpha0'};
            app.alpha0_tvp.Value = 0.001;
            app.gamma_tvp = uieditfield(app.GridLayout17_2, 'numeric');
            app.gamma_tvp.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.gamma_tvp.Tag = 'gamma';
            app.gamma_tvp.Tooltip = {'gamma'};
            app.gamma_tvp.Value = 0.85;
            app.ARcoefficientonresidualvarianceEditFieldLabel_2 = uilabel(app.GridLayout17_2);
            app.ARcoefficientonresidualvarianceEditFieldLabel_2.Text = 'IG scale on residual variance';
            app.IGscaleonresidualvarianceEditFieldLabel_2 = uilabel(app.GridLayout17_2);
            app.IGscaleonresidualvarianceEditFieldLabel_2.Text = 'IG shape on residual variance';
            app.IGshapeonresidualvarianceEditFieldLabel_2 = uilabel(app.GridLayout17_2);
            app.IGshapeonresidualvarianceEditFieldLabel_2.Text = 'AR coefficient on residual variance';
            app.alltirf_tvp = uicheckbox(app.GridLayout17_2);
            app.alltirf_tvp.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.alltirf_tvp.Tag = 'alltirf';
            app.alltirf_tvp.Tooltip = {'alltirf'};
            app.alltirf_tvp.Text = 'Time-varying IRFs';
            app.alltirf_tvp.Value = true;
            app.tvbvar = uibuttongroup(app.TVPGrid);
            app.tvbvar.SelectionChangedFcn = createCallbackFcn(app, @tvbvarSelectionChanged, true);
            app.tvbvar.Title = 'Time-varying';
            app.VARcoefficientsButton = uiradiobutton(app.tvbvar);
            app.VARcoefficientsButton.Text = 'VAR coefficients';
            app.GeneralButton = uiradiobutton(app.tvbvar);
            app.GeneralButton.Text = 'General';
            app.GeneralButton.Value = true;
            app.Iterations_4 = uipanel(app.TVPGrid);
            app.Iterations_4.Title = 'Iterations';
            app.GridLayout18_7 = uigridlayout(app.Iterations_4);
            app.Bu_tvp = uieditfield(app.GridLayout18_7, 'numeric');
            app.Bu_tvp.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.Bu_tvp.Tag = 'Bu';
            app.Bu_tvp.Tooltip = {'Bu'};
            app.Bu_tvp.Value = 1000;
            app.It_tvp = uieditfield(app.GridLayout18_7, 'numeric');
            app.It_tvp.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.It_tvp.Tag = 'It';
            app.It_tvp.Tooltip = {'It'};
            app.It_tvp.Value = 2000;
            app.NumberofburniniterationsEditFieldLabel_7 = uilabel(app.GridLayout18_7);
            app.NumberofburniniterationsEditFieldLabel_7.Text = 'Number of burn-in iterations';
            app.TotalnumberofiterationsEditFieldLabel_7 = uilabel(app.GridLayout18_7);
            app.TotalnumberofiterationsEditFieldLabel_7.Text = 'Total number of iterations:';
            app.FAVAROptionsTVP = uipanel(app.TVPGrid);
            app.FAVAROptionsTVP.Title = 'FAVAR Options';
            app.FAVARCheckBox6 = uicheckbox(app.FAVAROptionsTVP);
            app.FAVARCheckBox6.ValueChangedFcn = createCallbackFcn(app, @FAVARCheckBox6ValueChanged, true);
            app.FAVARCheckBox6.Text = 'FAVAR';
            app.FAVAROptionsButton6 = uibutton(app.FAVAROptionsTVP, 'push');
            app.FAVAROptionsButton6.ButtonPushedFcn = createCallbackFcn(app, @FAVAROptionsButton6Pushed, true);
            app.FAVAROptionsButton6.Enable = 'off';
            app.FAVAROptionsButton6.Text = 'FAVAR Options';
            app.MixedFrequencyTab = uitab(app.VARtypeTab);
            app.MixedFrequencyTab.Title = 'Mixed Frequency';
            app.GridLayout26 = uigridlayout(app.MixedFrequencyTab);
            app.Iterations_5 = uipanel(app.GridLayout26);
            app.Iterations_5.Title = 'Iterations';
            app.GridLayout18_8 = uigridlayout(app.Iterations_5);
            app.Bu_mfavar = uieditfield(app.GridLayout18_8, 'numeric');
            app.Bu_mfavar.Tag = 'Bu';
            app.Bu_mfavar.Tooltip = {'Bu'};
            app.Bu_mfavar.Value = 1000;
            app.It_mfavar = uieditfield(app.GridLayout18_8, 'numeric');
            app.It_mfavar.Tag = 'It';
            app.It_mfavar.Tooltip = {'It'};
            app.It_mfavar.Value = 2000;
            app.NumberofburniniterationsEditFieldLabel_8 = uilabel(app.GridLayout18_8);
            app.NumberofburniniterationsEditFieldLabel_8.Text = 'Number of burn-in iterations';
            app.TotalnumberofiterationsEditFieldLabel_8 = uilabel(app.GridLayout18_8);
            app.TotalnumberofiterationsEditFieldLabel_8.Text = 'Total number of iterations:';
            app.OptionsPanel_2 = uipanel(app.GridLayout26);
            app.OptionsPanel_2.Title = 'Options';
            app.GridLayout17_8 = uigridlayout(app.OptionsPanel_2);
            app.GridsearchonexcelSwitchLabel_2 = uilabel(app.GridLayout17_8);
            app.GridsearchonexcelSwitchLabel_2.Text = 'Grid search (on excel)';
            app.hogs_mfavar = uiswitch(app.GridLayout17_8, 'slider');
            app.hogs_mfavar.Items = {'No', 'Yes'};
            app.hogs_mfavar.ItemsData = [0 1];
            app.hogs_mfavar.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.hogs_mfavar.Tag = 'hogs';
            app.hogs_mfavar.Tooltip = {'hogs'};
            app.hogs_mfavar.Value = 0;
            app.BlockexogeneityonexcelSwitchLabel_2 = uilabel(app.GridLayout17_8);
            app.BlockexogeneityonexcelSwitchLabel_2.Text = 'Block exogeneity (on excel)';
            app.bex_mfavar = uiswitch(app.GridLayout17_8, 'slider');
            app.bex_mfavar.Items = {'No', 'Yes'};
            app.bex_mfavar.ItemsData = [0 1];
            app.bex_mfavar.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.bex_mfavar.Tag = 'bex';
            app.bex_mfavar.Tooltip = {'bex'};
            app.bex_mfavar.Value = 0;
            app.DummyobservationextensionsLabel_2 = uilabel(app.GridLayout17_8);
            app.DummyobservationextensionsLabel_2.Text = 'Dummy observation extensions';
            app.scoeff_mfavar = uicheckbox(app.GridLayout17_8);
            app.scoeff_mfavar.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.scoeff_mfavar.Tag = 'scoeff';
            app.scoeff_mfavar.Tooltip = {'scoeff'};
            app.scoeff_mfavar.Text = 'Sum of coefficients';
            app.iobs_mfavar = uicheckbox(app.GridLayout17_8);
            app.iobs_mfavar.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.iobs_mfavar.Tag = 'iobs';
            app.iobs_mfavar.Tooltip = {'iobs'};
            app.iobs_mfavar.Text = 'Dummy initial observations';
            app.lrp_mfavar = uicheckbox(app.GridLayout17_8);
            app.lrp_mfavar.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.lrp_mfavar.Tag = 'lrp';
            app.lrp_mfavar.Tooltip = {'lrp'};
            app.lrp_mfavar.Text = 'Long-run priors';

            app.HyperparametersPanel_5 = uipanel(app.GridLayout26);
            app.HyperparametersPanel_5.Title = 'Hyperparameters';
            app.GridLayout17_9 = uigridlayout(app.HyperparametersPanel_5);
            app.lambda2_mfavar = uieditfield(app.GridLayout17_9, 'numeric');
            app.lambda2_mfavar.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.lambda2_mfavar.Tag = 'lambda2';
            app.lambda2_mfavar.Tooltip = {'lambda2'};
            app.lambda2_mfavar.Value = 0.5;
            app.lambda1_mfavar = uieditfield(app.GridLayout17_9, 'numeric');
            app.lambda1_mfavar.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.lambda1_mfavar.Tag = 'lambda1';
            app.lambda1_mfavar.Tooltip = {'lambda1'};
            app.lambda1_mfavar.Value = 0.1;
            app.ar_mfavar = uieditfield(app.GridLayout17_9, 'numeric');
            app.ar_mfavar.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.ar_mfavar.Tag = 'ar';
            app.ar_mfavar.Tooltip = {'ar'};
            app.ar_mfavar.Value = 1;
            app.ARcoefficientonresidualvarianceEditFieldLabel_8 = uilabel(app.GridLayout17_9);
            app.ARcoefficientonresidualvarianceEditFieldLabel_8.Text = 'Cross-variable weighting';
            app.IGscaleonresidualvarianceEditFieldLabel_8 = uilabel(app.GridLayout17_9);
            app.IGscaleonresidualvarianceEditFieldLabel_8.Text = 'Overall tightness';
            app.IGshapeonresidualvarianceEditFieldLabel_8 = uilabel(app.GridLayout17_9);
            app.IGshapeonresidualvarianceEditFieldLabel_8.Text = 'Autoregresive coefficient';
            app.LagdecayEditField_2Label_3 = uilabel(app.GridLayout17_9);
            app.LagdecayEditField_2Label_3.Text = 'Lag decay';
            app.lambda3_mfavar = uieditfield(app.GridLayout17_9, 'numeric');
            app.lambda3_mfavar.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.lambda3_mfavar.Tag = 'lambda3';
            app.lambda3_mfavar.Tooltip = {'lambda3'};
            app.lambda3_mfavar.Value = 1;
            app.ExogenousvariableEditFieldLabel_3 = uilabel(app.GridLayout17_9);
            app.ExogenousvariableEditFieldLabel_3.Text = 'Exogenous variable';
            app.lambda4_mfavar = uieditfield(app.GridLayout17_9, 'numeric');
            app.lambda4_mfavar.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.lambda4_mfavar.Tag = 'lambda4';
            app.lambda4_mfavar.Tooltip = {'lambda4'};
            app.lambda4_mfavar.Value = 100;
            app.BlockexogenetyshrinkageEditFieldLabel_3 = uilabel(app.GridLayout17_9);
            app.BlockexogenetyshrinkageEditFieldLabel_3.Text = 'Block exogenety shrinkage';
            app.lambda5_mfavar = uieditfield(app.GridLayout17_9, 'numeric');
            app.lambda5_mfavar.ValueChangedFcn = createCallbackFcn(app, @setPropCallback, true);
            app.lambda5_mfavar.Tag = 'lambda5';
            app.lambda5_mfavar.Tooltip = {'lambda5'};
            app.lambda5_mfavar.Value = 0.001;
            app.APPLICATIONSTab = uitab(app.TabGroup);
            app.APPLICATIONSTab.Title = 'APPLICATIONS';
            app.GridLayout14 = uigridlayout(app.APPLICATIONSTab);
            app.Applicationoptions = uipanel(app.GridLayout14);
            app.Applicationoptions.Title = 'Application options';
            app.GridLayout22 = uigridlayout(app.Applicationoptions);
            app.FEVD_cp = uiswitch(app.GridLayout22, 'slider');
            app.FEVD_cp.Items = {'No', 'Yes'};
            app.FEVD_cp.ItemsData = [0 1];
            app.FEVD_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.FEVD_cp.Tag = 'FEVD';
            app.FEVD_cp.Tooltip = {'FEVD'};
            app.FEVD_cp.Value = 1;
            app.ForecasterrorvarianceSwitchLabel = uilabel(app.GridLayout22);
            app.ForecasterrorvarianceSwitchLabel.Text = 'Forecast error variance: ';
            app.CF_cp = uiswitch(app.GridLayout22, 'slider');
            app.CF_cp.Items = {'No', 'Yes'};
            app.CF_cp.ItemsData = [0 1];
            app.CF_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.CF_cp.Tag = 'CF';
            app.CF_cp.Tooltip = {'CF'};
            app.CF_cp.Value = 0;
            app.ConditionalforecastsSwitchLabel = uilabel(app.GridLayout22);
            app.ConditionalforecastsSwitchLabel.Text = 'Conditional forecasts:         ';
            app.HD_cp = uiswitch(app.GridLayout22, 'slider');
            app.HD_cp.Items = {'No', 'Yes'};
            app.HD_cp.ItemsData = [0 1];
            app.HD_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.HD_cp.Tag = 'HD';
            app.HD_cp.Tooltip = {'HD'};
            app.HD_cp.Value = 1;
            app.HistoricaldecompositionsSwitchLabel = uilabel(app.GridLayout22);
            app.HistoricaldecompositionsSwitchLabel.Text = 'Historical decompositions:';
            app.F_cp = uiswitch(app.GridLayout22, 'slider');
            app.F_cp.Items = {'No', 'Yes'};
            app.F_cp.ItemsData = [0 1];
            app.F_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.F_cp.Tag = 'F';
            app.F_cp.Tooltip = {'F'};
            app.F_cp.Value = 1;
            app.UnconditionalforecastsSwitchLabel = uilabel(app.GridLayout22);
            app.UnconditionalforecastsSwitchLabel.Text = 'Unconditional forecasts:';
            app.IRF_cp = uiswitch(app.GridLayout22, 'slider');
            app.IRF_cp.Items = {'No', 'Yes'};
            app.IRF_cp.ItemsData = [0 1];
            app.IRF_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.IRF_cp.Tag = 'IRF';
            app.IRF_cp.Tooltip = {'IRF'};
            app.IRF_cp.Value = 1;
            app.ImpulseresponsefunctionsSwitchLabel = uilabel(app.GridLayout22);
            app.ImpulseresponsefunctionsSwitchLabel.Text = 'Impulse response functions:';
            app.Periodoptions = uipanel(app.GridLayout14);
            app.Periodoptions.Title = 'Period options';
            app.GridLayout21 = uigridlayout(app.Periodoptions);
            app.cband_cp = uieditfield(app.GridLayout21, 'numeric');
            app.cband_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.cband_cp.Tag = 'cband';
            app.cband_cp.Tooltip = {'cband'};
            app.cband_cp.Value = 0.67;
            app.VARcoefficientsEditFieldLabel = uilabel(app.GridLayout21);
            app.VARcoefficientsEditFieldLabel.Text = 'Credibility Intervals:';
            app.Fstartdate_cp = uieditfield(app.GridLayout21, 'text');
            app.Fstartdate_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.Fstartdate_cp.Tag = 'Fstartdate';
            app.Fstartdate_cp.Tooltip = {'Fstartdate'};
            app.Fstartdate_cp.Value = '2014q1';
            app.ForecastsStartdateEditFieldLabel = uilabel(app.GridLayout21);
            app.ForecastsStartdateEditFieldLabel.Text = 'Forecasts: Start date:';
            app.Fendsmpl_cp = uiswitch(app.GridLayout21, 'slider');
            app.Fendsmpl_cp.Items = {'No', 'Yes'};
            app.Fendsmpl_cp.ItemsData = [0 1];
            app.Fendsmpl_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.Fendsmpl_cp.Tag = 'Fendsmpl';
            app.Fendsmpl_cp.Tooltip = {'Fendsmpl'};
            app.Fendsmpl_cp.Value = 0;
            app.ForecastsafterlastsampleperiodSwitchLabel = uilabel(app.GridLayout21);
            app.ForecastsafterlastsampleperiodSwitchLabel.Text = 'Forecasts after last sample period:';
            app.IRFperiods_cp = uieditfield(app.GridLayout21, 'numeric');
            app.IRFperiods_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.IRFperiods_cp.Tag = 'IRFperiods';
            app.IRFperiods_cp.Tooltip = {'IRFperiods'};
            app.IRFperiods_cp.Value = 20;
            app.IRFperiodsEditFieldLabel = uilabel(app.GridLayout21);
            app.IRFperiodsEditFieldLabel.Text = 'IRF periods:';
            app.Fenddate_cp = uieditfield(app.GridLayout21, 'text');
            app.Fenddate_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.Fenddate_cp.Tag = 'Fenddate';
            app.Fenddate_cp.Tooltip = {'Fenddate'};
            app.Fenddate_cp.Value = '2016q4';
            app.ForecastsEnddateEditFieldLabel = uilabel(app.GridLayout21);
            app.ForecastsEnddateEditFieldLabel.Text = 'Forecasts: End date:';
            app.CFt = uibuttongroup(app.GridLayout14);
            app.CFt.SelectionChangedFcn = createCallbackFcn(app, @CFtSelectionChanged, true);
            app.CFt.Title = 'Types of conditional forecasts';
            app.Standardallshocks = uiradiobutton(app.CFt);
            app.Standardallshocks.Text = 'Standard (all shocks)';
            app.Standardallshocks.Value = true;
            app.Standardshockspecific = uiradiobutton(app.CFt);
            app.Standardshockspecific.Text = 'Standard (shock specific)';
            app.Tiltingmedian = uiradiobutton(app.CFt);
            app.Tiltingmedian.Text = 'Titling (median)';
            app.Tiltinginterval = uiradiobutton(app.CFt);
            app.Tiltinginterval.Text = 'Titling (interval)';
            app.Estimationoptions = uipanel(app.GridLayout14);
            app.Estimationoptions.Title = 'Estimation options:';
            app.GridLayout19 = uigridlayout(app.Estimationoptions);
            app.evaluation_size_cp = uieditfield(app.GridLayout19, 'numeric');
            app.evaluation_size_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.evaluation_size_cp.Tag = 'evaluation_size';
            app.evaluation_size_cp.Tooltip = {'evaluation_size'};
            app.evaluation_size_cp.Value = 0.5;
            app.EvaluationSizeEditFieldLabel = uilabel(app.GridLayout19);
            app.EvaluationSizeEditFieldLabel.Text = 'Evaluation Size:';
            app.window_size_cp = uieditfield(app.GridLayout19, 'numeric');
            app.window_size_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.window_size_cp.Tag = 'window_size';
            app.window_size_cp.Tooltip = {'window_size'};
            app.RollingWindow0forfullsampleEditFieldLabel = uilabel(app.GridLayout19);
            app.RollingWindow0forfullsampleEditFieldLabel.Text = 'Rolling Window (0 for full sample):';
            app.hstep_cp = uieditfield(app.GridLayout19, 'numeric');
            app.hstep_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.hstep_cp.Tag = 'hstep';
            app.hstep_cp.Tooltip = {'hstep'};
            app.hstep_cp.Value = 1;
            app.ForecaststepaheadevaluationsEditFieldLabel = uilabel(app.GridLayout19);
            app.ForecaststepaheadevaluationsEditFieldLabel.Text = 'Forecast step ahead evaluations:';
            app.Feval_cp = uiswitch(app.GridLayout19, 'slider');
            app.Feval_cp.Items = {'No', 'Yes'};
            app.Feval_cp.ItemsData = [0 1];
            app.Feval_cp.Tag = 'Feval';
            app.Feval_cp.Tooltip = {'Feval'};
            app.Feval_cp.Value = 1;
            app.ForecastevaluationsSwitchLabel = uilabel(app.GridLayout19);
            app.ForecastevaluationsSwitchLabel.Text = 'Forecast evaluations:';
            app.ProxyVars = uipanel(app.GridLayout14);
            app.ProxyVars.Title = 'Proxy SVAR options';
            app.GridLayout20 = uigridlayout(app.ProxyVars);
            app.CorrelInstrument = uieditfield(app.GridLayout20, 'text');
            app.CorrelInstrument.ValueChangedFcn = createCallbackFcn(app, @StructidentValueChanged, true);
            app.CorrelInstrument.Tag = 'CorrelInstrument';
            app.CorrelInstrument.Tooltip = {'CorrelInstrument'};
            app.CorrelInstrumentLabel = uilabel(app.GridLayout20);
            app.CorrelInstrumentLabel.Text = 'Correl Instrument';
            app.CorrelShock = uieditfield(app.GridLayout20, 'text');
            app.CorrelShock.ValueChangedFcn = createCallbackFcn(app, @StructidentValueChanged, true);
            app.CorrelShock.Tag = 'CorrelShock';
            app.CorrelShock.Tooltip = {'CorrelShock'};
            app.CorrelShockLabel = uilabel(app.GridLayout20);
            app.CorrelShockLabel.Text = 'Correl Shock';
            app.prior_type_proxy = uiswitch(app.GridLayout20, 'slider');
            app.prior_type_proxy.Items = {'No', 'Yes'};
            app.prior_type_proxy.ItemsData = [0 1];
            app.prior_type_proxy.ValueChangedFcn = createCallbackFcn(app, @StructidentValueChanged, true);
            app.prior_type_proxy.Tag = 'prior_type_proxy';
            app.prior_type_proxy.Tooltip = {'prior_type_proxy'};
            app.prior_type_proxy.Value = 1;
            app.HighrelevancepriorLabel = uilabel(app.GridLayout20);
            app.HighrelevancepriorLabel.Text = 'High relevance prior';
            app.prior_type_reduced_form = uiswitch(app.GridLayout20, 'slider');
            app.prior_type_reduced_form.Items = {'No', 'Yes'};
            app.prior_type_reduced_form.ItemsData = [0 1];
            app.prior_type_reduced_form.ValueChangedFcn = createCallbackFcn(app, @StructidentValueChanged, true);
            app.prior_type_reduced_form.Tag = 'prior_type_reduced_form';
            app.prior_type_reduced_form.Tooltip = {'prior_type_reduced_form'};
            app.prior_type_reduced_form.Value = 1;
            app.FlatreducedformpriorLabel = uilabel(app.GridLayout20);
            app.FlatreducedformpriorLabel.Text = 'Flat reduced form prior:';
            app.Instrument = uieditfield(app.GridLayout20, 'text');
            app.Instrument.ValueChangedFcn = createCallbackFcn(app, @StructidentValueChanged, true);
            app.Instrument.Tag = 'Instrument';
            app.Instrument.Tooltip = {'Instrument'};
            app.enddateIV = uieditfield(app.GridLayout20, 'text');
            app.enddateIV.ValueChangedFcn = createCallbackFcn(app, @StructidentValueChanged, true);
            app.enddateIV.Tag = 'enddateIV';
            app.enddateIV.Tooltip = {'enddateIV'};
            app.InstrumentEnddateLabel = uilabel(app.GridLayout20);
            app.InstrumentEnddateLabel.Text = 'Instrument: End date:';
            app.startdateIV = uieditfield(app.GridLayout20, 'text');
            app.startdateIV.ValueChangedFcn = createCallbackFcn(app, @StructidentValueChanged, true);
            app.startdateIV.Tag = 'startdateIV';
            app.startdateIV.Tooltip = {'startdateIV'};
            app.InstrumentstartdateLabel = uilabel(app.GridLayout20);
            app.InstrumentstartdateLabel.Text = 'Instrument: Start date:';
            app.InstrumentLabel = uilabel(app.GridLayout20);
            app.InstrumentLabel.Text = 'Instrument:';
            app.IRFt = uibuttongroup(app.GridLayout14);
            app.IRFt.SelectionChangedFcn = createCallbackFcn(app, @IRFtSelectionChanged, true);
            app.IRFt.Tooltip = {'IRFt'};
            app.IRFt.Title = 'Structural identifications';
            app.Noidentification = uiradiobutton(app.IRFt);
            app.Noidentification.Text = 'None';
            app.Choleskifactorisation = uiradiobutton(app.IRFt);
            app.Choleskifactorisation.Text = 'Cholesky';
            app.Triangularfactorisation = uiradiobutton(app.IRFt);
            app.Triangularfactorisation.Text = 'Triangular';
            app.Signrestrictions = uiradiobutton(app.IRFt);
            app.Signrestrictions.Text = 'Sign restrictions';
            app.Signrestrictions.Value = true;
            app.Proxy = uiradiobutton(app.IRFt);
            app.Proxy.Text = 'Proxy SVAR';
            app.Proxysign = uiradiobutton(app.IRFt);
            app.Proxysign.Text = 'Sign and proxy';
            % Show the figure after all components are created
            app.BEARinterfaceR2020aUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BEARapp20a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BEARinterfaceR2020aUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BEARinterfaceR2020aUIFigure)
        end
    end
end