classdef BEARapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BEARinterfaceUIFigure           matlab.ui.Figure
        TabGrid                         matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        SPECIFICATIONTab                matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout11                    matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        BayesianTab                     matlab.ui.container.Tab
        BayesianGrid                    matlab.ui.container.GridLayout
        prior                           matlab.ui.container.ButtonGroup
        OLSTab                          matlab.ui.container.Tab
        DeterministicButton             matlab.ui.control.RadioButton
        IndependentNormalWishartButton  matlab.ui.control.RadioButton
        NormalWishartButton             matlab.ui.control.RadioButton
        DummyObservationsButton         matlab.ui.control.RadioButton
        NormalDiffuseButton             matlab.ui.control.RadioButton
        MinnesotaButton                 matlab.ui.control.RadioButton
        HyperparametersPanel_3          matlab.ui.container.Panel
        GridLayout17_6                  matlab.ui.container.GridLayout
        priorsexogenous_bvar            matlab.ui.control.Switch
        ExogenousvariablesSwitchLabel   matlab.ui.control.Label
        lambda8_bvar                    matlab.ui.control.NumericEditField
        LongrunpriortightnessEditFieldLabel  matlab.ui.control.Label
        lambda7_bvar                    matlab.ui.control.NumericEditField
        DummyinitialobservationtightnessLabel  matlab.ui.control.Label
        lambda6_bvar                    matlab.ui.control.NumericEditField
        SumofcoefficienttightnessEditFieldLabel  matlab.ui.control.Label
        lambda5_bvar                    matlab.ui.control.NumericEditField
        BlockexogenetyshrinkageEditFieldLabel  matlab.ui.control.Label
        lambda4_bvar                    matlab.ui.control.NumericEditField
        ExogenousvariableEditFieldLabel  matlab.ui.control.Label
        PriorExcel_bvar                 matlab.ui.control.CheckBox
        lambda3_bvar                    matlab.ui.control.NumericEditField
        LagdecayEditField_2Label        matlab.ui.control.Label
        IGshapeonresidualvarianceEditFieldLabel_6  matlab.ui.control.Label
        IGscaleonresidualvarianceEditFieldLabel_6  matlab.ui.control.Label
        ARcoefficientonresidualvarianceEditFieldLabel_6  matlab.ui.control.Label
        ar_bvar                         matlab.ui.control.NumericEditField
        lambda1_bvar                    matlab.ui.control.NumericEditField
        lambda2_bvar                    matlab.ui.control.NumericEditField
        OptionsPanel                    matlab.ui.container.Panel
        GridLayout17_5                  matlab.ui.container.GridLayout
        DumyobservationextensionsLabel  matlab.ui.control.Label
        bex_bvar                        matlab.ui.control.Switch
        BlockexogeneityonexcelSwitchLabel  matlab.ui.control.Label
        hogs_bvar                       matlab.ui.control.Switch
        GridsearchonexcelSwitchLabel    matlab.ui.control.Label
        lrp_bvar                        matlab.ui.control.CheckBox
        iobs_bvar                       matlab.ui.control.CheckBox
        scoeff_bvar                     matlab.ui.control.CheckBox
        Iterations                      matlab.ui.container.Panel
        GridLayout18_4                  matlab.ui.container.GridLayout
        TotalnumberofiterationsEditFieldLabel_4  matlab.ui.control.Label
        NumberofburniniterationsEditFieldLabel_4  matlab.ui.control.Label
        It_bvar                         matlab.ui.control.NumericEditField
        Bu_bvar                         matlab.ui.control.NumericEditField
        PanelTab                        matlab.ui.container.Tab
        PanelGrid                       matlab.ui.container.GridLayout
        panel                           matlab.ui.container.ButtonGroup
        DynamicStructureFactorButton    matlab.ui.control.RadioButton
        RandomEffectHierarchicalButton  matlab.ui.control.RadioButton
        PooledEstimatorButton           matlab.ui.control.RadioButton
        StaticStructureFactorButton     matlab.ui.control.RadioButton
        RandomEffectZellnerHongButton   matlab.ui.control.RadioButton
        MeangroupEstimatorButton        matlab.ui.control.RadioButton
        Iterations_2                    matlab.ui.container.Panel
        GridLayout18_5                  matlab.ui.container.GridLayout
        TotalnumberofiterationsEditFieldLabel_5  matlab.ui.control.Label
        NumberofburniniterationsEditFieldLabel_5  matlab.ui.control.Label
        It_panel                        matlab.ui.control.NumericEditField
        Bu_panel                        matlab.ui.control.NumericEditField
        EnterlistofunitsPanel           matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        unitnames_panel                 matlab.ui.control.EditField
        HyperparametersPanel            matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        IGshapeonresidualvarianceEditFieldLabel  matlab.ui.control.Label
        IGscaleonresidualvarianceEditFieldLabel  matlab.ui.control.Label
        ARcoefficientonresidualvarianceEditFieldLabel  matlab.ui.control.Label
        IGshapeonfactorvarianceEditFieldLabel  matlab.ui.control.Label
        IGscaleonfactorvarianceEditFieldLabel  matlab.ui.control.Label
        ARcoefficientonfactorEditFieldLabel  matlab.ui.control.Label
        VarianceonMetropolisdrawEditFieldLabel  matlab.ui.control.Label
        alpha0_panel                    matlab.ui.control.NumericEditField
        delta0_panel                    matlab.ui.control.NumericEditField
        gama_panel                      matlab.ui.control.NumericEditField
        a0_panel                        matlab.ui.control.NumericEditField
        b0_panel                        matlab.ui.control.NumericEditField
        rho_panel                       matlab.ui.control.NumericEditField
        psi_panel                       matlab.ui.control.NumericEditField
        HyperparametersPanel_panel      matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        AutoregressivecoefficientEditFieldLabel  matlab.ui.control.Label
        OveralltightnessEditFieldLabel  matlab.ui.control.Label
        CrossvariableweightingEditFieldLabel  matlab.ui.control.Label
        LagdecayEditFieldLabel          matlab.ui.control.Label
        ExogenousvariabletightnessEditFieldLabel  matlab.ui.control.Label
        IGshapeonoveralltightnessEditFieldLabel  matlab.ui.control.Label
        IGscaleonoveralltightnessEditFieldLabel  matlab.ui.control.Label
        ar_panel                        matlab.ui.control.NumericEditField
        lambda1_panel                   matlab.ui.control.NumericEditField
        lambda2_panel                   matlab.ui.control.NumericEditField
        lambda3_panel                   matlab.ui.control.NumericEditField
        lambda4_panel                   matlab.ui.control.NumericEditField
        s0_panel                        matlab.ui.control.NumericEditField
        v0_panel                        matlab.ui.control.NumericEditField
        StochasticVolatilityTab         matlab.ui.container.Tab
        SVGrid                          matlab.ui.container.GridLayout
        HyperparametersPanel_4          matlab.ui.container.Panel
        GridLayout17_7                  matlab.ui.container.GridLayout
        priorsexogenous_sv              matlab.ui.control.Switch
        ExogenousvariablesSwitchLabel_2  matlab.ui.control.Label
        lambda8_sv                      matlab.ui.control.NumericEditField
        LongrunpriortightnessEditFieldLabel_2  matlab.ui.control.Label
        lambda7_sv                      matlab.ui.control.NumericEditField
        DummyinitialobservationtightnessLabel_2  matlab.ui.control.Label
        lambda6_sv                      matlab.ui.control.NumericEditField
        SumofcoefficienttightnessEditFieldLabel_2  matlab.ui.control.Label
        lambda5_sv                      matlab.ui.control.NumericEditField
        BlockexogenetyshrinkageEditFieldLabel_2  matlab.ui.control.Label
        lambda4_sv                      matlab.ui.control.NumericEditField
        ExogenousvariableEditFieldLabel_2  matlab.ui.control.Label
        PriorExcel_sv                   matlab.ui.control.CheckBox
        lambda3_sv                      matlab.ui.control.NumericEditField
        LagdecayEditField_2Label_2      matlab.ui.control.Label
        IGshapeonresidualvarianceEditFieldLabel_7  matlab.ui.control.Label
        IGscaleonresidualvarianceEditFieldLabel_7  matlab.ui.control.Label
        ARcoefficientonresidualvarianceEditFieldLabel_7  matlab.ui.control.Label
        ar_sv                           matlab.ui.control.NumericEditField
        lambda1_sv                      matlab.ui.control.NumericEditField
        lambda2_sv                      matlab.ui.control.NumericEditField
        Iterations_3                    matlab.ui.container.Panel
        GridLayout18_6                  matlab.ui.container.GridLayout
        TotalnumberofiterationsEditFieldLabel_6  matlab.ui.control.Label
        NumberofburniniterationsEditFieldLabel_6  matlab.ui.control.Label
        It_sv                           matlab.ui.control.NumericEditField
        Bu_sv                           matlab.ui.control.NumericEditField
        stvol                           matlab.ui.container.ButtonGroup
        StochasticButton                matlab.ui.control.RadioButton
        LargeVARButton                  matlab.ui.control.RadioButton
        RandomInertiaButton             matlab.ui.control.RadioButton
        StandardButton                  matlab.ui.control.RadioButton
        HyperparametersstochasticPanel  matlab.ui.container.Panel
        GridLayout17_3                  matlab.ui.container.GridLayout
        alltirf_sv                      matlab.ui.control.CheckBox
        IGshapeonresidualvarianceEditFieldLabel_3  matlab.ui.control.Label
        IGscaleonresidualvarianceEditFieldLabel_3  matlab.ui.control.Label
        ARcoefficientonresidualvarianceEditFieldLabel_3  matlab.ui.control.Label
        IGshapeonfactorvarianceEditFieldLabel_3  matlab.ui.control.Label
        IGscaleonfactorvarianceEditFieldLabel_3  matlab.ui.control.Label
        gamma_sv                        matlab.ui.control.NumericEditField
        alpha0_sv                       matlab.ui.control.NumericEditField
        delta0_sv                       matlab.ui.control.NumericEditField
        gamma0_sv                       matlab.ui.control.NumericEditField
        zeta0_sv                        matlab.ui.control.NumericEditField
        TimeVaryingTab                  matlab.ui.container.Tab
        TVPGrid                         matlab.ui.container.GridLayout
        Iterations_4                    matlab.ui.container.Panel
        GridLayout18_7                  matlab.ui.container.GridLayout
        TotalnumberofiterationsEditFieldLabel_7  matlab.ui.control.Label
        NumberofburniniterationsEditFieldLabel_7  matlab.ui.control.Label
        It_tvp                          matlab.ui.control.NumericEditField
        Bu_tvp                          matlab.ui.control.NumericEditField
        tvbvar                          matlab.ui.container.ButtonGroup
        GeneralButton                   matlab.ui.control.RadioButton
        VARcoefficientsButton           matlab.ui.control.RadioButton
        HyperparameterstimevaryingPanel  matlab.ui.container.Panel
        GridLayout17_2                  matlab.ui.container.GridLayout
        alltirf_tvp                     matlab.ui.control.CheckBox
        IGshapeonresidualvarianceEditFieldLabel_2  matlab.ui.control.Label
        IGscaleonresidualvarianceEditFieldLabel_2  matlab.ui.control.Label
        ARcoefficientonresidualvarianceEditFieldLabel_2  matlab.ui.control.Label
        gamma_tvp                       matlab.ui.control.NumericEditField
        alpha0_tvp                      matlab.ui.control.NumericEditField
        delta0_tvp                      matlab.ui.control.NumericEditField
        MixedFrequencyTab               matlab.ui.container.Tab
        SELECTVARTYPELabel              matlab.ui.control.Label
        GridLayout4                     matlab.ui.container.GridLayout
        GridLayout18                    matlab.ui.container.GridLayout
        ExportButton                    matlab.ui.control.Button
        RUNButton                       matlab.ui.control.Button
        GridLayout10                    matlab.ui.container.GridLayout
        SetpathtodataButton             matlab.ui.control.Button
        SetresultsfileButton            matlab.ui.control.StateButton
        SetpathtodataEditField          matlab.ui.control.EditField
        SetresultsfileEditField         matlab.ui.control.EditField
        GridLayout9                     matlab.ui.container.GridLayout
        OutputinExcelCheckBox           matlab.ui.control.CheckBox
        ProduceFiguresCheckBox          matlab.ui.control.CheckBox
        GridLayout8                     matlab.ui.container.GridLayout
        IncludeconstantSwitchLabel      matlab.ui.control.Label
        IncludeconstantSwitch           matlab.ui.control.Switch
        LagsEditFieldLabel              matlab.ui.control.Label
        LagsEditField                   matlab.ui.control.NumericEditField
        GridLayout7                     matlab.ui.container.GridLayout
        EnterthelistofexogeneousvariablesTextArea  matlab.ui.control.TextArea
        EnterthelistofexogeneousvariablesTextAreaLabel  matlab.ui.control.Label
        EnterthelistofendogeneousvariablesTextArea  matlab.ui.control.TextArea
        EnterthelistofendogeneousvariablesTextAreaLabel  matlab.ui.control.Label
        GridLayout6                     matlab.ui.container.GridLayout
        enddate                         matlab.ui.control.EditField
        EstimationEndDateEditFieldLabel  matlab.ui.control.Label
        startdate                       matlab.ui.control.EditField
        EstimationStartDateEditFieldLabel  matlab.ui.control.Label
        GridLayout5                     matlab.ui.container.GridLayout
        frequency                       matlab.ui.control.DropDown
        DataFrequencyDropDownLabel      matlab.ui.control.Label
        APPLICATIONSTab                 matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        StructuralidentificationsButtonGroup  matlab.ui.container.ButtonGroup
        Proxysign                       matlab.ui.control.RadioButton
        Proxy                           matlab.ui.control.RadioButton
        Signrestrictions                matlab.ui.control.RadioButton
        Triangularfactorisation         matlab.ui.control.RadioButton
        Choleskifactorisation           matlab.ui.control.RadioButton
        Noidentification                matlab.ui.control.RadioButton
        FAVARplotPanel                  matlab.ui.container.Panel
        plotXshock                      matlab.ui.control.EditField
        PlotXshockLabel                 matlab.ui.control.Label
        plotX                           matlab.ui.control.EditField
        PlotXLabel                      matlab.ui.control.Label
        ProxyVars                       matlab.ui.container.Panel
        Correlinstrument                matlab.ui.control.EditField
        CorrelInstrumentLabel           matlab.ui.control.Label
        Correlshock                     matlab.ui.control.EditField
        CorrelShockLabel                matlab.ui.control.Label
        prior_type_proxy                matlab.ui.control.Switch
        HighrelevancepriorLabel         matlab.ui.control.Label
        prior_type_reduced_form         matlab.ui.control.Switch
        FlatreducedformpriorLabel       matlab.ui.control.Label
        Instrument                      matlab.ui.control.EditField
        enddateIV                       matlab.ui.control.EditField
        InstrumentEnddateLabel          matlab.ui.control.Label
        startdateIV                     matlab.ui.control.EditField
        InstrumentstartdateLabel        matlab.ui.control.Label
        InstrumentLabel                 matlab.ui.control.Label
        Estimationoptions               matlab.ui.container.Panel
        evaluation_size                 matlab.ui.control.NumericEditField
        EvaluationSizeEditFieldLabel    matlab.ui.control.Label
        window_size                     matlab.ui.control.NumericEditField
        RollingWindow0forfullsampleEditFieldLabel  matlab.ui.control.Label
        hstep                           matlab.ui.control.NumericEditField
        ForecaststepaheadevaluationsEditFieldLabel  matlab.ui.control.Label
        Feval                           matlab.ui.control.Switch
        ForecastevaluationsSwitchLabel  matlab.ui.control.Label
        CFt                             matlab.ui.container.ButtonGroup
        Tiltinginterval                 matlab.ui.control.RadioButton
        Tiltingmedian                   matlab.ui.control.RadioButton
        Standardshockspecific           matlab.ui.control.RadioButton
        Standardallshocks               matlab.ui.control.RadioButton
        Periodoptions                   matlab.ui.container.Panel
        cband                           matlab.ui.control.NumericEditField
        VARcoefficientsEditFieldLabel   matlab.ui.control.Label
        Fenddate                        matlab.ui.control.EditField
        ForecastsEnddateLabel           matlab.ui.control.Label
        Fstartdate                      matlab.ui.control.EditField
        ForecastsStartdateEditFieldLabel  matlab.ui.control.Label
        Fendsmpl                        matlab.ui.control.Switch
        ForecastsafterlastsampleperiodSwitchLabel  matlab.ui.control.Label
        IRFperiods                      matlab.ui.control.NumericEditField
        IRFperiodsEditFieldLabel        matlab.ui.control.Label
        Applicationoptions              matlab.ui.container.Panel
        favarHDplot                     matlab.ui.control.CheckBox
        favarFEVDplot                   matlab.ui.control.CheckBox
        favarIRFplot                    matlab.ui.control.CheckBox
        FEVD                            matlab.ui.control.Switch
        ForecasterrorvarianceSwitchLabel  matlab.ui.control.Label
        CF                              matlab.ui.control.Switch
        ConditionalforecastsSwitchLabel  matlab.ui.control.Label
        HD                              matlab.ui.control.Switch
        HistoricaldecompositionsSwitchLabel  matlab.ui.control.Label
        F                               matlab.ui.control.Switch
        UnconditionalforecastsSwitchLabel  matlab.ui.control.Label
        IRF                             matlab.ui.control.Switch
        ImpulseresponsefunctionsSwitchLabel  matlab.ui.control.Label
        REPLICATIONSTab                 matlab.ui.container.Tab
        ABOUTBEARTab                    matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        GridLayout12                    matlab.ui.container.GridLayout
        Label                           matlab.ui.control.Label
        ByAlistairDieppeandBjrnvanRoyeLabel  matlab.ui.control.Label
        BAYESIANESTIMATIONANALYSISANDREGRESSIONBEARTOOLBOXLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        opts (1,1) bear.app.optionsProxyForApp % BEAR settings object
    end

    methods (Access = private)

        function setProp(app, prop, field, value)
            try
                app.opts.setProp(prop, value);
            catch e
                app.(field).Value = app.opts.opts.(prop);
                uialert(app.BEARinterfaceUIFigure, e.message, "Something went wrong!")
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: tvbvar
        function tvbvarSelectionChanged(app, event)
            selectedButton = app.tvbvar.SelectedObject;
            switch selectedButton.Text
                case "General"
                    app.HyperparameterstimevaryingPanel.Enable = 'on';
                    app.setProp('tvbvar', 'tvbvar', 'TVP_SV');
                case "VAR coefficients"
                    app.HyperparameterstimevaryingPanel.Enable = 'off';
                    app.setProp('tvbvar', 'tvbvar', 'TVP');
            end
        end

        % Selection changed function: stvol
        function stvolSelectionChanged(app, event)
            selectedButton = app.stvol.SelectedObject;
            switch selectedButton.Text
                case "Standard"
                    app.HyperparametersstochasticPanel.Enable = 'on';
                    app.IGshapeonresidualvarianceEditFieldLabel_3.Enable = 'on';
                    app.gamma_sv.Enable = 'on';
                    app.IGscaleonfactorvarianceEditFieldLabel_3.Enable = 'off';
                    app.zeta0_sv.Enable = 'off';
                    app.IGshapeonfactorvarianceEditFieldLabel_3.Enable = 'off';
                    app.gamma0_sv.Enable = 'off';
                    app.setProp('stvol', 'stvol', 'Standard');
                case "Random Inertia"
                    app.HyperparametersstochasticPanel.Enable = 'on';
                    app.IGshapeonresidualvarianceEditFieldLabel_3.Enable = 'off';
                    app.gamma_sv.Enable = 'off';
                    app.IGscaleonfactorvarianceEditFieldLabel_3.Enable = 'on';
                    app.zeta0_sv.Enable = 'on';
                    app.IGshapeonfactorvarianceEditFieldLabel_3.Enable = 'on';
                    app.gamma0_sv.Enable = 'on';
                    app.setProp('stvol', 'stvol', 'Random_scaling');
                case "Large VAR"
                    app.HyperparametersstochasticPanel.Enable = 'on';
                    app.IGshapeonresidualvarianceEditFieldLabel_3.Enable = 'on';
                    app.gamma_sv.Enable = 'on';
                    app.IGscaleonfactorvarianceEditFieldLabel_3.Enable = 'off';
                    app.zeta0_sv.Enable = 'off';
                    app.IGshapeonfactorvarianceEditFieldLabel_3.Enable = 'off';
                    app.gamma0_sv.Enable = 'off';
                    app.setProp('stvol', 'stvol', 'Large_BVAR');
                case "Stochastic"
                    app.HyperparametersstochasticPanel.Enable = 'off';
                    app.IGscaleonfactorvarianceEditFieldLabel_3.Enable = 'on';
                    app.zeta0_sv.Enable = 'on';
                    app.IGshapeonfactorvarianceEditFieldLabel_3.Enable = 'on';
                    app.gamma0_sv.Enable = 'on';
                    app.setProp('stvol', 'stvol', 'LMM');
            end
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            selectedTab = app.TabGroup2.SelectedTab;
            switch selectedTab.Title
                case "OLS"
                    app.opts.VARtype = 1;
                case "Bayesian"
                    app.opts.VARtype = 2;
                case "Panel"
                    app.opts.VARtype = 4;
                case "Stochastic Volatility"
                    app.opts.VARtype = 5;
                case "Time Varying"
                    app.opts.VARtype = 6;
            end
        end

        % Value changed function: It_bvar
        function It_bvarValueChanged(app, event)
            value = app.It_bvar.Value;
            app.setProp('It', 'It_bvar', value);
        end

        % Value changed function: Bu_bvar
        function Bu_bvarValueChanged(app, event)
            value = app.Bu_bvar.Value;
            app.setProp('Bu', 'Bu_bvar', value);
        end

        % Value changed function: It_panel
        function It_panelValueChanged(app, event)
            value = app.It_panel.Value;
            app.setProp('It', 'It_panel', value);
        end

        % Value changed function: Bu_panel
        function Bu_panelValueChanged(app, event)
            value = app.Bu_panel.Value;
            app.setProp('Bu', 'Bu_panel', value);
        end

        % Value changed function: It_sv
        function It_svValueChanged(app, event)
            value = app.It_sv.Value;
            app.setProp('Bu', 'It_sv', value);
        end

        % Value changed function: Bu_sv
        function Bu_svValueChanged(app, event)
            value = app.Bu_sv.Value;
            app.setProp('It', 'Bu_sv', value);
        end

        % Value changed function: It_tvp
        function It_tvpValueChanged(app, event)
            value = app.It_tvp.Value;
            app.setProp('It', 'It_tvp', value);
        end

        % Value changed function: Bu_tvp
        function Bu_tvpValueChanged(app, event)
            value = app.Bu_tvp.Value;
            app.setProp('Bu', 'Bu_tvp', value);
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            try
                d = uiprogressdlg(app.BEARinterfaceUIFigure,'Indeterminate', 'on');
                BEARmain(app.opts.opts)
                delete(d);
            catch e
                uialert(app.BEARinterfaceUIFigure, e.message, 'Something went wrong!')
            end
        end

        % Value changed function: CF
        function CFValueChanged(app, event)
            switch app.CF.Value
                case 'on'
                    opts.CF = true;
            end

        end

        % Callback function
        function EstimationEndDateDatePicker_2ValueChanged(app, event)
            value = app.EstimationEndDateDatePicker_2.Value;
            app.opts.setCommonProp('enddate', strrep(char(value),'Q','q'));
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            assignin("base", 'opts', app.opts.opts)
        end

        % Value changed function: 
        % EnterthelistofendogeneousvariablesTextArea
        function EnterthelistofendogeneousvariablesTextAreaValueChanged(app, event)
            value = app.EnterthelistofendogeneousvariablesTextArea.Value;
            app.opts.setCommonProp('varendo', value{1});
        end

        % Value changed function: 
        % EnterthelistofexogeneousvariablesTextArea
        function EnterthelistofexogeneousvariablesTextAreaValueChanged(app, event)
            value = app.EnterthelistofexogeneousvariablesTextArea.Value;
            app.opts.setCommonProp('varexo', value{1});
        end

        % Value changed function: LagsEditField
        function LagsEditFieldValueChanged(app, event)
            app.opts.setCommonProp('lags', app.LagsEditField.Value);
        end

        % Value changed function: OutputinExcelCheckBox
        function OutputinExcelCheckBoxValueChanged(app, event)
            value = app.OutputinExcelCheckBox.Value;
            pref = app.opts.opts.pref;
            pref.results = value;
            app.opts.setCommonProp('pref', pref);
        end

        % Value changed function: ProduceFiguresCheckBox
        function ProduceFiguresCheckBoxValueChanged(app, event)
            value = app.ProduceFiguresCheckBox.Value;
            pref = app.opts.opts.pref;
            pref.plot = value;
            app.opts.setCommonProp('pref', pref);
        end

        % Value changed function: IncludeconstantSwitch
        function IncludeconstantSwitchValueChanged(app, event)
            value = app.IncludeconstantSwitch.Value;
            switch value
                case 'on'
                    app.opts.setCommonProp('const', true);
                case 'off'
                    app.opts.setCommonProp('const', false);
            end
        end

        % Value changed function: SetresultsfileButton
        function SetresultsfileButtonValueChanged(app, event)
            selpath = uigetdir(pwd);
            if ~isequal(selpath,0)
                pref = app.opts.opts.pref;
                pref.results_path = selpath;
                app.opts.setCommonProp('pref', pref);
            end
        end

        % Button pushed function: SetpathtodataButton
        function SetpathtodataButtonPushed(app, event)
            [file,path] = uigetfile('*.xlsx');
            if ~isequal(file,0)
                app.SetpathtodataEditField.Value = fullfile(path,file);
                pref = app.opts.opts.pref;
                pref.excelFile = fullfile(path,file);
                app.opts.setCommonProp('pref', pref);
            end
        end

        % Value changed function: SetresultsfileEditField
        function SetresultsfileEditFieldValueChanged(app, event)
            value = app.SetresultsfileEditField.Value;
            pref = app.opts.opts.pref;
            pref.results_sub = value;
            app.opts.setCommonProp('pref', pref);
        end

        % Value changed function: frequency
        function frequencyValueChanged(app, event)
            value = app.frequency.Value;
            app.opts.setCommonProp('frequency', value);
        end

        % Value changed function: ar_bvar
        function ar_bvarValueChanged(app, event)
            value = app.ar_bvar.Value;
            app.setProp('ar', 'ar_bvar', value);
        end

        % Value changed function: lambda1_bvar
        function lambda1_bvarValueChanged(app, event)
            value = app.lambda1_bvar.Value;
            app.setProp('lambda1', 'lambda1_bvar', value);
        end

        % Value changed function: lambda2_bvar
        function lambda2_bvarValueChanged(app, event)
            value = app.lambda2_bvar.Value;
            app.setProp('lambda2', 'lambda2_bvar', value);
        end

        % Value changed function: lambda3_bvar
        function lambda3_bvarValueChanged(app, event)
            value = app.lambda3_bvar.Value;
            app.setProp('lambda3', 'lambda3_bvar', value);
        end

        % Value changed function: lambda4_bvar
        function lambda4_bvarValueChanged(app, event)
            value = app.lambda4_bvar.Value;
            app.setProp('lambda4', 'lambda4_bvar', value);
        end

        % Selection changed function: prior
        function priorSelectionChanged(app, event)
            switch event.NewValue.Text
                case "Minnesota"
                    pr = bear.PRIORtype('Minnesota_univariate');
                case "Normal Diffuse"
                    pr = bear.PRIORtype('Nd');
                case "Dummy Observations"
                    pr = bear.PRIORtype('Dummy');
                case "Normal Wishart"
                    pr = bear.PRIORtype('Nw_univariate');
                case "Independent Normal Wishart"
                    pr = bear.PRIORtype('Inw_univariate');
                case "Deterministic"
                    pr = bear.PRIORtype('Madj');
            end
            app.setProp('prior', 'prior', pr);
        end

        % Value changed function: PriorExcel_bvar
        function PriorExcel_bvarValueChanged(app, event)
            value = app.PriorExcel_bvar.Value;
            if value
                app.ar_bvar.Enable = false;
            else
                app.ar_bvar.Enable = true;
            end
            app.setProp('PriorExcel', 'PriorExcel_bvar', value);
        end

        % Value changed function: lambda5_bvar
        function lambda5_bvarValueChanged(app, event)
            value = app.lambda5_bvar.Value;
            app.setProp('lambda5', 'lambda5_bvar', value);
        end

        % Value changed function: lambda6_bvar
        function lambda6_bvarValueChanged(app, event)
            value = app.lambda6_bvar.Value;
            app.setProp('lambda6', 'lambda6_bvar', value);
        end

        % Value changed function: lambda7_bvar
        function lambda7_bvarValueChanged(app, event)
            value = app.lambda7_bvar.Value;
            app.setProp('lambda7', 'lambda7_bvar', value);
        end

        % Value changed function: lambda8_bvar
        function lambda8_bvarValueChanged(app, event)
            value = app.lambda8_bvar.Value;
            app.setProp('lambda8', 'lambda8_bvar', value);
        end

        % Value changed function: hogs_bvar
        function hogs_bvarValueChanged(app, event)
            value = app.hogs_bvar.Value;
            if value
                app.lambda1_bvar.Enable = false;
                app.lambda2_bvar.Enable = false;
                app.lambda3_bvar.Enable = false;
                app.lambda4_bvar.Enable = false;
            else
                app.lambda1_bvar.Enable = true;
                app.lambda2_bvar.Enable = true;
                app.lambda3_bvar.Enable = true;
                app.lambda4_bvar.Enable = true;
            end
            app.setProp('hogs', 'hogs_bvar', value);
        end

        % Value changed function: bex_bvar
        function bex_bvarValueChanged(app, event)
            value = app.bex_bvar.Value;
            if value
                app.lambda5_bvar.Enable = true;
            else
                app.lambda5_bvar.Enable = false;
            end
            app.setProp('bex', 'bex_bvar', value);
        end

        % Value changed function: scoeff_bvar
        function scoeff_bvarValueChanged(app, event)
            value = app.scoeff_bvar.Value;
            if value
                app.lambda6_bvar.Enable = true;
            else
                app.lambda6_bvar.Enable = false;
            end
            app.setProp('scoeff', 'scoeff_bvar', value);
        end

        % Value changed function: iobs_bvar
        function iobs_bvarValueChanged(app, event)
            value = app.iobs_bvar.Value;
            if value
                app.lambda7_bvar.Enable = true;
            else
                app.lambda7_bvar.Enable = false;
            end
            app.setProp('iobs', 'iobs_bvar', value);
        end

        % Value changed function: lrp_bvar
        function lrp_bvarValueChanged(app, event)
            value = app.lrp_bvar.Value;
            if value
                app.lambda8_bvar.Enable = true;
            else
                app.lambda8_bvar.Enable = false;
            end
            app.setProp('lrp', 'lrp_bvar', value);
        end

        % Value changed function: priorsexogenous_bvar
        function priorsexogenous_bvarValueChanged(app, event)
            value = app.priorsexogenous_bvar.Value;
            app.setProp('priorsexogenous', 'priorsexogenous_bvar', value);
        end

        % Selection changed function: panel
        function panelSelectionChanged(app, event)
            switch event.NewValue.Text
                case "Mean group Estimator"
                    pn = bear.PANELtype('Mge');
                case "Random Effect (Zellner-Hong)"
                    pn = bear.PANELtype('Random_zh');
                case "Static Structure Factor"
                    pn = bear.PANELtype('Factor_static');
                case "Pooled Estimator"
                    pn = bear.PANELtype('Pooled');
                case "Random Effect (Hierarchical)"
                    pn = bear.PANELtype('Random_hierarchical');
                case "Dynamic Structure Factor"
                    pn = bear.PANELtype('Factor_dynamic');
            end
            app.setProp('panel', 'panel', pn);
        end

        % Value changed function: ar_panel
        function ar_panelValueChanged(app, event)
            value = app.ar_panel.Value;
            app.setProp('ar', 'ar_panel', value);
        end

        % Value changed function: lambda1_panel
        function lambda1_panelValueChanged(app, event)
            value = app.lambda1_panel.Value;
            app.setProp('lambda1', 'lambda1_panel', value);
        end

        % Value changed function: lambda2_panel
        function lambda2_panelValueChanged(app, event)
            value = app.lambda2_panel.Value;
            app.setProp('lambda2', 'lambda2_panel', value);
        end

        % Value changed function: lambda3_panel
        function lambda3_panelValueChanged(app, event)
            value = app.lambda3_panel.Value;
            app.setProp('lambda3', 'lambda3_panel', value);
        end

        % Value changed function: lambda4_panel
        function lambda4_panelValueChanged(app, event)
            value = app.lambda4_panel.Value;
            app.setProp('lambda4', 'lambda4_panel', value);
        end

        % Value changed function: s0_panel
        function s0_panelValueChanged(app, event)
            value = app.s0_panel.Value;
            app.setProp('s0', 's0_panel', value);
        end

        % Value changed function: v0_panel
        function v0_panelValueChanged(app, event)
            value = app.v0_panel.Value;
            app.setProp('v0', 'v0_panel', value);
        end

        % Value changed function: unitnames_panel
        function unitnames_panelValueChanged(app, event)
            value = app.unitnames_panel.Value;
            app.setProp('unitnames', 'unitnames_panel', value);
        end

        % Value changed function: alpha0_panel
        function alpha0_panelValueChanged(app, event)
            value = app.alpha0_panel.Value;
            app.setProp('alpha0', 'alpha0_panel', value);
        end

        % Value changed function: delta0_panel
        function delta0_panelValueChanged(app, event)
            value = app.delta0_panel.Value;
            app.setProp('delta0', 'delta0_panel', value);
        end

        % Value changed function: gama_panel
        function gama_panelValueChanged(app, event)
            value = app.gama_panel.Value;
            app.setProp('gama', 'gama_panel', value);
        end

        % Value changed function: a0_panel
        function a0_panelValueChanged(app, event)
            value = app.a0_panel.Value;
            app.setProp('a0', 'a0_panel', value);
        end

        % Value changed function: b0_panel
        function b0_panelValueChanged(app, event)
            value = app.b0_panel.Value;
            app.setProp('b0', 'b0_panel', value);
        end

        % Value changed function: rho_panel
        function rho_panelValueChanged(app, event)
            value = app.rho_panel.Value;
            app.setProp('rho', 'rho_panel', value);
        end

        % Value changed function: psi_panel
        function psi_panelValueChanged(app, event)
            value = app.psi_panel.Value;
            app.setProp('psi', 'psi_panel', value);
        end

        % Value changed function: alltirf_tvp
        function alltirf_tvpValueChanged(app, event)
            value = app.alltirf_tvp.Value;
            app.setProp('alltirf', 'alltirf_tvp', value);
        end

        % Value changed function: delta0_tvp
        function delta0_tvpValueChanged(app, event)
            value = app.delta0_tvp.Value;
            app.setProp('delta0', 'delta0_tvp', value);
        end

        % Value changed function: alpha0_tvp
        function alpha0_tvpValueChanged(app, event)
            value = app.alpha0_tvp.Value;
            app.setProp('alpha0', 'alpha0_tvp', value);
        end

        % Value changed function: gamma_tvp
        function gamma_tvpValueChanged(app, event)
            value = app.gamma_tvp.Value;
            app.setProp('gamma', 'gamma_tvp', value);
        end

        % Value changed function: alpha0_sv
        function alpha0_svValueChanged(app, event)
            value = app.alpha0_sv.Value;
            app.setProp('alpha0', 'alpha0_sv', value);
        end

        % Value changed function: delta0_sv
        function delta0_svValueChanged(app, event)
            value = app.delta0_sv.Value;
            app.setProp('delta0', 'delta0_sv', value);
        end

        % Value changed function: gamma_sv
        function gamma_svValueChanged(app, event)
            value = app.gamma_sv.Value;
            app.setProp('gamma', 'gamma_sv', value);
        end

        % Value changed function: gamma0_sv
        function gamma0_svValueChanged(app, event)
            value = app.gamma0_sv.Value;
            app.setProp('gamma0', 'gamma0_sv', value);
        end

        % Value changed function: zeta0_sv
        function zeta0_svValueChanged(app, event)
            value = app.zeta0_sv.Value;
            app.setProp('zeta0', 'zeta0_sv', value);
        end

        % Value changed function: alltirf_sv
        function alltirf_svValueChanged(app, event)
            value = app.alltirf_sv.Value;
            app.setProp('alltirf', 'alltirf_sv', value);
        end

        % Value changed function: ar_sv
        function ar_svValueChanged(app, event)
            value = app.ar_sv.Value;
            app.setProp('ar', 'ar_sv', value);
        end

        % Value changed function: lambda1_sv
        function lambda1_svValueChanged(app, event)
            value = app.lambda1_sv.Value;
            app.setProp('lambda1', 'lambda1_sv', value);
        end

        % Value changed function: lambda2_sv
        function lambda2_svValueChanged(app, event)
            value = app.lambda2_sv.Value;
            app.setProp('lambda2', 'lambda2_sv', value);
        end

        % Value changed function: lambda3_sv
        function lambda3_svValueChanged(app, event)
            value = app.lambda3_sv.Value;
            app.setProp('lambda3', 'lambda3_sv', value);
        end

        % Value changed function: lambda4_sv
        function lambda4_svValueChanged(app, event)
            value = app.lambda4_sv.Value;
            app.setProp('lambda4', 'lambda4_sv', value);
        end

        % Value changed function: priorsexogenous_sv
        function priorsexogenous_svValueChanged(app, event)
            value = app.priorsexogenous_sv.Value;
            app.setProp('priorsexogenous', 'priorsexogenous_sv', value);
        end

        % Value changed function: lambda5_sv
        function lambda5_svValueChanged(app, event)
            value = app.lambda5_sv.Value;
            app.setProp('lambda5', 'lambda5_sv', value);
        end

        % Value changed function: lambda6_sv
        function lambda6_svValueChanged(app, event)
            value = app.lambda6_sv.Value;
            app.setProp('lambda7', 'lambda7_sv', value);
        end

        % Value changed function: lambda7_sv
        function lambda7_svValueChanged(app, event)
            value = app.lambda7_sv.Value;
            app.setProp('lambda7', 'lambda7_sv', value);
        end

        % Value changed function: lambda8_sv
        function lambda8_svValueChanged(app, event)
            value = app.lambda8_sv.Value;
            app.setProp('lambda8', 'lambda8_sv', value);
        end

        % Value changed function: PriorExcel_sv
        function PriorExcel_svValueChanged(app, event)
            value = app.PriorExcel_sv.Value;
            app.setProp('PriorExcel', 'PriorExcel_sv', value);
        end

        % Selection changed function: CFt
        function CFtSelectionChanged(app, event)
            switch event.NewValue.Text
                case "Standard (all shocks)"
                    ct = bear.CFtype('Standard_all');
                case "Standard (shock specific)"
                    ct = bear.CFtype('Standard_specific');
                case "Tilting (median)"
                    ct = bear.CFtype('Tilting_median');
                case "Tilting (interval)"
                    ct = bear.CFtype('Tilting_interval');
            end
            app.setCommonProp('CFt', 'CFt', ct);
        end

        % Selection changed function: 
        % StructuralidentificationsButtonGroup
        function StructuralidentificationsButtonGroupSelectionChanged(app, event)
            switch event.NewValue.Text
                case "None"
                    ct = bear.IRFtype('Reduced');
                case "Triangular"
                    ct = bear.IRFtype('Triangular');
                case "Cholesky"
                    ct = bear.IRFtype('Cholesky');
                case "Sign restrictions"
                    ct = bear.IRFtype('Sign');
                case "Proxy SVAR"
                    ct = bear.IRFtype('IV');
                case "Sign and proxy"
                    ct = bear.IRFtype('IV_Sign');
            end
            app.setCommonProp('IRFt', 'IRFt', ct);
        end

        % Value changed function: IRFperiods
        function IRFperiodsValueChanged(app, event)
            value = app.IRFperiods.Value;
            app.setCommonProp('IRFperiods', 'IRFperiods', value);
        end

        % Value changed function: Fstartdate
        function FstartdateValueChanged(app, event)
            value = app.Fstartdate.Value;
            app.setCommonProp('Fstartdate', 'Fstartdate', value);
        end

        % Value changed function: Fenddate
        function FenddateValueChanged(app, event)
            value = app.Fenddate.Value;
            app.setCommonProp('Fenddate', 'Fenddate', value);
        end

        % Value changed function: cband
        function cbandValueChanged(app, event)
            value = app.cband.Value;
            app.setCommonProp('cband', 'cband', value);
        end

        % Value changed function: Fendsmpl
        function FendsmplValueChanged(app, event)
            value = app.Fendsmpl.Value;
            app.setCommonProp('Fendsmpl', 'Fendsmpl', value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BEARinterfaceUIFigure = uifigure('Visible', 'off');
            app.BEARinterfaceUIFigure.Name = 'BEAR interface';
            app.BEARinterfaceUIFigure.Icon = 'bear-coloured1.png';
            app.TabGrid = uigridlayout(app.BEARinterfaceUIFigure);
            app.TabGroup = uitabgroup(app.TabGrid);
            app.SPECIFICATIONTab = uitab(app.TabGroup);
            app.SPECIFICATIONTab.Title = 'SPECIFICATION';
            app.GridLayout2 = uigridlayout(app.SPECIFICATIONTab);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.DataFrequencyDropDownLabel = uilabel(app.GridLayout5);
            app.DataFrequencyDropDownLabel.Text = 'Data Frequency';
            app.frequency = uidropdown(app.GridLayout5);
            app.frequency.Items = {'yearly', 'quarterly', 'monthly', 'weekly', 'daily', 'undated'};
            app.frequency.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.frequency.ValueChangedFcn = createCallbackFcn(app, @frequencyValueChanged, true);
            app.frequency.Value = '2';
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.EstimationStartDateEditFieldLabel = uilabel(app.GridLayout6);
            app.EstimationStartDateEditFieldLabel.Text = 'Estimation Start Date';
            app.startdate = uieditfield(app.GridLayout6, 'text');
            app.startdate.Value = '1974q1';
            app.EstimationEndDateEditFieldLabel = uilabel(app.GridLayout6);
            app.EstimationEndDateEditFieldLabel.Text = 'Estimation End Date';
            app.enddate = uieditfield(app.GridLayout6, 'text');
            app.enddate.Value = '2014q4';
            app.GridLayout7 = uigridlayout(app.GridLayout4);
            app.EnterthelistofendogeneousvariablesTextAreaLabel = uilabel(app.GridLayout7);
            app.EnterthelistofendogeneousvariablesTextAreaLabel.Text = 'Enter the list of endogeneous variables';
            app.EnterthelistofendogeneousvariablesTextArea = uitextarea(app.GridLayout7);
            app.EnterthelistofendogeneousvariablesTextArea.ValueChangedFcn = createCallbackFcn(app, @EnterthelistofendogeneousvariablesTextAreaValueChanged, true);
            app.EnterthelistofendogeneousvariablesTextArea.Value = {'DOM_GDP DOM_CPI STN'};
            app.EnterthelistofexogeneousvariablesTextAreaLabel = uilabel(app.GridLayout7);
            app.EnterthelistofexogeneousvariablesTextAreaLabel.Text = 'Enter the list of exogeneous variables';
            app.EnterthelistofexogeneousvariablesTextArea = uitextarea(app.GridLayout7);
            app.EnterthelistofexogeneousvariablesTextArea.ValueChangedFcn = createCallbackFcn(app, @EnterthelistofexogeneousvariablesTextAreaValueChanged, true);
            app.GridLayout8 = uigridlayout(app.GridLayout4);
            app.LagsEditField = uieditfield(app.GridLayout8, 'numeric');
            app.LagsEditField.ValueChangedFcn = createCallbackFcn(app, @LagsEditFieldValueChanged, true);
            app.LagsEditField.Value = 4;
            app.LagsEditFieldLabel = uilabel(app.GridLayout8);
            app.LagsEditFieldLabel.Text = 'Lags';
            app.IncludeconstantSwitch = uiswitch(app.GridLayout8, 'slider');
            app.IncludeconstantSwitch.ValueChangedFcn = createCallbackFcn(app, @IncludeconstantSwitchValueChanged, true);
            app.IncludeconstantSwitch.Value = 'On';
            app.IncludeconstantSwitchLabel = uilabel(app.GridLayout8);
            app.IncludeconstantSwitchLabel.Text = 'Include constant';
            app.GridLayout9 = uigridlayout(app.GridLayout4);
            app.ProduceFiguresCheckBox = uicheckbox(app.GridLayout9);
            app.ProduceFiguresCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProduceFiguresCheckBoxValueChanged, true);
            app.ProduceFiguresCheckBox.Text = 'Produce Figures';
            app.OutputinExcelCheckBox = uicheckbox(app.GridLayout9);
            app.OutputinExcelCheckBox.ValueChangedFcn = createCallbackFcn(app, @OutputinExcelCheckBoxValueChanged, true);
            app.OutputinExcelCheckBox.Text = 'Output in Excel';
            app.GridLayout10 = uigridlayout(app.GridLayout4);
            app.SetresultsfileEditField = uieditfield(app.GridLayout10, 'text');
            app.SetresultsfileEditField.ValueChangedFcn = createCallbackFcn(app, @SetresultsfileEditFieldValueChanged, true);
            app.SetpathtodataEditField = uieditfield(app.GridLayout10, 'text');
            app.SetresultsfileButton = uibutton(app.GridLayout10, 'state');
            app.SetresultsfileButton.ValueChangedFcn = createCallbackFcn(app, @SetresultsfileButtonValueChanged, true);
            app.SetresultsfileButton.Text = 'Set results file';
            app.SetpathtodataButton = uibutton(app.GridLayout10, 'push');
            app.SetpathtodataButton.ButtonPushedFcn = createCallbackFcn(app, @SetpathtodataButtonPushed, true);
            app.SetpathtodataButton.Text = 'Set path to data';
            app.GridLayout18 = uigridlayout(app.GridLayout4);
            app.RUNButton = uibutton(app.GridLayout18, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Icon = 'BEAR.PNG';
            app.RUNButton.Text = 'RUN';
            app.ExportButton = uibutton(app.GridLayout18, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.GridLayout11 = uigridlayout(app.GridLayout2);
            app.SELECTVARTYPELabel = uilabel(app.GridLayout11);
            app.SELECTVARTYPELabel.Text = 'SELECT VAR TYPE';
            app.TabGroup2 = uitabgroup(app.GridLayout11);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.BayesianTab = uitab(app.TabGroup2);
            app.BayesianTab.Title = 'Bayesian';
            app.BayesianGrid = uigridlayout(app.BayesianTab);
            app.Iterations = uipanel(app.BayesianGrid);
            app.Iterations.Title = 'Iterations';
            app.GridLayout18_4 = uigridlayout(app.Iterations);
            app.Bu_bvar = uieditfield(app.GridLayout18_4, 'numeric');
            app.Bu_bvar.ValueChangedFcn = createCallbackFcn(app, @Bu_bvarValueChanged, true);
            app.Bu_bvar.Value = 1000;
            app.It_bvar = uieditfield(app.GridLayout18_4, 'numeric');
            app.It_bvar.ValueChangedFcn = createCallbackFcn(app, @It_bvarValueChanged, true);
            app.It_bvar.Value = 2000;
            app.NumberofburniniterationsEditFieldLabel_4 = uilabel(app.GridLayout18_4);
            app.NumberofburniniterationsEditFieldLabel_4.Text = 'Number of burn-in iterations';
            app.TotalnumberofiterationsEditFieldLabel_4 = uilabel(app.GridLayout18_4);
            app.TotalnumberofiterationsEditFieldLabel_4.Text = 'Total number of iterations:';
            app.OptionsPanel = uipanel(app.BayesianGrid);
            app.OptionsPanel.Title = 'Options';
            app.GridLayout17_5 = uigridlayout(app.OptionsPanel);
            app.scoeff_bvar = uicheckbox(app.GridLayout17_5);
            app.scoeff_bvar.ValueChangedFcn = createCallbackFcn(app, @scoeff_bvarValueChanged, true);
            app.scoeff_bvar.Text = 'Sum of coefficients';
            app.iobs_bvar = uicheckbox(app.GridLayout17_5);
            app.iobs_bvar.ValueChangedFcn = createCallbackFcn(app, @iobs_bvarValueChanged, true);
            app.iobs_bvar.Text = 'Dummy initial observations';
            app.lrp_bvar = uicheckbox(app.GridLayout17_5);
            app.lrp_bvar.ValueChangedFcn = createCallbackFcn(app, @lrp_bvarValueChanged, true);
            app.lrp_bvar.Text = 'Long-run priors';
            app.GridsearchonexcelSwitchLabel = uilabel(app.GridLayout17_5);
            app.GridsearchonexcelSwitchLabel.Text = 'Grid search (on excel)';
            app.hogs_bvar = uiswitch(app.GridLayout17_5, 'slider');
            app.hogs_bvar.Items = {'No', 'Yes'};
            app.hogs_bvar.ItemsData = [0 1];
            app.hogs_bvar.ValueChangedFcn = createCallbackFcn(app, @hogs_bvarValueChanged, true);
            app.hogs_bvar.Value = 0;
            app.BlockexogeneityonexcelSwitchLabel = uilabel(app.GridLayout17_5);
            app.BlockexogeneityonexcelSwitchLabel.Text = 'Block exogeneity (on excel)';
            app.bex_bvar = uiswitch(app.GridLayout17_5, 'slider');
            app.bex_bvar.Items = {'No', 'Yes'};
            app.bex_bvar.ItemsData = [0 1];
            app.bex_bvar.ValueChangedFcn = createCallbackFcn(app, @bex_bvarValueChanged, true);
            app.bex_bvar.Value = 0;
            app.DumyobservationextensionsLabel = uilabel(app.GridLayout17_5);
            app.DumyobservationextensionsLabel.Text = 'Dumy observation extensions';
            app.HyperparametersPanel_3 = uipanel(app.BayesianGrid);
            app.HyperparametersPanel_3.Title = 'Hyperparameters';
            app.GridLayout17_6 = uigridlayout(app.HyperparametersPanel_3);
            app.lambda2_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.lambda2_bvar.ValueChangedFcn = createCallbackFcn(app, @lambda2_bvarValueChanged, true);
            app.lambda2_bvar.Value = 0.5;
            app.lambda1_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.lambda1_bvar.ValueChangedFcn = createCallbackFcn(app, @lambda1_bvarValueChanged, true);
            app.lambda1_bvar.Value = 0.1;
            app.ar_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.ar_bvar.ValueChangedFcn = createCallbackFcn(app, @ar_bvarValueChanged, true);
            app.ar_bvar.Value = 1;
            app.ARcoefficientonresidualvarianceEditFieldLabel_6 = uilabel(app.GridLayout17_6);
            app.ARcoefficientonresidualvarianceEditFieldLabel_6.Text = 'Cross-variable weighting';
            app.IGscaleonresidualvarianceEditFieldLabel_6 = uilabel(app.GridLayout17_6);
            app.IGscaleonresidualvarianceEditFieldLabel_6.Text = 'Overall tightness';
            app.IGshapeonresidualvarianceEditFieldLabel_6 = uilabel(app.GridLayout17_6);
            app.IGshapeonresidualvarianceEditFieldLabel_6.Text = 'Autoregresive coefficient';
            app.LagdecayEditField_2Label = uilabel(app.GridLayout17_6);
            app.LagdecayEditField_2Label.Text = 'Lag decay';
            app.lambda3_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.lambda3_bvar.ValueChangedFcn = createCallbackFcn(app, @lambda3_bvarValueChanged, true);
            app.lambda3_bvar.Value = 1;
            app.PriorExcel_bvar = uicheckbox(app.GridLayout17_6);
            app.PriorExcel_bvar.ValueChangedFcn = createCallbackFcn(app, @PriorExcel_bvarValueChanged, true);
            app.PriorExcel_bvar.Text = 'Excel';
            app.ExogenousvariableEditFieldLabel = uilabel(app.GridLayout17_6);
            app.ExogenousvariableEditFieldLabel.Text = 'Exogenous variable';
            app.lambda4_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.lambda4_bvar.ValueChangedFcn = createCallbackFcn(app, @lambda4_bvarValueChanged, true);
            app.lambda4_bvar.Value = 100;
            app.BlockexogenetyshrinkageEditFieldLabel = uilabel(app.GridLayout17_6);
            app.BlockexogenetyshrinkageEditFieldLabel.Text = 'Block exogenety shrinkage';
            app.lambda5_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.lambda5_bvar.ValueChangedFcn = createCallbackFcn(app, @lambda5_bvarValueChanged, true);
            app.lambda5_bvar.Enable = 'off';
            app.lambda5_bvar.Value = 0.001;
            app.SumofcoefficienttightnessEditFieldLabel = uilabel(app.GridLayout17_6);
            app.SumofcoefficienttightnessEditFieldLabel.Text = 'Sum of coefficient tightness';
            app.lambda6_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.lambda6_bvar.ValueChangedFcn = createCallbackFcn(app, @lambda6_bvarValueChanged, true);
            app.lambda6_bvar.Enable = 'off';
            app.lambda6_bvar.Value = 0.1;
            app.DummyinitialobservationtightnessLabel = uilabel(app.GridLayout17_6);
            app.DummyinitialobservationtightnessLabel.Text = 'Dummy initial observation tightness';
            app.lambda7_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.lambda7_bvar.ValueChangedFcn = createCallbackFcn(app, @lambda7_bvarValueChanged, true);
            app.lambda7_bvar.Enable = 'off';
            app.lambda7_bvar.Value = 0.001;
            app.LongrunpriortightnessEditFieldLabel = uilabel(app.GridLayout17_6);
            app.LongrunpriortightnessEditFieldLabel.Text = 'Long-run prior tightness';
            app.lambda8_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.lambda8_bvar.ValueChangedFcn = createCallbackFcn(app, @lambda8_bvarValueChanged, true);
            app.lambda8_bvar.Enable = 'off';
            app.lambda8_bvar.Value = 1;
            app.ExogenousvariablesSwitchLabel = uilabel(app.GridLayout17_6);
            app.ExogenousvariablesSwitchLabel.Text = 'Exogenous variables';
            app.priorsexogenous_bvar = uiswitch(app.GridLayout17_6, 'slider');
            app.priorsexogenous_bvar.Items = {'Excel', 'Default'};
            app.priorsexogenous_bvar.ItemsData = {'1', '0'};
            app.priorsexogenous_bvar.ValueChangedFcn = createCallbackFcn(app, @priorsexogenous_bvarValueChanged, true);
            app.priorsexogenous_bvar.Value = '0';
            app.prior = uibuttongroup(app.BayesianGrid);
            app.prior.SelectionChangedFcn = createCallbackFcn(app, @priorSelectionChanged, true);
            app.prior.Title = 'Bayesian VARs Priors';
            app.MinnesotaButton = uiradiobutton(app.prior);
            app.MinnesotaButton.Text = 'Minnesota';
            app.NormalDiffuseButton = uiradiobutton(app.prior);
            app.NormalDiffuseButton.Text = 'Normal Diffuse';
            app.DummyObservationsButton = uiradiobutton(app.prior);
            app.DummyObservationsButton.Text = 'Dummy Observations';
            app.NormalWishartButton = uiradiobutton(app.prior);
            app.NormalWishartButton.Text = 'Normal Wishart';
            app.IndependentNormalWishartButton = uiradiobutton(app.prior);
            app.IndependentNormalWishartButton.Text = 'Independent Normal Wishart';
            app.DeterministicButton = uiradiobutton(app.prior);
            app.DeterministicButton.Text = 'Deterministic';
            app.DeterministicButton.Value = true;
            app.OLSTab = uitab(app.TabGroup2);
            app.OLSTab.Title = 'OLS';
            app.PanelTab = uitab(app.TabGroup2);
            app.PanelTab.Title = 'Panel';
            app.PanelGrid = uigridlayout(app.PanelTab);
            app.HyperparametersPanel_panel = uipanel(app.PanelGrid);
            app.HyperparametersPanel_panel.Title = 'Hyperparameters';
            app.GridLayout15 = uigridlayout(app.HyperparametersPanel_panel);
            app.v0_panel = uieditfield(app.GridLayout15, 'numeric');
            app.v0_panel.ValueChangedFcn = createCallbackFcn(app, @v0_panelValueChanged, true);
            app.v0_panel.Value = 0.001;
            app.s0_panel = uieditfield(app.GridLayout15, 'numeric');
            app.s0_panel.ValueChangedFcn = createCallbackFcn(app, @s0_panelValueChanged, true);
            app.s0_panel.Value = 0.001;
            app.lambda4_panel = uieditfield(app.GridLayout15, 'numeric');
            app.lambda4_panel.ValueChangedFcn = createCallbackFcn(app, @lambda4_panelValueChanged, true);
            app.lambda4_panel.Value = 100;
            app.lambda3_panel = uieditfield(app.GridLayout15, 'numeric');
            app.lambda3_panel.ValueChangedFcn = createCallbackFcn(app, @lambda3_panelValueChanged, true);
            app.lambda3_panel.Value = 1;
            app.lambda2_panel = uieditfield(app.GridLayout15, 'numeric');
            app.lambda2_panel.ValueChangedFcn = createCallbackFcn(app, @lambda2_panelValueChanged, true);
            app.lambda2_panel.Value = 0.5;
            app.lambda1_panel = uieditfield(app.GridLayout15, 'numeric');
            app.lambda1_panel.ValueChangedFcn = createCallbackFcn(app, @lambda1_panelValueChanged, true);
            app.lambda1_panel.Value = 0.1;
            app.ar_panel = uieditfield(app.GridLayout15, 'numeric');
            app.ar_panel.ValueChangedFcn = createCallbackFcn(app, @ar_panelValueChanged, true);
            app.ar_panel.Value = 0.8;
            app.IGscaleonoveralltightnessEditFieldLabel = uilabel(app.GridLayout15);
            app.IGscaleonoveralltightnessEditFieldLabel.Text = 'IG scale on overall tightness';
            app.IGshapeonoveralltightnessEditFieldLabel = uilabel(app.GridLayout15);
            app.IGshapeonoveralltightnessEditFieldLabel.Text = 'IG shape on overall tightness';
            app.ExogenousvariabletightnessEditFieldLabel = uilabel(app.GridLayout15);
            app.ExogenousvariabletightnessEditFieldLabel.Text = 'Exogenous variable tightness';
            app.LagdecayEditFieldLabel = uilabel(app.GridLayout15);
            app.LagdecayEditFieldLabel.Text = 'Lag decay';
            app.CrossvariableweightingEditFieldLabel = uilabel(app.GridLayout15);
            app.CrossvariableweightingEditFieldLabel.Text = 'Cross-variable weighting';
            app.OveralltightnessEditFieldLabel = uilabel(app.GridLayout15);
            app.OveralltightnessEditFieldLabel.Text = 'Overall tightness';
            app.AutoregressivecoefficientEditFieldLabel = uilabel(app.GridLayout15);
            app.AutoregressivecoefficientEditFieldLabel.Text = 'Autoregressive coefficient';
            app.HyperparametersPanel = uipanel(app.PanelGrid);
            app.HyperparametersPanel.Title = 'Hyperparameters';
            app.GridLayout17 = uigridlayout(app.HyperparametersPanel);
            app.psi_panel = uieditfield(app.GridLayout17, 'numeric');
            app.psi_panel.ValueChangedFcn = createCallbackFcn(app, @psi_panelValueChanged, true);
            app.psi_panel.Value = 0.1;
            app.rho_panel = uieditfield(app.GridLayout17, 'numeric');
            app.rho_panel.ValueChangedFcn = createCallbackFcn(app, @rho_panelValueChanged, true);
            app.rho_panel.Value = 0.75;
            app.b0_panel = uieditfield(app.GridLayout17, 'numeric');
            app.b0_panel.ValueChangedFcn = createCallbackFcn(app, @b0_panelValueChanged, true);
            app.b0_panel.Value = 1;
            app.a0_panel = uieditfield(app.GridLayout17, 'numeric');
            app.a0_panel.ValueChangedFcn = createCallbackFcn(app, @a0_panelValueChanged, true);
            app.a0_panel.Value = 1000;
            app.gama_panel = uieditfield(app.GridLayout17, 'numeric');
            app.gama_panel.ValueChangedFcn = createCallbackFcn(app, @gama_panelValueChanged, true);
            app.gama_panel.Value = 0.85;
            app.delta0_panel = uieditfield(app.GridLayout17, 'numeric');
            app.delta0_panel.ValueChangedFcn = createCallbackFcn(app, @delta0_panelValueChanged, true);
            app.delta0_panel.Value = 1;
            app.alpha0_panel = uieditfield(app.GridLayout17, 'numeric');
            app.alpha0_panel.ValueChangedFcn = createCallbackFcn(app, @alpha0_panelValueChanged, true);
            app.alpha0_panel.Value = 1000;
            app.VarianceonMetropolisdrawEditFieldLabel = uilabel(app.GridLayout17);
            app.VarianceonMetropolisdrawEditFieldLabel.Tooltip = {'psi'};
            app.VarianceonMetropolisdrawEditFieldLabel.Text = 'Variance on Metropolis draw';
            app.ARcoefficientonfactorEditFieldLabel = uilabel(app.GridLayout17);
            app.ARcoefficientonfactorEditFieldLabel.Tooltip = {'rho'};
            app.ARcoefficientonfactorEditFieldLabel.Text = 'AR coefficient on factor';
            app.IGscaleonfactorvarianceEditFieldLabel = uilabel(app.GridLayout17);
            app.IGscaleonfactorvarianceEditFieldLabel.Tooltip = {'b0'};
            app.IGscaleonfactorvarianceEditFieldLabel.Text = 'IG scale on factor variance';
            app.IGshapeonfactorvarianceEditFieldLabel = uilabel(app.GridLayout17);
            app.IGshapeonfactorvarianceEditFieldLabel.Tooltip = {'a0'};
            app.IGshapeonfactorvarianceEditFieldLabel.Text = 'IG shape on factor variance';
            app.ARcoefficientonresidualvarianceEditFieldLabel = uilabel(app.GridLayout17);
            app.ARcoefficientonresidualvarianceEditFieldLabel.Tooltip = {'gama'};
            app.ARcoefficientonresidualvarianceEditFieldLabel.Text = 'AR coefficient on residual variance';
            app.IGscaleonresidualvarianceEditFieldLabel = uilabel(app.GridLayout17);
            app.IGscaleonresidualvarianceEditFieldLabel.Tooltip = {'delta0'};
            app.IGscaleonresidualvarianceEditFieldLabel.Text = 'IG scale on residual variance';
            app.IGshapeonresidualvarianceEditFieldLabel = uilabel(app.GridLayout17);
            app.IGshapeonresidualvarianceEditFieldLabel.Tooltip = {'alpha0'};
            app.IGshapeonresidualvarianceEditFieldLabel.Text = 'IG shape on residual variance';
            app.EnterlistofunitsPanel = uipanel(app.PanelGrid);
            app.EnterlistofunitsPanel.Title = 'Enter list of units';
            app.GridLayout16 = uigridlayout(app.EnterlistofunitsPanel);
            app.unitnames_panel = uieditfield(app.GridLayout16, 'text');
            app.unitnames_panel.ValueChangedFcn = createCallbackFcn(app, @unitnames_panelValueChanged, true);
            app.Iterations_2 = uipanel(app.PanelGrid);
            app.Iterations_2.Title = 'Iterations';
            app.GridLayout18_5 = uigridlayout(app.Iterations_2);
            app.Bu_panel = uieditfield(app.GridLayout18_5, 'numeric');
            app.Bu_panel.ValueChangedFcn = createCallbackFcn(app, @Bu_panelValueChanged, true);
            app.Bu_panel.Value = 1000;
            app.It_panel = uieditfield(app.GridLayout18_5, 'numeric');
            app.It_panel.ValueChangedFcn = createCallbackFcn(app, @It_panelValueChanged, true);
            app.It_panel.Value = 2000;
            app.NumberofburniniterationsEditFieldLabel_5 = uilabel(app.GridLayout18_5);
            app.NumberofburniniterationsEditFieldLabel_5.Text = 'Number of burn-in iterations';
            app.TotalnumberofiterationsEditFieldLabel_5 = uilabel(app.GridLayout18_5);
            app.TotalnumberofiterationsEditFieldLabel_5.Text = 'Total number of iterations';
            app.panel = uibuttongroup(app.PanelGrid);
            app.panel.SelectionChangedFcn = createCallbackFcn(app, @panelSelectionChanged, true);
            app.panel.Title = 'Bayesian Panel VARs Priors';
            app.MeangroupEstimatorButton = uiradiobutton(app.panel);
            app.MeangroupEstimatorButton.Text = 'Mean group Estimator';
            app.MeangroupEstimatorButton.Value = true;
            app.RandomEffectZellnerHongButton = uiradiobutton(app.panel);
            app.RandomEffectZellnerHongButton.Text = 'Random Effect (Zellner-Hong)';
            app.StaticStructureFactorButton = uiradiobutton(app.panel);
            app.StaticStructureFactorButton.Text = 'Static Structure Factor';
            app.PooledEstimatorButton = uiradiobutton(app.panel);
            app.PooledEstimatorButton.Text = 'Pooled Estimator';
            app.RandomEffectHierarchicalButton = uiradiobutton(app.panel);
            app.RandomEffectHierarchicalButton.Text = 'Random Effect (Hierarchical)';
            app.DynamicStructureFactorButton = uiradiobutton(app.panel);
            app.DynamicStructureFactorButton.Text = 'Dynamic Structure Factor';
            app.StochasticVolatilityTab = uitab(app.TabGroup2);
            app.StochasticVolatilityTab.Title = 'Stochastic Volatility';
            app.SVGrid = uigridlayout(app.StochasticVolatilityTab);
            app.HyperparametersstochasticPanel = uipanel(app.SVGrid);
            app.HyperparametersstochasticPanel.Title = 'Hyperparameters stochastic';
            app.GridLayout17_3 = uigridlayout(app.HyperparametersstochasticPanel);
            app.zeta0_sv = uieditfield(app.GridLayout17_3, 'numeric');
            app.zeta0_sv.ValueChangedFcn = createCallbackFcn(app, @zeta0_svValueChanged, true);
            app.zeta0_sv.Value = 10000;
            app.gamma0_sv = uieditfield(app.GridLayout17_3, 'numeric');
            app.gamma0_sv.ValueChangedFcn = createCallbackFcn(app, @gamma0_svValueChanged, true);
            app.delta0_sv = uieditfield(app.GridLayout17_3, 'numeric');
            app.delta0_sv.ValueChangedFcn = createCallbackFcn(app, @delta0_svValueChanged, true);
            app.delta0_sv.Value = 0.001;
            app.alpha0_sv = uieditfield(app.GridLayout17_3, 'numeric');
            app.alpha0_sv.ValueChangedFcn = createCallbackFcn(app, @alpha0_svValueChanged, true);
            app.alpha0_sv.Value = 0.001;
            app.gamma_sv = uieditfield(app.GridLayout17_3, 'numeric');
            app.gamma_sv.ValueChangedFcn = createCallbackFcn(app, @gamma_svValueChanged, true);
            app.gamma_sv.Value = 1;
            app.IGscaleonfactorvarianceEditFieldLabel_3 = uilabel(app.GridLayout17_3);
            app.IGscaleonfactorvarianceEditFieldLabel_3.Tooltip = {'zeta0'};
            app.IGscaleonfactorvarianceEditFieldLabel_3.Text = 'Prior variance on inertia';
            app.IGshapeonfactorvarianceEditFieldLabel_3 = uilabel(app.GridLayout17_3);
            app.IGshapeonfactorvarianceEditFieldLabel_3.Tooltip = {'gamma0'};
            app.IGshapeonfactorvarianceEditFieldLabel_3.Text = 'Prior mean on inertia';
            app.ARcoefficientonresidualvarianceEditFieldLabel_3 = uilabel(app.GridLayout17_3);
            app.ARcoefficientonresidualvarianceEditFieldLabel_3.Tooltip = {'delta0'};
            app.ARcoefficientonresidualvarianceEditFieldLabel_3.Text = 'IG scale on residual variance';
            app.IGscaleonresidualvarianceEditFieldLabel_3 = uilabel(app.GridLayout17_3);
            app.IGscaleonresidualvarianceEditFieldLabel_3.Tooltip = {'alpha0'};
            app.IGscaleonresidualvarianceEditFieldLabel_3.Text = 'IG shape on residual variance';
            app.IGshapeonresidualvarianceEditFieldLabel_3 = uilabel(app.GridLayout17_3);
            app.IGshapeonresidualvarianceEditFieldLabel_3.Text = 'AR coefficient on residual variance';
            app.alltirf_sv = uicheckbox(app.GridLayout17_3);
            app.alltirf_sv.ValueChangedFcn = createCallbackFcn(app, @alltirf_svValueChanged, true);
            app.alltirf_sv.Tooltip = {'alltirf'};
            app.alltirf_sv.Text = 'Time-varying IRFs';
            app.alltirf_sv.Value = true;
            app.stvol = uibuttongroup(app.SVGrid);
            app.stvol.SelectionChangedFcn = createCallbackFcn(app, @stvolSelectionChanged, true);
            app.stvol.Title = 'Stochastic Volatility';
            app.StandardButton = uiradiobutton(app.stvol);
            app.StandardButton.Text = 'Standard';
            app.RandomInertiaButton = uiradiobutton(app.stvol);
            app.RandomInertiaButton.Text = 'Random Inertia';
            app.LargeVARButton = uiradiobutton(app.stvol);
            app.LargeVARButton.Text = 'Large VAR';
            app.StochasticButton = uiradiobutton(app.stvol);
            app.StochasticButton.Text = 'Stochastic';
            app.StochasticButton.Value = true;
            app.Iterations_3 = uipanel(app.SVGrid);
            app.Iterations_3.Title = 'Iterations';
            app.GridLayout18_6 = uigridlayout(app.Iterations_3);
            app.Bu_sv = uieditfield(app.GridLayout18_6, 'numeric');
            app.Bu_sv.ValueChangedFcn = createCallbackFcn(app, @Bu_svValueChanged, true);
            app.Bu_sv.Value = 1000;
            app.It_sv = uieditfield(app.GridLayout18_6, 'numeric');
            app.It_sv.ValueChangedFcn = createCallbackFcn(app, @It_svValueChanged, true);
            app.It_sv.Value = 2000;
            app.NumberofburniniterationsEditFieldLabel_6 = uilabel(app.GridLayout18_6);
            app.NumberofburniniterationsEditFieldLabel_6.Text = 'Number of burn-in iterations';
            app.TotalnumberofiterationsEditFieldLabel_6 = uilabel(app.GridLayout18_6);
            app.TotalnumberofiterationsEditFieldLabel_6.Text = 'Total number of iterations';
            app.HyperparametersPanel_4 = uipanel(app.SVGrid);
            app.HyperparametersPanel_4.Title = 'Hyperparameters';
            app.GridLayout17_7 = uigridlayout(app.HyperparametersPanel_4);
            app.lambda2_sv = uieditfield(app.GridLayout17_7, 'numeric');
            app.lambda2_sv.ValueChangedFcn = createCallbackFcn(app, @lambda2_svValueChanged, true);
            app.lambda2_sv.Value = 0.5;
            app.lambda1_sv = uieditfield(app.GridLayout17_7, 'numeric');
            app.lambda1_sv.ValueChangedFcn = createCallbackFcn(app, @lambda1_svValueChanged, true);
            app.lambda1_sv.Value = 0.1;
            app.ar_sv = uieditfield(app.GridLayout17_7, 'numeric');
            app.ar_sv.ValueChangedFcn = createCallbackFcn(app, @ar_svValueChanged, true);
            app.ar_sv.Value = 1;
            app.ARcoefficientonresidualvarianceEditFieldLabel_7 = uilabel(app.GridLayout17_7);
            app.ARcoefficientonresidualvarianceEditFieldLabel_7.Text = 'Cross-variable weighting';
            app.IGscaleonresidualvarianceEditFieldLabel_7 = uilabel(app.GridLayout17_7);
            app.IGscaleonresidualvarianceEditFieldLabel_7.Text = 'Overall tightness';
            app.IGshapeonresidualvarianceEditFieldLabel_7 = uilabel(app.GridLayout17_7);
            app.IGshapeonresidualvarianceEditFieldLabel_7.Text = 'Autoregresive coefficient';
            app.LagdecayEditField_2Label_2 = uilabel(app.GridLayout17_7);
            app.LagdecayEditField_2Label_2.Text = 'Lag decay';
            app.lambda3_sv = uieditfield(app.GridLayout17_7, 'numeric');
            app.lambda3_sv.ValueChangedFcn = createCallbackFcn(app, @lambda3_svValueChanged, true);
            app.lambda3_sv.Value = 1;
            app.PriorExcel_sv = uicheckbox(app.GridLayout17_7);
            app.PriorExcel_sv.ValueChangedFcn = createCallbackFcn(app, @PriorExcel_svValueChanged, true);
            app.PriorExcel_sv.Text = 'Excel';
            app.ExogenousvariableEditFieldLabel_2 = uilabel(app.GridLayout17_7);
            app.ExogenousvariableEditFieldLabel_2.Text = 'Exogenous variable';
            app.lambda4_sv = uieditfield(app.GridLayout17_7, 'numeric');
            app.lambda4_sv.ValueChangedFcn = createCallbackFcn(app, @lambda4_svValueChanged, true);
            app.lambda4_sv.Value = 100;
            app.BlockexogenetyshrinkageEditFieldLabel_2 = uilabel(app.GridLayout17_7);
            app.BlockexogenetyshrinkageEditFieldLabel_2.Text = 'Block exogenety shrinkage';
            app.lambda5_sv = uieditfield(app.GridLayout17_7, 'numeric');
            app.lambda5_sv.ValueChangedFcn = createCallbackFcn(app, @lambda5_svValueChanged, true);
            app.lambda5_sv.Enable = 'off';
            app.lambda5_sv.Value = 0.001;
            app.SumofcoefficienttightnessEditFieldLabel_2 = uilabel(app.GridLayout17_7);
            app.SumofcoefficienttightnessEditFieldLabel_2.Text = 'Sum of coefficient tightness';
            app.lambda6_sv = uieditfield(app.GridLayout17_7, 'numeric');
            app.lambda6_sv.ValueChangedFcn = createCallbackFcn(app, @lambda6_svValueChanged, true);
            app.lambda6_sv.Enable = 'off';
            app.lambda6_sv.Value = 0.1;
            app.DummyinitialobservationtightnessLabel_2 = uilabel(app.GridLayout17_7);
            app.DummyinitialobservationtightnessLabel_2.Text = 'Dummy initial observation tightness';
            app.lambda7_sv = uieditfield(app.GridLayout17_7, 'numeric');
            app.lambda7_sv.ValueChangedFcn = createCallbackFcn(app, @lambda7_svValueChanged, true);
            app.lambda7_sv.Enable = 'off';
            app.lambda7_sv.Value = 0.001;
            app.LongrunpriortightnessEditFieldLabel_2 = uilabel(app.GridLayout17_7);
            app.LongrunpriortightnessEditFieldLabel_2.Text = 'Long-run prior tightness';
            app.lambda8_sv = uieditfield(app.GridLayout17_7, 'numeric');
            app.lambda8_sv.ValueChangedFcn = createCallbackFcn(app, @lambda8_svValueChanged, true);
            app.lambda8_sv.Enable = 'off';
            app.lambda8_sv.Value = 1;
            app.ExogenousvariablesSwitchLabel_2 = uilabel(app.GridLayout17_7);
            app.ExogenousvariablesSwitchLabel_2.Text = 'Exogenous variables';
            app.priorsexogenous_sv = uiswitch(app.GridLayout17_7, 'slider');
            app.priorsexogenous_sv.Items = {'Excel', 'Default'};
            app.priorsexogenous_sv.ItemsData = {'1', '0'};
            app.priorsexogenous_sv.ValueChangedFcn = createCallbackFcn(app, @priorsexogenous_svValueChanged, true);
            app.priorsexogenous_sv.Value = '0';
            app.TimeVaryingTab = uitab(app.TabGroup2);
            app.TimeVaryingTab.Title = 'Time Varying';
            app.TVPGrid = uigridlayout(app.TimeVaryingTab);
            app.HyperparameterstimevaryingPanel = uipanel(app.TVPGrid);
            app.HyperparameterstimevaryingPanel.Title = 'Hyperparameters time varying';
            app.GridLayout17_2 = uigridlayout(app.HyperparameterstimevaryingPanel);
            app.delta0_tvp = uieditfield(app.GridLayout17_2, 'numeric');
            app.delta0_tvp.ValueChangedFcn = createCallbackFcn(app, @delta0_tvpValueChanged, true);
            app.delta0_tvp.Value = 0.001;
            app.alpha0_tvp = uieditfield(app.GridLayout17_2, 'numeric');
            app.alpha0_tvp.ValueChangedFcn = createCallbackFcn(app, @alpha0_tvpValueChanged, true);
            app.alpha0_tvp.Value = 0.001;
            app.gamma_tvp = uieditfield(app.GridLayout17_2, 'numeric');
            app.gamma_tvp.ValueChangedFcn = createCallbackFcn(app, @gamma_tvpValueChanged, true);
            app.gamma_tvp.Value = 0.85;
            app.ARcoefficientonresidualvarianceEditFieldLabel_2 = uilabel(app.GridLayout17_2);
            app.ARcoefficientonresidualvarianceEditFieldLabel_2.Text = 'IG scale on residual variance';
            app.IGscaleonresidualvarianceEditFieldLabel_2 = uilabel(app.GridLayout17_2);
            app.IGscaleonresidualvarianceEditFieldLabel_2.Text = 'IG shape on residual variance';
            app.IGshapeonresidualvarianceEditFieldLabel_2 = uilabel(app.GridLayout17_2);
            app.IGshapeonresidualvarianceEditFieldLabel_2.Text = 'AR coefficient on residual variance';
            app.alltirf_tvp = uicheckbox(app.GridLayout17_2);
            app.alltirf_tvp.ValueChangedFcn = createCallbackFcn(app, @alltirf_tvpValueChanged, true);
            app.alltirf_tvp.Text = 'Time-varying IRFs';
            app.alltirf_tvp.Value = true;
            app.tvbvar = uibuttongroup(app.TVPGrid);
            app.tvbvar.SelectionChangedFcn = createCallbackFcn(app, @tvbvarSelectionChanged, true);
            app.tvbvar.Title = 'Time-varying';
            app.VARcoefficientsButton = uiradiobutton(app.tvbvar);
            app.VARcoefficientsButton.Text = 'VAR coefficients';
            app.GeneralButton = uiradiobutton(app.tvbvar);
            app.GeneralButton.Text = 'General';
            app.GeneralButton.Value = true;
            app.Iterations_4 = uipanel(app.TVPGrid);
            app.Iterations_4.Title = 'Iterations';
            app.GridLayout18_7 = uigridlayout(app.Iterations_4);
            app.Bu_tvp = uieditfield(app.GridLayout18_7, 'numeric');
            app.Bu_tvp.ValueChangedFcn = createCallbackFcn(app, @Bu_tvpValueChanged, true);
            app.Bu_tvp.Value = 1000;
            app.It_tvp = uieditfield(app.GridLayout18_7, 'numeric');
            app.It_tvp.ValueChangedFcn = createCallbackFcn(app, @It_tvpValueChanged, true);
            app.It_tvp.Value = 2000;
            app.NumberofburniniterationsEditFieldLabel_7 = uilabel(app.GridLayout18_7);
            app.NumberofburniniterationsEditFieldLabel_7.Text = 'Number of burn-in iterations';
            app.TotalnumberofiterationsEditFieldLabel_7 = uilabel(app.GridLayout18_7);
            app.TotalnumberofiterationsEditFieldLabel_7.Text = 'Total number of iterations:';
            app.MixedFrequencyTab = uitab(app.TabGroup2);
            app.MixedFrequencyTab.Title = 'Mixed Frequency';
            app.APPLICATIONSTab = uitab(app.TabGroup);
            app.APPLICATIONSTab.Title = 'APPLICATIONS';
            app.GridLayout14 = uigridlayout(app.APPLICATIONSTab);
            app.Applicationoptions = uipanel(app.GridLayout14);
            app.Applicationoptions.Title = 'Application options                               FAVAR';
            app.ImpulseresponsefunctionsSwitchLabel = uilabel(app.Applicationoptions);
            app.ImpulseresponsefunctionsSwitchLabel.Text = 'Impulse response functions:';
            app.IRF = uiswitch(app.Applicationoptions, 'slider');
            app.IRF.Items = {'No', 'Yes'};
            app.IRF.ItemsData = {'0', '1'};
            app.IRF.Value = '1';
            app.UnconditionalforecastsSwitchLabel = uilabel(app.Applicationoptions);
            app.UnconditionalforecastsSwitchLabel.Text = 'Unconditional forecasts:';
            app.F = uiswitch(app.Applicationoptions, 'slider');
            app.F.Items = {'No', 'Yes'};
            app.F.ItemsData = {'0', '1'};
            app.F.Value = '1';
            app.HistoricaldecompositionsSwitchLabel = uilabel(app.Applicationoptions);
            app.HistoricaldecompositionsSwitchLabel.Text = ' Historical decompositions:';
            app.HD = uiswitch(app.Applicationoptions, 'slider');
            app.HD.Items = {'No', 'Yes'};
            app.HD.ItemsData = {'0', '1'};
            app.HD.Value = '1';
            app.ConditionalforecastsSwitchLabel = uilabel(app.Applicationoptions);
            app.ConditionalforecastsSwitchLabel.Text = '  Conditional forecasts:         ';
            app.CF = uiswitch(app.Applicationoptions, 'slider');
            app.CF.Items = {'No', 'Yes'};
            app.CF.ItemsData = {'0', '1'};
            app.CF.ValueChangedFcn = createCallbackFcn(app, @CFValueChanged, true);
            app.CF.Value = '1';
            app.ForecasterrorvarianceSwitchLabel = uilabel(app.Applicationoptions);
            app.ForecasterrorvarianceSwitchLabel.Text = 'Forecast error variance: ';
            app.FEVD = uiswitch(app.Applicationoptions, 'slider');
            app.FEVD.Items = {'No', 'Yes'};
            app.FEVD.ItemsData = {'0', '1'};
            app.FEVD.Value = '0';
            app.favarIRFplot = uicheckbox(app.Applicationoptions);
            app.favarIRFplot.Text = '';
            app.favarFEVDplot = uicheckbox(app.Applicationoptions);
            app.favarFEVDplot.Text = '';
            app.favarHDplot = uicheckbox(app.Applicationoptions);
            app.favarHDplot.Text = '';
            app.Periodoptions = uipanel(app.GridLayout14);
            app.Periodoptions.Title = 'Period options';
            app.IRFperiodsEditFieldLabel = uilabel(app.Periodoptions);
            app.IRFperiodsEditFieldLabel.Text = 'IRF periods:';
            app.IRFperiods = uieditfield(app.Periodoptions, 'numeric');
            app.IRFperiods.ValueChangedFcn = createCallbackFcn(app, @IRFperiodsValueChanged, true);
            app.IRFperiods.Value = 20;
            app.ForecastsafterlastsampleperiodSwitchLabel = uilabel(app.Periodoptions);
            app.ForecastsafterlastsampleperiodSwitchLabel.Text = 'Forecasts after last sample period:';
            app.Fendsmpl = uiswitch(app.Periodoptions, 'slider');
            app.Fendsmpl.Items = {'No', 'Yes'};
            app.Fendsmpl.ItemsData = {'0', '1'};
            app.Fendsmpl.ValueChangedFcn = createCallbackFcn(app, @FendsmplValueChanged, true);
            app.Fendsmpl.Value = '0';
            app.ForecastsStartdateEditFieldLabel = uilabel(app.Periodoptions);
            app.ForecastsStartdateEditFieldLabel.Text = 'Forecasts: Start date:';
            app.Fstartdate = uieditfield(app.Periodoptions, 'text');
            app.Fstartdate.ValueChangedFcn = createCallbackFcn(app, @FstartdateValueChanged, true);
            app.Fstartdate.Value = '2014q1';
            app.ForecastsEnddateLabel = uilabel(app.Periodoptions);
            app.ForecastsEnddateLabel.Text = 'Forecasts: End date:';
            app.Fenddate = uieditfield(app.Periodoptions, 'text');
            app.Fenddate.ValueChangedFcn = createCallbackFcn(app, @FenddateValueChanged, true);
            app.Fenddate.Value = '2016q4';
            app.VARcoefficientsEditFieldLabel = uilabel(app.Periodoptions);
            app.VARcoefficientsEditFieldLabel.Text = 'Credibility Intervals:';
            app.cband = uieditfield(app.Periodoptions, 'numeric');
            app.cband.ValueChangedFcn = createCallbackFcn(app, @cbandValueChanged, true);
            app.cband.Value = 0.67;
            app.CFt = uibuttongroup(app.GridLayout14);
            app.CFt.SelectionChangedFcn = createCallbackFcn(app, @CFtSelectionChanged, true);
            app.CFt.Title = 'Types of conditional forecasts';
            app.Standardallshocks = uiradiobutton(app.CFt);
            app.Standardallshocks.Text = 'Standard (all shocks)';
            app.Standardallshocks.Value = true;
            app.Standardshockspecific = uiradiobutton(app.CFt);
            app.Standardshockspecific.Text = 'Standard (shock specific)';
            app.Tiltingmedian = uiradiobutton(app.CFt);
            app.Tiltingmedian.Text = 'Titling (median)';
            app.Tiltinginterval = uiradiobutton(app.CFt);
            app.Tiltinginterval.Text = 'Titling (interval)';
            app.Estimationoptions = uipanel(app.GridLayout14);
            app.Estimationoptions.Title = 'Estimation options:';
            app.ForecastevaluationsSwitchLabel = uilabel(app.Estimationoptions);
            app.ForecastevaluationsSwitchLabel.Text = 'Forecast evaluations:';
            app.Feval = uiswitch(app.Estimationoptions, 'slider');
            app.Feval.Items = {'No', 'Yes'};
            app.Feval.Value = 'Yes';
            app.ForecaststepaheadevaluationsEditFieldLabel = uilabel(app.Estimationoptions);
            app.ForecaststepaheadevaluationsEditFieldLabel.Text = 'Forecast step ahead evaluations:';
            app.hstep = uieditfield(app.Estimationoptions, 'numeric');
            app.hstep.Value = 1;
            app.RollingWindow0forfullsampleEditFieldLabel = uilabel(app.Estimationoptions);
            app.RollingWindow0forfullsampleEditFieldLabel.Text = 'Rolling Window (0 for full sample)';
            app.window_size = uieditfield(app.Estimationoptions, 'numeric');
            app.EvaluationSizeEditFieldLabel = uilabel(app.Estimationoptions);
            app.EvaluationSizeEditFieldLabel.Text = 'Evaluation Size';
            app.evaluation_size = uieditfield(app.Estimationoptions, 'numeric');
            app.evaluation_size.Value = 0.5;
            app.ProxyVars = uipanel(app.GridLayout14);
            app.ProxyVars.Title = 'Proxy SVAR options';
            app.InstrumentLabel = uilabel(app.ProxyVars);
            app.InstrumentLabel.Text = 'Instrument:';
            app.InstrumentstartdateLabel = uilabel(app.ProxyVars);
            app.InstrumentstartdateLabel.Text = 'Instrument: Start date:';
            app.startdateIV = uieditfield(app.ProxyVars, 'text');
            app.InstrumentEnddateLabel = uilabel(app.ProxyVars);
            app.InstrumentEnddateLabel.Text = 'Instrument: End date:';
            app.enddateIV = uieditfield(app.ProxyVars, 'text');
            app.Instrument = uieditfield(app.ProxyVars, 'text');
            app.FlatreducedformpriorLabel = uilabel(app.ProxyVars);
            app.FlatreducedformpriorLabel.Text = 'Flat reduced form prior:';
            app.prior_type_reduced_form = uiswitch(app.ProxyVars, 'slider');
            app.prior_type_reduced_form.Items = {'No', 'Yes'};
            app.prior_type_reduced_form.Value = 'Yes';
            app.HighrelevancepriorLabel = uilabel(app.ProxyVars);
            app.HighrelevancepriorLabel.Text = 'High relevance prior';
            app.prior_type_proxy = uiswitch(app.ProxyVars, 'slider');
            app.prior_type_proxy.Items = {'No', 'Yes'};
            app.prior_type_proxy.Value = 'Yes';
            app.CorrelShockLabel = uilabel(app.ProxyVars);
            app.CorrelShockLabel.Text = 'Correl Shock';
            app.Correlshock = uieditfield(app.ProxyVars, 'text');
            app.CorrelInstrumentLabel = uilabel(app.ProxyVars);
            app.CorrelInstrumentLabel.Text = 'Correl Instrument';
            app.Correlinstrument = uieditfield(app.ProxyVars, 'text');
            app.FAVARplotPanel = uipanel(app.GridLayout14);
            app.FAVARplotPanel.Title = 'FAVAR plot';
            app.PlotXLabel = uilabel(app.FAVARplotPanel);
            app.PlotXLabel.Text = 'Plot X';
            app.plotX = uieditfield(app.FAVARplotPanel, 'text');
            app.PlotXshockLabel = uilabel(app.FAVARplotPanel);
            app.PlotXshockLabel.Text = 'PlotX shock';
            app.plotXshock = uieditfield(app.FAVARplotPanel, 'text');
            app.StructuralidentificationsButtonGroup = uibuttongroup(app.GridLayout14);
            app.StructuralidentificationsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StructuralidentificationsButtonGroupSelectionChanged, true);
            app.StructuralidentificationsButtonGroup.Title = 'Structural identifications';
            app.Noidentification = uiradiobutton(app.StructuralidentificationsButtonGroup);
            app.Noidentification.Text = 'None';
            app.Choleskifactorisation = uiradiobutton(app.StructuralidentificationsButtonGroup);
            app.Choleskifactorisation.Text = 'Choleski';
            app.Triangularfactorisation = uiradiobutton(app.StructuralidentificationsButtonGroup);
            app.Triangularfactorisation.Text = 'Triangular';
            app.Signrestrictions = uiradiobutton(app.StructuralidentificationsButtonGroup);
            app.Signrestrictions.Text = 'Sign restrictions';
            app.Signrestrictions.Value = true;
            app.Proxy = uiradiobutton(app.StructuralidentificationsButtonGroup);
            app.Proxy.Text = 'Proxy SVAR';
            app.Proxysign = uiradiobutton(app.StructuralidentificationsButtonGroup);
            app.Proxysign.Text = 'Sign and proxy';
            app.REPLICATIONSTab = uitab(app.TabGroup);
            app.REPLICATIONSTab.Title = 'REPLICATIONS';
            app.ABOUTBEARTab = uitab(app.TabGroup);
            app.ABOUTBEARTab.Title = 'ABOUT BEAR';
            app.GridLayout = uigridlayout(app.ABOUTBEARTab);
            app.BAYESIANESTIMATIONANALYSISANDREGRESSIONBEARTOOLBOXLabel = uilabel(app.GridLayout);
            app.BAYESIANESTIMATIONANALYSISANDREGRESSIONBEARTOOLBOXLabel.Text = 'BAYESIAN ESTIMATION, ANALYSIS AND REGRESSION (BEAR) TOOLBOX';
            app.GridLayout12 = uigridlayout(app.GridLayout);
            app.ByAlistairDieppeandBjrnvanRoyeLabel = uilabel(app.GridLayout12);
            app.ByAlistairDieppeandBjrnvanRoyeLabel.Text = 'By Alistair Dieppe and Björn van Roye';
            app.Label = uilabel(app.GridLayout12);
            app.Label.Interpreter = 'html';
            app.Label.Text = {'<b>Authors:</b>'; ''; 'Alistair Dieppe (alistair.dieppe@ecb.europa.eu)'; 'Björn van Roye  (bvanroye@bloomberg.net)'; ''; '<b>Version:</b> 5.0'; ''; 'The updated version 5 of BEAR has benefitted from contributions from'; 'Boris Blagov, Marius Schulte and Ben Schumann.'; ''; 'This version builds-upon previous versions where Romain Legrand was'; 'instrumental in developing BEAR.'; ''; 'The authors are grateful to the following people for valuable input'; 'and advice which contributed to improve the quality of the toolbox:'; 'Paolo Bonomolo, Mirco Balatti, Marta Banbura, Niccolo Battistini,'; 'Gabriel Bobeica, Martin Bruns, Fabio Canova, Matteo Ciccarelli,'; 'Marek Jarocinski, Michele Lenza, Francesca Loria, Mirela Miescu,'; 'Gary Koop, Chiara Osbat, Giorgio Primiceri, Martino Ricci,'; 'Michal Rubaszek, Barbara Rossi, Fabian Schupp, Peter Welz and'; 'Hugo Vega de la Cruz.'; ''; 'These programmes are the responsibilities of the authors and not of'; 'the ECB and all errors and ommissions remain those of the authors.'; ''; 'Using the BEAR toolbox implies acceptance of the End User Licence'; 'Agreement and appropriate acknowledgement should be made.'; ''};
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'BEAR.PNG';
            % Show the figure after all components are created
            app.BEARinterfaceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BEARapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BEARinterfaceUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BEARinterfaceUIFigure)
        end
    end
end