classdef BEARapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BEARinterfaceUIFigure           matlab.ui.Figure
        ImportMenu                      matlab.ui.container.Menu
        ReplicationsMenu                matlab.ui.container.Menu
        AAU2009Menu                     matlab.ui.container.Menu
        BBE2005Menu                     matlab.ui.container.Menu
        BvV2018Menu                     matlab.ui.container.Menu
        CH2019Menu                      matlab.ui.container.Menu
        WGP2016Menu                     matlab.ui.container.Menu
        SavedSessionMenu                matlab.ui.container.Menu
        SettingsobjectfromworkspaceMenu  matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        SavecurrentsessionMenu          matlab.ui.container.Menu
        ExportcurrentoptionstotheworkspaceMenu  matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        AboutBEARMenu                   matlab.ui.container.Menu
        TabGrid                         matlab.ui.container.GridLayout
        GridLayout23                    matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        SPECIFICATIONTab                matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout11                    matlab.ui.container.GridLayout
        VARtypeTab                      matlab.ui.container.TabGroup
        BayesianTab                     matlab.ui.container.Tab
        BayesianGrid                    matlab.ui.container.GridLayout
        prior                           matlab.ui.container.ButtonGroup
        DeterministicButton             matlab.ui.control.RadioButton
        IndependentNormalWishartButton  matlab.ui.control.RadioButton
        NormalWishartButton             matlab.ui.control.RadioButton
        DummyObservationsButton         matlab.ui.control.RadioButton
        NormalDiffuseButton             matlab.ui.control.RadioButton
        MinnesotaButton                 matlab.ui.control.RadioButton
        HyperparametersPanel_3          matlab.ui.container.Panel
        GridLayout17_6                  matlab.ui.container.GridLayout
        priorsexogenous_bvar            matlab.ui.control.Switch
        ExogenousvariablesSwitchLabel   matlab.ui.control.Label
        lambda8_bvar                    matlab.ui.control.NumericEditField
        LongrunpriortightnessEditFieldLabel  matlab.ui.control.Label
        lambda7_bvar                    matlab.ui.control.NumericEditField
        DummyinitialobservationtightnessLabel  matlab.ui.control.Label
        lambda6_bvar                    matlab.ui.control.NumericEditField
        SumofcoefficienttightnessEditFieldLabel  matlab.ui.control.Label
        lambda5_bvar                    matlab.ui.control.NumericEditField
        BlockexogenetyshrinkageEditFieldLabel  matlab.ui.control.Label
        lambda4_bvar                    matlab.ui.control.NumericEditField
        ExogenousvariableEditFieldLabel  matlab.ui.control.Label
        PriorExcel_bvar                 matlab.ui.control.CheckBox
        lambda3_bvar                    matlab.ui.control.NumericEditField
        LagdecayEditField_2Label        matlab.ui.control.Label
        IGshapeonresidualvarianceEditFieldLabel_6  matlab.ui.control.Label
        IGscaleonresidualvarianceEditFieldLabel_6  matlab.ui.control.Label
        ARcoefficientonresidualvarianceEditFieldLabel_6  matlab.ui.control.Label
        ar_bvar                         matlab.ui.control.NumericEditField
        lambda1_bvar                    matlab.ui.control.NumericEditField
        lambda2_bvar                    matlab.ui.control.NumericEditField
        OptionsPanel                    matlab.ui.container.Panel
        GridLayout17_5                  matlab.ui.container.GridLayout
        lrp_bvar                        matlab.ui.control.CheckBox
        iobs_bvar                       matlab.ui.control.CheckBox
        scoeff_bvar                     matlab.ui.control.CheckBox
        DummyobservationextensionsLabel  matlab.ui.control.Label
        bex_bvar                        matlab.ui.control.Switch
        BlockexogeneityonexcelSwitchLabel  matlab.ui.control.Label
        hogs_bvar                       matlab.ui.control.Switch
        GridsearchonexcelSwitchLabel    matlab.ui.control.Label
        Iterations                      matlab.ui.container.Panel
        GridLayout18_4                  matlab.ui.container.GridLayout
        TotalnumberofiterationsEditFieldLabel_4  matlab.ui.control.Label
        NumberofburniniterationsEditFieldLabel_4  matlab.ui.control.Label
        It_bvar                         matlab.ui.control.NumericEditField
        Bu_bvar                         matlab.ui.control.NumericEditField
        OLSTab                          matlab.ui.container.Tab
        PanelTab                        matlab.ui.container.Tab
        PanelGrid                       matlab.ui.container.GridLayout
        panel                           matlab.ui.container.ButtonGroup
        DynamicStructureFactorButton    matlab.ui.control.RadioButton
        RandomEffectHierarchicalButton  matlab.ui.control.RadioButton
        PooledEstimatorButton           matlab.ui.control.RadioButton
        StaticStructureFactorButton     matlab.ui.control.RadioButton
        RandomEffectZellnerHongButton   matlab.ui.control.RadioButton
        MeangroupEstimatorButton        matlab.ui.control.RadioButton
        Iterations_2                    matlab.ui.container.Panel
        GridLayout18_5                  matlab.ui.container.GridLayout
        TotalnumberofiterationsEditFieldLabel_5  matlab.ui.control.Label
        NumberofburniniterationsEditFieldLabel_5  matlab.ui.control.Label
        It_panel                        matlab.ui.control.NumericEditField
        Bu_panel                        matlab.ui.control.NumericEditField
        EnterlistofunitsPanel           matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        unitnames_panel                 matlab.ui.control.EditField
        HyperparametersPanel            matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        IGshapeonresidualvarianceEditFieldLabel  matlab.ui.control.Label
        IGscaleonresidualvarianceEditFieldLabel  matlab.ui.control.Label
        ARcoefficientonresidualvarianceEditFieldLabel  matlab.ui.control.Label
        IGshapeonfactorvarianceEditFieldLabel  matlab.ui.control.Label
        IGscaleonfactorvarianceEditFieldLabel  matlab.ui.control.Label
        ARcoefficientonfactorEditFieldLabel  matlab.ui.control.Label
        VarianceonMetropolisdrawEditFieldLabel  matlab.ui.control.Label
        alpha0_panel                    matlab.ui.control.NumericEditField
        delta0_panel                    matlab.ui.control.NumericEditField
        gama_panel                      matlab.ui.control.NumericEditField
        a0_panel                        matlab.ui.control.NumericEditField
        b0_panel                        matlab.ui.control.NumericEditField
        rho_panel                       matlab.ui.control.NumericEditField
        psi_panel                       matlab.ui.control.NumericEditField
        HyperparametersPanelpanel       matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        AutoregressivecoefficientEditFieldLabel  matlab.ui.control.Label
        OveralltightnessEditFieldLabel  matlab.ui.control.Label
        CrossvariableweightingEditFieldLabel  matlab.ui.control.Label
        LagdecayEditFieldLabel          matlab.ui.control.Label
        ExogenousvariabletightnessEditFieldLabel  matlab.ui.control.Label
        IGshapeonoveralltightnessEditFieldLabel  matlab.ui.control.Label
        IGscaleonoveralltightnessEditFieldLabel  matlab.ui.control.Label
        ar_panel                        matlab.ui.control.NumericEditField
        lambda1_panel                   matlab.ui.control.NumericEditField
        lambda2_panel                   matlab.ui.control.NumericEditField
        lambda3_panel                   matlab.ui.control.NumericEditField
        lambda4_panel                   matlab.ui.control.NumericEditField
        s0_panel                        matlab.ui.control.NumericEditField
        v0_panel                        matlab.ui.control.NumericEditField
        StochasticVolatilityTab         matlab.ui.container.Tab
        SVGrid                          matlab.ui.container.GridLayout
        HyperparametersPanel_4          matlab.ui.container.Panel
        GridLayout17_7                  matlab.ui.container.GridLayout
        priorsexogenous_sv              matlab.ui.control.Switch
        ExogenousvariablesSwitchLabel_2  matlab.ui.control.Label
        lambda8_sv                      matlab.ui.control.NumericEditField
        LongrunpriortightnessEditFieldLabel_2  matlab.ui.control.Label
        lambda7_sv                      matlab.ui.control.NumericEditField
        DummyinitialobservationtightnessLabel_2  matlab.ui.control.Label
        lambda6_sv                      matlab.ui.control.NumericEditField
        SumofcoefficienttightnessEditFieldLabel_2  matlab.ui.control.Label
        lambda5_sv                      matlab.ui.control.NumericEditField
        BlockexogenetyshrinkageEditFieldLabel_2  matlab.ui.control.Label
        lambda4_sv                      matlab.ui.control.NumericEditField
        ExogenousvariableEditFieldLabel_2  matlab.ui.control.Label
        PriorExcel_sv                   matlab.ui.control.CheckBox
        lambda3_sv                      matlab.ui.control.NumericEditField
        LagdecayEditField_2Label_2      matlab.ui.control.Label
        IGshapeonresidualvarianceEditFieldLabel_7  matlab.ui.control.Label
        IGscaleonresidualvarianceEditFieldLabel_7  matlab.ui.control.Label
        ARcoefficientonresidualvarianceEditFieldLabel_7  matlab.ui.control.Label
        ar_sv                           matlab.ui.control.NumericEditField
        lambda1_sv                      matlab.ui.control.NumericEditField
        lambda2_sv                      matlab.ui.control.NumericEditField
        Iterations_3                    matlab.ui.container.Panel
        GridLayout18_6                  matlab.ui.container.GridLayout
        TotalnumberofiterationsEditFieldLabel_6  matlab.ui.control.Label
        NumberofburniniterationsEditFieldLabel_6  matlab.ui.control.Label
        It_sv                           matlab.ui.control.NumericEditField
        Bu_sv                           matlab.ui.control.NumericEditField
        stvol                           matlab.ui.container.ButtonGroup
        StochasticButton                matlab.ui.control.RadioButton
        LargeVARButton                  matlab.ui.control.RadioButton
        RandomInertiaButton             matlab.ui.control.RadioButton
        StandardButton                  matlab.ui.control.RadioButton
        HyperparametersstochasticPanel  matlab.ui.container.Panel
        GridLayout17_3                  matlab.ui.container.GridLayout
        alltirf_sv                      matlab.ui.control.CheckBox
        IGshapeonresidualvarianceEditFieldLabel_3  matlab.ui.control.Label
        IGscaleonresidualvarianceEditFieldLabel_3  matlab.ui.control.Label
        ARcoefficientonresidualvarianceEditFieldLabel_3  matlab.ui.control.Label
        IGshapeonfactorvarianceEditFieldLabel_3  matlab.ui.control.Label
        IGscaleonfactorvarianceEditFieldLabel_3  matlab.ui.control.Label
        gamma_sv                        matlab.ui.control.NumericEditField
        alpha0_sv                       matlab.ui.control.NumericEditField
        delta0_sv                       matlab.ui.control.NumericEditField
        gamma0_sv                       matlab.ui.control.NumericEditField
        zeta0_sv                        matlab.ui.control.NumericEditField
        TimeVaryingTab                  matlab.ui.container.Tab
        TVPGrid                         matlab.ui.container.GridLayout
        Iterations_4                    matlab.ui.container.Panel
        GridLayout18_7                  matlab.ui.container.GridLayout
        TotalnumberofiterationsEditFieldLabel_7  matlab.ui.control.Label
        NumberofburniniterationsEditFieldLabel_7  matlab.ui.control.Label
        It_tvp                          matlab.ui.control.NumericEditField
        Bu_tvp                          matlab.ui.control.NumericEditField
        tvbvar                          matlab.ui.container.ButtonGroup
        GeneralButton                   matlab.ui.control.RadioButton
        VARcoefficientsButton           matlab.ui.control.RadioButton
        HyperparameterstimevaryingPanel  matlab.ui.container.Panel
        GridLayout17_2                  matlab.ui.container.GridLayout
        alltirf_tvp                     matlab.ui.control.CheckBox
        IGshapeonresidualvarianceEditFieldLabel_2  matlab.ui.control.Label
        IGscaleonresidualvarianceEditFieldLabel_2  matlab.ui.control.Label
        ARcoefficientonresidualvarianceEditFieldLabel_2  matlab.ui.control.Label
        gamma_tvp                       matlab.ui.control.NumericEditField
        alpha0_tvp                      matlab.ui.control.NumericEditField
        delta0_tvp                      matlab.ui.control.NumericEditField
        MixedFrequencyTab               matlab.ui.container.Tab
        SELECTVARTYPELabel              matlab.ui.control.Label
        APPLICATIONSTab                 matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        IRFt                            matlab.ui.container.ButtonGroup
        Proxysign                       matlab.ui.control.RadioButton
        Proxy                           matlab.ui.control.RadioButton
        Signrestrictions                matlab.ui.control.RadioButton
        Triangularfactorisation         matlab.ui.control.RadioButton
        Choleskifactorisation           matlab.ui.control.RadioButton
        Noidentification                matlab.ui.control.RadioButton
        FAVARplotPanel                  matlab.ui.container.Panel
        plotXshock                      matlab.ui.control.EditField
        PlotXshockLabel                 matlab.ui.control.Label
        plotX                           matlab.ui.control.EditField
        PlotXLabel                      matlab.ui.control.Label
        ProxyVars                       matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        InstrumentLabel                 matlab.ui.control.Label
        InstrumentstartdateLabel        matlab.ui.control.Label
        startdateIV                     matlab.ui.control.EditField
        InstrumentEnddateLabel          matlab.ui.control.Label
        enddateIV                       matlab.ui.control.EditField
        Instrument                      matlab.ui.control.EditField
        FlatreducedformpriorLabel       matlab.ui.control.Label
        prior_type_reduced_form         matlab.ui.control.Switch
        HighrelevancepriorLabel         matlab.ui.control.Label
        prior_type_proxy                matlab.ui.control.Switch
        CorrelShockLabel                matlab.ui.control.Label
        CorrelShock                     matlab.ui.control.EditField
        CorrelInstrumentLabel           matlab.ui.control.Label
        CorrelInstrument                matlab.ui.control.EditField
        Estimationoptions               matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        ForecastevaluationsSwitchLabel  matlab.ui.control.Label
        Feval_cp                        matlab.ui.control.Switch
        ForecaststepaheadevaluationsEditFieldLabel  matlab.ui.control.Label
        hstep_cp                        matlab.ui.control.NumericEditField
        RollingWindow0forfullsampleEditFieldLabel  matlab.ui.control.Label
        window_size_cp                  matlab.ui.control.NumericEditField
        EvaluationSizeEditFieldLabel    matlab.ui.control.Label
        evaluation_size_cp              matlab.ui.control.NumericEditField
        CFt                             matlab.ui.container.ButtonGroup
        Tiltinginterval                 matlab.ui.control.RadioButton
        Tiltingmedian                   matlab.ui.control.RadioButton
        Standardshockspecific           matlab.ui.control.RadioButton
        Standardallshocks               matlab.ui.control.RadioButton
        Periodoptions                   matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        ForecastsEnddateEditFieldLabel  matlab.ui.control.Label
        Fenddate_cp                     matlab.ui.control.EditField
        IRFperiodsEditFieldLabel        matlab.ui.control.Label
        IRFperiods_cp                   matlab.ui.control.NumericEditField
        ForecastsafterlastsampleperiodSwitchLabel  matlab.ui.control.Label
        Fendsmpl_cp                     matlab.ui.control.Switch
        ForecastsStartdateEditFieldLabel  matlab.ui.control.Label
        Fstartdate_cp                   matlab.ui.control.EditField
        VARcoefficientsEditFieldLabel   matlab.ui.control.Label
        cband_cp                        matlab.ui.control.NumericEditField
        Applicationoptions              matlab.ui.container.Panel
        GridLayout22                    matlab.ui.container.GridLayout
        FAVARLabel                      matlab.ui.control.Label
        ImpulseresponsefunctionsSwitchLabel  matlab.ui.control.Label
        IRF_cp                          matlab.ui.control.Switch
        UnconditionalforecastsSwitchLabel  matlab.ui.control.Label
        F_cp                            matlab.ui.control.Switch
        HistoricaldecompositionsSwitchLabel  matlab.ui.control.Label
        HD_cp                           matlab.ui.control.Switch
        ConditionalforecastsSwitchLabel  matlab.ui.control.Label
        CF_cp                           matlab.ui.control.Switch
        ForecasterrorvarianceSwitchLabel  matlab.ui.control.Label
        FEVD_cp                         matlab.ui.control.Switch
        favarIRFplot                    matlab.ui.control.CheckBox
        favarFEVDplot                   matlab.ui.control.CheckBox
        favarHDplot                     matlab.ui.control.CheckBox
        GridLayout4                     matlab.ui.container.GridLayout
        GridLayout25                    matlab.ui.container.GridLayout
        excelFile                       matlab.ui.control.EditField
        SelectExcelfilewithinputsButton  matlab.ui.control.Button
        GridLayout24                    matlab.ui.container.GridLayout
        results_sub                     matlab.ui.control.EditField
        ResultsfilenameLabel            matlab.ui.control.Label
        ChangeresultsfolderButton       matlab.ui.control.StateButton
        results_path                    matlab.ui.control.EditField
        GridLayout18                    matlab.ui.container.GridLayout
        QuickExportButton               matlab.ui.control.Button
        RUNButton                       matlab.ui.control.Button
        GridLayout9                     matlab.ui.container.GridLayout
        plot                            matlab.ui.control.CheckBox
        results                         matlab.ui.control.CheckBox
        GridLayout8                     matlab.ui.container.GridLayout
        IncludeconstantSwitchLabel      matlab.ui.control.Label
        const_cp                        matlab.ui.control.Switch
        LagsEditFieldLabel              matlab.ui.control.Label
        lags_cp                         matlab.ui.control.NumericEditField
        GridLayout7                     matlab.ui.container.GridLayout
        varexo_cp                       matlab.ui.control.TextArea
        EnterthelistofexogeneousvariablesTextAreaLabel  matlab.ui.control.Label
        varendo_cp                      matlab.ui.control.TextArea
        EnterthelistofendogeneousvariablesTextAreaLabel  matlab.ui.control.Label
        GridLayout6                     matlab.ui.container.GridLayout
        enddate_cp                      matlab.ui.control.EditField
        EstimationEndDateEditFieldLabel  matlab.ui.control.Label
        startdate_cp                    matlab.ui.control.EditField
        EstimationStartDateEditFieldLabel  matlab.ui.control.Label
        GridLayout5                     matlab.ui.container.GridLayout
        frequency_cp                    matlab.ui.control.DropDown
        DataFrequencyDropDownLabel      matlab.ui.control.Label
    end


    properties (Access = private)
        opts (1,1) bear.app.optionsProxyForApp % BEAR settings object
    end

    methods (Access = private)

        function changeAppAuto(app, se, cue)
            % Gett app properties that need to change
            mcls = meta.class.fromName(mfilename);

            idx = contains({mcls.PropertyList.Name}, cue);
            prop = mcls.PropertyList(idx);
            prop = {prop.Name};

            for i = 1 : length(prop)

                pname = extractBefore(prop{i}, cue);
                try
                    app.(prop{i}).Value = se.(pname);
                catch
                    prop{i} %For debuggging keep for now.
                end

            end

            switch se.VARtype

                case 2

                    switch se.prior
                        case "Minnesota_univariate"
                            app.prior.SelectedObject = app.MinnesotaButton;
                        case "Nd"
                            app.prior.SelectedObject = app.NormalDiffuseButton;
                        case "Dummy"
                            app.prior.SelectedObject = app.DummyObservationsButton;
                        case "Nw_univariate"
                            app.prior.SelectedObject = app.NormalWishartButton;
                        case "Inw_univariate"
                            app.prior.SelectedObject = app.IndependentNormalWishartButton;
                        case "Madj"
                            app.prior.SelectedObject = app.DeterministicButton;
                    end

                case 4

                    switch se.panel

                        case "Mge"
                            app.panel.SelectedObject = app.MeangroupEstimatorButton;
                        case "Random_zh"
                            app.panel.SelectedObject = app.RandomEffectZellnerHongButton;
                        case "Factor_static"
                            app.panel.SelectedObject = app.StaticStructureFactorButton;
                        case "Pooled"
                            app.panel.SelectedObject = app.PooledEstimatorButton;
                        case "Random_hierarchical"
                            app.panel.SelectedObject = app.RandomEffectHierarchicalButton;
                        case "Factor_dynamic"
                            app.panel.SelectedObject = app.DynamicStructureFactorButton;
                    end

                case 5

                    switch se.stvol

                        case "Standard"
                            app.stvol.SelectedObject = app.StandardButton;
                        case "Random_scaling"
                            app.stvol.SelectedObject = app.RandomInertiaButton;
                        case "Large_BVAR"
                            app.stvol.SelectedObject = app.LargeVARButton;
                        case "LMM"
                            app.stvol.SelectedObject = app.StochasticButton;
                    end

                case 6

                    switch se.tvbvar
                        case 'TVP'
                            app.tvbvar.SelectedObject = app.VARcoefficientsButton;
                        case 'TVP_SV'
                            app.tvbvar.SelectedObject = app.GeneralButton;
                    end

            end

        end

        function loadCommonProps(app, s)

            % Set common properties
            app.changeAppAuto(s, '_cp')

            % set radio buttons
            switch s.CFt

                case 'Standard_all'
                    app.CFt.SelectedObject = app.Standardallshocks;
                case 'Standard_specific'
                    app.CFt.SelectedObject = app.Standardshockspecific;
                case 'Tilting_median'
                    app.CFt.SelectedObject = app.Tiltingmedian;
                case 'Tilting_interval'
                    app.CFt.SelectedObject = app.Tiltinginterval;
            end

            switch s.IRFt

                case 'Reduced'
                    app.IRFt.SelectedObject = app.Noidentification;
                case 'Cholesky'
                    app.IRFt.SelectedObject = app.Choleskifactorisation;
                case 'Triangular'
                    app.IRFt.SelectedObject = app.Triangularfactorisation;
                case 'Sign'
                    app.IRFt.SelectedObject = app.Signrestrictions;
                case 'IV'
                    app.IRFt.SelectedObject = app.Proxy;
                case 'IV_Sign'
                    app.IRFt.SelectedObject = app.Proxysign;
            end

            % Set preferences
            app.excelFile.Value    = s.excelFile;
            app.results_path.Value = s.results_path;
            app.results_sub.Value  = s.results_sub;
            app.results.Value      = s.results;
            app.plot.Value         = s.plot;

            % Structident
            if isprop(s,'strctident')

                prop = properties(s.strctident);
                for i = 1 : length(prop)
                    try
                        app.(prop{i}).Value = s.strctident.(prop{i});
                    catch
                        prop{i} %For debuggging keep for now.
                    end
                end

            end

            % callbacks
            app.lambda6_bvar.Enable = app.scoeff_bvar.Value;
            app.lambda7_bvar.Enable = app.iobs_bvar.Value;
            app.lambda8_bvar.Enable = app.lrp_bvar.Value;
        end

        function loadSpecificOpts(app, s)

            switch s.VARtype
                case 1
                    app.VARtypeTab.SelectedTab = app.OLSTab;
                case 2
                    app.changeAppAuto(s, '_bvar' )
                    app.VARtypeTab.SelectedTab = app.BayesianTab;
                case 4
                    app.changeAppAuto(s, '_panel' )
                    app.VARtypeTab.SelectedTab = app.PanelTab;
                case 5
                    app.changeAppAuto(s, '_sv')
                    app.VARtypeTab.SelectedTab = app.StochasticVolatilityTab;
                case 6
                    app.changeAppAuto(s, '_tvp')
                    app.VARtypeTab.SelectedTab = app.TimeVaryingTab;
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            if isdeployed
                app.QuickExportButton.Enable = 'off';
                app.SettingsobjectfromworkspaceMenu.Enable = 'off';
                app.ExportcurrentoptionstotheworkspaceMenu.Enable = 'off';
            end
            
            app.results_path.Value = fullfile(app.opts.opts.results_path, app.opts.opts.results_sub + ".xlsx");
            
        end

        % Selection changed function: tvbvar
        function tvbvarSelectionChanged(app, event)
            selectedButton = app.tvbvar.SelectedObject;
            switch selectedButton.Text
                case "General"
                    app.HyperparameterstimevaryingPanel.Enable = 'on';
                    app.opts.setProp('tvbvar', 'TVP_SV');
                case "VAR coefficients"
                    app.HyperparameterstimevaryingPanel.Enable = 'off';
                    app.opts.setProp('tvbvar', 'TVP');
            end
        end

        % Selection changed function: stvol
        function stvolSelectionChanged(app, event)
            selectedButton = app.stvol.SelectedObject;
            switch selectedButton.Text
                case "Standard"
                    app.HyperparametersstochasticPanel.Enable = 'on';
                    app.IGshapeonresidualvarianceEditFieldLabel_3.Enable = 'on';
                    app.gamma_sv.Enable = 'on';
                    app.IGscaleonfactorvarianceEditFieldLabel_3.Enable = 'off';
                    app.zeta0_sv.Enable = 'off';
                    app.IGshapeonfactorvarianceEditFieldLabel_3.Enable = 'off';
                    app.gamma0_sv.Enable = 'off';
                    app.opts.setProp('stvol', 'Standard');
                case "Random Inertia"
                    app.HyperparametersstochasticPanel.Enable = 'on';
                    app.IGshapeonresidualvarianceEditFieldLabel_3.Enable = 'off';
                    app.gamma_sv.Enable = 'off';
                    app.IGscaleonfactorvarianceEditFieldLabel_3.Enable = 'on';
                    app.zeta0_sv.Enable = 'on';
                    app.IGshapeonfactorvarianceEditFieldLabel_3.Enable = 'on';
                    app.gamma0_sv.Enable = 'on';
                    app.opts.setProp('stvol', 'Random_scaling');
                case "Large VAR"
                    app.HyperparametersstochasticPanel.Enable = 'on';
                    app.IGshapeonresidualvarianceEditFieldLabel_3.Enable = 'on';
                    app.gamma_sv.Enable = 'on';
                    app.IGscaleonfactorvarianceEditFieldLabel_3.Enable = 'off';
                    app.zeta0_sv.Enable = 'off';
                    app.IGshapeonfactorvarianceEditFieldLabel_3.Enable = 'off';
                    app.gamma0_sv.Enable = 'off';
                    app.opts.setProp('stvol', 'Large_BVAR');
                case "Stochastic"
                    app.HyperparametersstochasticPanel.Enable = 'off';
                    app.IGscaleonfactorvarianceEditFieldLabel_3.Enable = 'on';
                    app.zeta0_sv.Enable = 'on';
                    app.IGshapeonfactorvarianceEditFieldLabel_3.Enable = 'on';
                    app.gamma0_sv.Enable = 'on';
                    app.opts.setProp('stvol', 'LMM');
            end
        end

        % Selection change function: VARtypeTab
        function VARtypeTabSelectionChanged(app, event)
            selectedTab = app.VARtypeTab.SelectedTab;
            switch selectedTab.Title
                case "OLS"
                    app.opts.VARtype = 1;
                case "Bayesian"
                    app.opts.VARtype = 2;
                case "Panel"
                    app.opts.VARtype = 4;
                case "Stochastic Volatility"
                    app.opts.VARtype = 5;
                case "Time Varying"
                    app.opts.VARtype = 6;
            end
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            try
                d = uiprogressdlg(app.BEARinterfaceUIFigure, ...
                    'Message','Running BEAR', ...
                    'Indeterminate', 'on');
                BEARmain(app.opts.opts)
                delete(d);
            catch e
                uialert(app.BEARinterfaceUIFigure, e.message, 'Something went wrong!')
            end
        end

        % Callback function
        function EstimationEndDateDatePicker_2ValueChanged(app, event)
            value = app.EstimationEndDateDatePicker_2.Value;
            app.opts.setCommonProp('enddate', strrep(char(value),'Q','q'));
        end

        % Button pushed function: QuickExportButton
        function QuickExportButtonPushed(app, event)
            assignin("base", 'opts', app.opts.opts)
        end

        % Value changed function: ChangeresultsfolderButton
        function ChangeresultsfolderButtonValueChanged(app, event)
            selpath = uigetdir(pwd);
            if ~isequal(selpath,0)
                if isempty(app.results_sub.Value)
                    uialert(app.BEARinterfaceUIFigure, 'Please set a name to the results file')
                end
                app.opts.setCommonProp('results_path', selpath);
                app.results_path.Value = fullfile(selpath, app.results_sub.Value + ".xlsx");
            end
        end

        % Button pushed function: SelectExcelfilewithinputsButton
        function SelectExcelfilewithinputsButtonPushed(app, event)
            [file,path] = uigetfile('*.xlsx');
            if ~isequal(file,0)
                app.excelFile.Value = fullfile(path,file);
                app.opts.setCommonProp('excelFile', fullfile(path,file));
            end
        end

        % Value changed function: CF_cp, FEVD_cp, F_cp, 
        % Fenddate_cp, Fendsmpl_cp, Fstartdate_cp, HD_cp, IRF_cp, 
        % IRFperiods_cp, cband_cp, const_cp, enddate_cp, 
        % evaluation_size_cp, frequency_cp, hstep_cp, lags_cp, 
        % plot, results, startdate_cp, varendo_cp, varexo_cp, 
        % window_size_cp
        function setCommonProp(app, event)
            if isa(event.Source, 'matlab.ui.control.TextArea')
                app.opts.setCommonProp(event.Source.Tag, event.Value{1});
            else
                app.opts.setCommonProp(event.Source.Tag, event.Value);
            end
        end

        % Selection changed function: prior
        function priorSelectionChanged(app, event)
            switch event.NewValue.Text
                case "Minnesota"
                    pr = bear.PRIORtype('Minnesota_univariate');
                case "Normal Diffuse"
                    pr = bear.PRIORtype('Nd');
                case "Dummy Observations"
                    pr = bear.PRIORtype('Dummy');
                case "Normal Wishart"
                    pr = bear.PRIORtype('Nw_univariate');
                case "Independent Normal Wishart"
                    pr = bear.PRIORtype('Inw_univariate');
                case "Deterministic"
                    pr = bear.PRIORtype('Madj');
            end
            app.opts.setProp('prior', pr);
        end

        % Selection changed function: panel
        function panelSelectionChanged(app, event)
            switch event.NewValue.Text
                case "Mean group Estimator"
                    pn = bear.PANELtype('Mge');
                case "Random Effect (Zellner-Hong)"
                    pn = bear.PANELtype('Random_zh');
                case "Static Structure Factor"
                    pn = bear.PANELtype('Factor_static');
                case "Pooled Estimator"
                    pn = bear.PANELtype('Pooled');
                case "Random Effect (Hierarchical)"
                    pn = bear.PANELtype('Random_hierarchical');
                case "Dynamic Structure Factor"
                    pn = bear.PANELtype('Factor_dynamic');
            end
            app.opts.setProp('panel', pn);
        end

        % Value changed function: Bu_bvar, Bu_panel, Bu_sv, Bu_tvp, 
        % It_bvar, It_panel, It_sv, It_tvp, PriorExcel_bvar, 
        % PriorExcel_sv, a0_panel, alltirf_sv, alltirf_tvp, 
        % alpha0_panel, alpha0_sv, alpha0_tvp, ar_bvar, ar_panel, 
        % b0_panel, bex_bvar, delta0_panel, delta0_sv, delta0_tvp, 
        % gama_panel, gamma0_sv, gamma_sv, gamma_tvp, hogs_bvar, 
        % lambda1_bvar, lambda1_panel, lambda1_sv, lambda2_bvar, 
        % lambda2_panel, lambda2_sv, lambda3_bvar, lambda3_panel, 
        % lambda3_sv, lambda4_bvar, lambda4_panel, lambda4_sv, 
        % lambda5_bvar, lambda5_sv, lambda6_bvar, lambda6_sv, 
        % lambda7_bvar, lambda7_sv, lambda8_bvar, lambda8_sv, 
        % priorsexogenous_bvar, priorsexogenous_sv, psi_panel, 
        % rho_panel, s0_panel, unitnames_panel, v0_panel, zeta0_sv
        function setProp(app, event)
            try
                app.opts.setProp(event.Source.Tag, event.Value);
            catch e
                app.opts.opts.(event.Source.Tag) = event.PreviousValue;
                event.Source.Value = event.PreviousValue;
                uialert(app.BEARinterfaceUIFigure, e.message, "Something went wrong!")
            end
        end

        % Selection changed function: CFt
        function CFtSelectionChanged(app, event)
            switch event.NewValue.Text
                case "Standard (all shocks)"
                    ct = bear.CFtype('Standard_all');
                case "Standard (shock specific)"
                    ct = bear.CFtype('Standard_specific');
                case "Titling (median)"
                    ct = bear.CFtype('Tilting_median');
                case "Titling (interval)"
                    ct = bear.CFtype('Tilting_interval');
            end
            app.opts.setCommonProp('CFt', ct);
        end

        % Selection changed function: IRFt
        function IRFtSelectionChanged(app, event)
            switch event.NewValue.Text
                case "None"
                    ct = bear.IRFtype('Reduced');
                case "Triangular"
                    ct = bear.IRFtype('Triangular');
                case "Cholesky"
                    ct = bear.IRFtype('Cholesky');
                case "Sign restrictions"
                    ct = bear.IRFtype('Sign');
                case "Proxy SVAR"
                    ct = bear.IRFtype('IV');
                case "Sign and proxy"
                    ct = bear.IRFtype('IV_Sign');
            end
            app.opts.setCommonProp('IRFt', ct);
            if isprop(app.opts.opts, 'strctident')
                for p = properties(app.opts.opts.strctident)'
                    prop = p{1};
                    try
                        app.opts.opts.strctident.(prop) = app.(prop).Value;
                    catch
                    end

                end
            end
        end

        % Value changed function: CorrelInstrument, CorrelShock, 
        % Instrument, enddateIV, prior_type_proxy, 
        % prior_type_reduced_form, startdateIV
        function StructidentValueChanged(app, event)
            if isprop(app.opts, 'structident') && ~isempty(app.opts.structident)
                structident = app.opts.structident;
                structident.(event.Source.Tag) = event.Value;
                app.opts.setCommonProp('structident', structident);
            end
        end

        % Value changed function: favarIRFplot
        function favarIRFplotValueChanged(app, event)
            favar = app.opts.opts.favar;
            favar.IRF.plot = event.Value;
            app.opts.setCommonProp('favar', favar);
        end

        % Value changed function: favarFEVDplot
        function favarFEVDplotValueChanged(app, event)
            favar = app.opts.opts.favar;
            favar.FEVD.plot = event.Value;
            app.opts.setCommonProp('favar', favar);
        end

        % Value changed function: favarHDplot
        function favarHDplotValueChanged(app, event)
            favar = app.opts.opts.favar;
            favar.HD.plot = event.Value;
            app.opts.setCommonProp('favar', favar);
        end

        % Value changed function: scoeff_bvar
        function scoeff_bvarValueChanged(app, event)
            value = app.scoeff_bvar.Value;
            app.lambda6_bvar.Enable = value;
            app.opts.setProp(event.Source.Tag, value)
        end

        % Value changed function: iobs_bvar
        function iobs_bvarValueChanged(app, event)
            value = app.iobs_bvar.Value;
            app.lambda7_bvar.Enable = value;
            app.opts.setProp(event.Source.Tag, value)
        end

        % Value changed function: lrp_bvar
        function lrp_bvarValueChanged(app, event)
            value = app.lrp_bvar.Value;
            app.lambda8_bvar.Enable = value;
            app.opts.setProp(event.Source.Tag, value)
        end

        % Menu selected function: AboutBEARMenu
        function AboutBEARMenuSelected(app, event)
            bear.app.About();
        end

        % Menu selected function: SavecurrentsessionMenu
        function SavecurrentsessionMenuSelected(app, event)
            [file,path] = uiputfile('appsettings.mat');

            if ~isequal(file,0)
                opts = app.opts; %#ok<ADPROPLC>
                save(fullfile(path, file), 'opts')
            end

        end

        % Menu selected function: SavedSessionMenu
        function SavedSessionMenuSelected(app, event)

            [file,path] = uigetfile('*.mat');

            if ~isequal(file,0)
                % Load previous session
                se = load(fullfile(path,file));

                try
                    se = se.opts;
                    if ~isa(se, 'bear.app.optionsProxyForApp')
                        error('BEARapp:InvalidSession','invalid opts type')
                    end
                catch
                    uialert(app.BEARinterfaceUIFigure, ...
                        'The .mat file needs to contain a single variable with an app session or a BEAR settings object named "opts"', ...
                        'Incorrect Variable Type');
                    return
                end
                app.opts = se;

                app.loadSpecificOpts(se.opts)
                app.loadCommonProps(se.opts)

                if isa(se.opts, 'bear.app.optionsProxyForApp')

                    % Change properties based on proxy object
                    pr = ["_bvar", "_panel", "_sv", "_tvp"];
                    vt = [2 4 5 6];

                    for k = 1 : length(vt)
                        se.VARtype = vt(k);
                        changeAppAuto(app, se.opts, pr(k))
                    end

                end
            end
        end

        % Menu selected function: 
        % ExportcurrentoptionstotheworkspaceMenu
        function ExportcurrentoptionstotheworkspaceMenuSelected(app, event)
            prompt = {'Please select the settings name'};
            dlgtitle = 'Export BEAR settings';
            definput = {'opts'};
            dims = [1 40];
            varname = inputdlg(prompt,dlgtitle,dims,definput);

            if ~isempty(varname)
                assignin("base", varname{1}, app.opts.opts)
            end
        end

        % Menu selected function: AAU2009Menu, BBE2005Menu, 
        % BvV2018Menu, CH2019Menu, WGP2016Menu
        function loadReplication(app, event)
            s = getReplicationSettings(event.Source.Text);
            app.opts.opts = s;
            app.loadSpecificOpts(s);
            app.loadCommonProps(s)

        end

        % Menu selected function: SettingsobjectfromworkspaceMenu
        function SettingsobjectfromworkspaceMenuSelected(app, event)
            prompt = {'Please specify the name of the variable'};
            dlgtitle = 'Save BEAR session';
            definput = {'opts'};
            dims = [1 40];
            s = inputdlg(prompt,dlgtitle,dims,definput);
            if ~isempty(s)
                s = evalin('base', s{1});
                app.opts.opts = s;
                app.loadSpecificOpts(s);
                app.loadCommonProps(s)
            end
        end

        % Value changed function: results_sub
        function results_subValueChanged(app, event)
            value = app.results_sub.Value;
            app.opts.setCommonProp('results_sub', value);
            app.results_path.Value = fullfile(app.opts.opts.results_path, value + ".xlsx");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BEARinterfaceUIFigure = uifigure('Visible', 'off');
            app.BEARinterfaceUIFigure.Name = 'BEAR interface';
            app.BEARinterfaceUIFigure.Icon = 'BEAR.PNG';
            app.ImportMenu = uimenu(app.BEARinterfaceUIFigure);
            app.ImportMenu.Text = 'Import';
            app.ReplicationsMenu = uimenu(app.ImportMenu);
            app.ReplicationsMenu.Text = 'Replications';
            app.AAU2009Menu = uimenu(app.ReplicationsMenu);
            app.AAU2009Menu.MenuSelectedFcn = createCallbackFcn(app, @loadReplication, true);
            app.AAU2009Menu.Text = 'AAU2009';
            app.BBE2005Menu = uimenu(app.ReplicationsMenu);
            app.BBE2005Menu.MenuSelectedFcn = createCallbackFcn(app, @loadReplication, true);
            app.BBE2005Menu.Text = 'BBE2005';
            app.BvV2018Menu = uimenu(app.ReplicationsMenu);
            app.BvV2018Menu.MenuSelectedFcn = createCallbackFcn(app, @loadReplication, true);
            app.BvV2018Menu.Text = 'BvV2018';
            app.CH2019Menu = uimenu(app.ReplicationsMenu);
            app.CH2019Menu.MenuSelectedFcn = createCallbackFcn(app, @loadReplication, true);
            app.CH2019Menu.Text = 'CH2019';
            app.WGP2016Menu = uimenu(app.ReplicationsMenu);
            app.WGP2016Menu.MenuSelectedFcn = createCallbackFcn(app, @loadReplication, true);
            app.WGP2016Menu.Text = 'WGP2016';
            app.SavedSessionMenu = uimenu(app.ImportMenu);
            app.SavedSessionMenu.MenuSelectedFcn = createCallbackFcn(app, @SavedSessionMenuSelected, true);
            app.SavedSessionMenu.Text = 'Saved Session';
            app.SettingsobjectfromworkspaceMenu = uimenu(app.ImportMenu);
            app.SettingsobjectfromworkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @SettingsobjectfromworkspaceMenuSelected, true);
            app.SettingsobjectfromworkspaceMenu.Text = 'Settings object from workspace';
            app.ExportMenu = uimenu(app.BEARinterfaceUIFigure);
            app.ExportMenu.Text = 'Export';
            app.SavecurrentsessionMenu = uimenu(app.ExportMenu);
            app.SavecurrentsessionMenu.MenuSelectedFcn = createCallbackFcn(app, @SavecurrentsessionMenuSelected, true);
            app.SavecurrentsessionMenu.Text = 'Save current session';
            app.ExportcurrentoptionstotheworkspaceMenu = uimenu(app.ExportMenu);
            app.ExportcurrentoptionstotheworkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportcurrentoptionstotheworkspaceMenuSelected, true);
            app.ExportcurrentoptionstotheworkspaceMenu.Text = 'Export current options to the workspace';
            app.AboutMenu = uimenu(app.BEARinterfaceUIFigure);
            app.AboutMenu.Text = 'About';
            app.AboutBEARMenu = uimenu(app.AboutMenu);
            app.AboutBEARMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutBEARMenuSelected, true);
            app.AboutBEARMenu.Text = 'About BEAR';
            app.TabGrid = uigridlayout(app.BEARinterfaceUIFigure);
            app.GridLayout23 = uigridlayout(app.TabGrid);
            app.GridLayout4 = uigridlayout(app.GridLayout23);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.DataFrequencyDropDownLabel = uilabel(app.GridLayout5);
            app.DataFrequencyDropDownLabel.Text = 'Data Frequency';
            app.frequency_cp = uidropdown(app.GridLayout5);
            app.frequency_cp.Items = {'yearly', 'quarterly', 'monthly', 'weekly', 'daily', 'undated'};
            app.frequency_cp.ItemsData = [1 2 3 4 5 6];
            app.frequency_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.frequency_cp.Value = 2;
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.EstimationStartDateEditFieldLabel = uilabel(app.GridLayout6);
            app.EstimationStartDateEditFieldLabel.Text = 'Estimation Start Date';
            app.startdate_cp = uieditfield(app.GridLayout6, 'text');
            app.startdate_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.startdate_cp.Tag = 'startdate';
            app.startdate_cp.Tooltip = {'startdate'};
            app.startdate_cp.Value = '1974q1';
            app.EstimationEndDateEditFieldLabel = uilabel(app.GridLayout6);
            app.EstimationEndDateEditFieldLabel.Text = 'Estimation End Date';
            app.enddate_cp = uieditfield(app.GridLayout6, 'text');
            app.enddate_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.enddate_cp.Tag = 'enddate';
            app.enddate_cp.Tooltip = {'enddate'};
            app.enddate_cp.Value = '2014q4';
            app.GridLayout7 = uigridlayout(app.GridLayout4);
            app.EnterthelistofendogeneousvariablesTextAreaLabel = uilabel(app.GridLayout7);
            app.EnterthelistofendogeneousvariablesTextAreaLabel.Text = 'Enter the list of endogeneous variables';
            app.varendo_cp = uitextarea(app.GridLayout7);
            app.varendo_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.varendo_cp.Tag = 'varendo';
            app.varendo_cp.Tooltip = {'varendo'};
            app.varendo_cp.Value = {'DOM_GDP DOM_CPI STN'};
            app.EnterthelistofexogeneousvariablesTextAreaLabel = uilabel(app.GridLayout7);
            app.EnterthelistofexogeneousvariablesTextAreaLabel.Text = 'Enter the list of exogeneous variables';
            app.varexo_cp = uitextarea(app.GridLayout7);
            app.varexo_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.varexo_cp.Tag = 'varexo';
            app.varexo_cp.Tooltip = {'varexo'};
            app.GridLayout8 = uigridlayout(app.GridLayout4);
            app.lags_cp = uieditfield(app.GridLayout8, 'numeric');
            app.lags_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.lags_cp.Tag = 'lags';
            app.lags_cp.Tooltip = {'lags'};
            app.lags_cp.Value = 4;
            app.LagsEditFieldLabel = uilabel(app.GridLayout8);
            app.LagsEditFieldLabel.Text = 'Lags';
            app.const_cp = uiswitch(app.GridLayout8, 'slider');
            app.const_cp.ItemsData = [0 1];
            app.const_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.const_cp.Tag = 'const';
            app.const_cp.Tooltip = {'const'};
            app.const_cp.Value = 1;
            app.IncludeconstantSwitchLabel = uilabel(app.GridLayout8);
            app.IncludeconstantSwitchLabel.Text = 'Include constant';
            app.GridLayout9 = uigridlayout(app.GridLayout4);
            app.results = uicheckbox(app.GridLayout9);
            app.results.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.results.Tag = 'results';
            app.results.Tooltip = {'results'};
            app.results.Text = 'Output in Excel';
            app.plot = uicheckbox(app.GridLayout9);
            app.plot.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.plot.Tag = 'plot';
            app.plot.Tooltip = {'plot'};
            app.plot.Text = 'Produce Figures';
            app.GridLayout18 = uigridlayout(app.GridLayout4);
            app.RUNButton = uibutton(app.GridLayout18, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Icon = 'BEAR.PNG';
            app.RUNButton.Text = 'RUN';
            app.QuickExportButton = uibutton(app.GridLayout18, 'push');
            app.QuickExportButton.ButtonPushedFcn = createCallbackFcn(app, @QuickExportButtonPushed, true);
            app.QuickExportButton.Tooltip = {'Export settings object into MATLAB workspace'};
            app.QuickExportButton.Text = 'Quick Export';
            app.GridLayout24 = uigridlayout(app.GridLayout4);
            app.results_path = uieditfield(app.GridLayout24, 'text');
            app.results_path.Tag = 'results_path';
            app.results_path.Editable = 'off';
            app.results_path.Tooltip = {'results_path'};
            app.ChangeresultsfolderButton = uibutton(app.GridLayout24, 'state');
            app.ChangeresultsfolderButton.ValueChangedFcn = createCallbackFcn(app, @ChangeresultsfolderButtonValueChanged, true);
            app.ChangeresultsfolderButton.Text = 'Change results folder';
            app.ChangeresultsfolderButton = uibutton(app.GridLayout24, 'state');
            app.ChangeresultsfolderButton.ValueChangedFcn = createCallbackFcn(app, @ChangeresultsfolderButtonValueChanged, true);
            app.ChangeresultsfolderButton.Text = 'Change results folder';
            app.ChangeresultsfolderButton = uibutton(app.GridLayout24, 'state');
            app.ChangeresultsfolderButton.ValueChangedFcn = createCallbackFcn(app, @ChangeresultsfolderButtonValueChanged, true);
            app.ChangeresultsfolderButton.Text = 'Change results folder';
            app.ResultsfilenameLabel = uilabel(app.GridLayout24);
            app.ResultsfilenameLabel.Text = 'Results file name:';
            app.ResultsfilenameLabel = uilabel(app.GridLayout24);
            app.ResultsfilenameLabel.Text = 'Results file name:';
            app.results_sub = uieditfield(app.GridLayout24, 'text');
            app.results_sub.ValueChangedFcn = createCallbackFcn(app, @results_subValueChanged, true);
            app.results_sub.Tag = 'results_sub';
            app.results_sub.Tooltip = {'results_sub'};
            app.results_sub.Value = 'results';
            app.results_sub = uieditfield(app.GridLayout24, 'text');
            app.results_sub.ValueChangedFcn = createCallbackFcn(app, @results_subValueChanged, true);
            app.results_sub.Tag = 'results_sub';
            app.results_sub.Tooltip = {'results_sub'};
            app.results_sub.Value = 'results';
            app.results_sub = uieditfield(app.GridLayout24, 'text');
            app.results_sub.ValueChangedFcn = createCallbackFcn(app, @results_subValueChanged, true);
            app.results_sub.Tag = 'results_sub';
            app.results_sub.Tooltip = {'results_sub'};
            app.results_sub.Value = 'results';
            app.results_path = uieditfield(app.GridLayout24, 'text');
            app.results_path.Tag = 'results_path';
            app.results_path.Editable = 'off';
            app.results_path.Tooltip = {'results_path'};
            app.results_path = uieditfield(app.GridLayout24, 'text');
            app.results_path.Tag = 'results_path';
            app.results_path.Editable = 'off';
            app.results_path.Tooltip = {'results_path'};
            app.GridLayout25 = uigridlayout(app.GridLayout4);
            app.SelectExcelfilewithinputsButton = uibutton(app.GridLayout25, 'push');
            app.SelectExcelfilewithinputsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExcelfilewithinputsButtonPushed, true);
            app.SelectExcelfilewithinputsButton.Text = 'Select Excel file with inputs';
            app.excelFile = uieditfield(app.GridLayout25, 'text');
            app.excelFile.Tag = 'excelFile';
            app.excelFile.Editable = 'off';
            app.excelFile.Tooltip = {'excelFile'};
            app.excelFile = uieditfield(app.GridLayout25, 'text');
            app.excelFile.Tag = 'excelFile';
            app.excelFile.Editable = 'off';
            app.excelFile.Tooltip = {'excelFile'};
            app.excelFile = uieditfield(app.GridLayout25, 'text');
            app.excelFile.Tag = 'excelFile';
            app.excelFile.Editable = 'off';
            app.excelFile.Tooltip = {'excelFile'};
            app.SelectExcelfilewithinputsButton = uibutton(app.GridLayout25, 'push');
            app.SelectExcelfilewithinputsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExcelfilewithinputsButtonPushed, true);
            app.SelectExcelfilewithinputsButton.Text = 'Select Excel file with inputs';
            app.SelectExcelfilewithinputsButton = uibutton(app.GridLayout25, 'push');
            app.SelectExcelfilewithinputsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExcelfilewithinputsButtonPushed, true);
            app.SelectExcelfilewithinputsButton.Text = 'Select Excel file with inputs';
            app.TabGroup = uitabgroup(app.GridLayout23);
            app.SPECIFICATIONTab = uitab(app.TabGroup);
            app.SPECIFICATIONTab.Title = 'SPECIFICATION';
            app.GridLayout2 = uigridlayout(app.SPECIFICATIONTab);
            app.GridLayout11 = uigridlayout(app.GridLayout2);
            app.SELECTVARTYPELabel = uilabel(app.GridLayout11);
            app.SELECTVARTYPELabel.Text = 'SELECT VAR TYPE';
            app.VARtypeTab = uitabgroup(app.GridLayout11);
            app.VARtypeTab.SelectionChangedFcn = createCallbackFcn(app, @VARtypeTabSelectionChanged, true);
            app.BayesianTab = uitab(app.VARtypeTab);
            app.BayesianTab.Title = 'Bayesian';
            app.BayesianGrid = uigridlayout(app.BayesianTab);
            app.Iterations = uipanel(app.BayesianGrid);
            app.Iterations.Title = 'Iterations';
            app.GridLayout18_4 = uigridlayout(app.Iterations);
            app.Bu_bvar = uieditfield(app.GridLayout18_4, 'numeric');
            app.Bu_bvar.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.Bu_bvar.Tag = 'Bu';
            app.Bu_bvar.Tooltip = {'Bu'};
            app.Bu_bvar.Value = 1000;
            app.It_bvar = uieditfield(app.GridLayout18_4, 'numeric');
            app.It_bvar.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.It_bvar.Tag = 'It';
            app.It_bvar.Tooltip = {'It'};
            app.It_bvar.Value = 2000;
            app.NumberofburniniterationsEditFieldLabel_4 = uilabel(app.GridLayout18_4);
            app.NumberofburniniterationsEditFieldLabel_4.Text = 'Number of burn-in iterations';
            app.TotalnumberofiterationsEditFieldLabel_4 = uilabel(app.GridLayout18_4);
            app.TotalnumberofiterationsEditFieldLabel_4.Text = 'Total number of iterations:';
            app.OptionsPanel = uipanel(app.BayesianGrid);
            app.OptionsPanel.Title = 'Options';
            app.GridLayout17_5 = uigridlayout(app.OptionsPanel);
            app.GridsearchonexcelSwitchLabel = uilabel(app.GridLayout17_5);
            app.GridsearchonexcelSwitchLabel.Text = 'Grid search (on excel)';
            app.hogs_bvar = uiswitch(app.GridLayout17_5, 'slider');
            app.hogs_bvar.Items = {'No', 'Yes'};
            app.hogs_bvar.ItemsData = [0 1];
            app.hogs_bvar.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.hogs_bvar.Tag = 'hogs';
            app.hogs_bvar.Tooltip = {'hogs'};
            app.hogs_bvar.Value = 0;
            app.BlockexogeneityonexcelSwitchLabel = uilabel(app.GridLayout17_5);
            app.BlockexogeneityonexcelSwitchLabel.Text = 'Block exogeneity (on excel)';
            app.bex_bvar = uiswitch(app.GridLayout17_5, 'slider');
            app.bex_bvar.Items = {'No', 'Yes'};
            app.bex_bvar.ItemsData = [0 1];
            app.bex_bvar.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.bex_bvar.Tag = 'bex';
            app.bex_bvar.Tooltip = {'bex'};
            app.bex_bvar.Value = 0;
            app.DummyobservationextensionsLabel = uilabel(app.GridLayout17_5);
            app.DummyobservationextensionsLabel.Text = 'Dummy observation extensions';
            app.scoeff_bvar = uicheckbox(app.GridLayout17_5);
            app.scoeff_bvar.ValueChangedFcn = createCallbackFcn(app, @scoeff_bvarValueChanged, true);
            app.scoeff_bvar.Tag = 'scoeff';
            app.scoeff_bvar.Tooltip = {'scoeff'};
            app.scoeff_bvar.Text = 'Sum of coefficients';
            app.iobs_bvar = uicheckbox(app.GridLayout17_5);
            app.iobs_bvar.ValueChangedFcn = createCallbackFcn(app, @iobs_bvarValueChanged, true);
            app.iobs_bvar.Tag = 'iobs';
            app.iobs_bvar.Tooltip = {'iobs'};
            app.iobs_bvar.Text = 'Dummy initial observations';
            app.lrp_bvar = uicheckbox(app.GridLayout17_5);
            app.lrp_bvar.ValueChangedFcn = createCallbackFcn(app, @lrp_bvarValueChanged, true);
            app.lrp_bvar.Tag = 'lrp';
            app.lrp_bvar.Tooltip = {'lrp'};
            app.lrp_bvar.Text = 'Long-run priors';
            app.HyperparametersPanel_3 = uipanel(app.BayesianGrid);
            app.HyperparametersPanel_3.Title = 'Hyperparameters';
            app.GridLayout17_6 = uigridlayout(app.HyperparametersPanel_3);
            app.lambda2_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.lambda2_bvar.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.lambda2_bvar.Tag = 'lambda2';
            app.lambda2_bvar.Tooltip = {'lambda2'};
            app.lambda2_bvar.Value = 0.5;
            app.lambda1_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.lambda1_bvar.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.lambda1_bvar.Tag = 'lambda1';
            app.lambda1_bvar.Tooltip = {'lambda1'};
            app.lambda1_bvar.Value = 0.1;
            app.ar_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.ar_bvar.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.ar_bvar.Tag = 'ar';
            app.ar_bvar.Tooltip = {'ar'};
            app.ar_bvar.Value = 1;
            app.ARcoefficientonresidualvarianceEditFieldLabel_6 = uilabel(app.GridLayout17_6);
            app.ARcoefficientonresidualvarianceEditFieldLabel_6.Text = 'Cross-variable weighting';
            app.IGscaleonresidualvarianceEditFieldLabel_6 = uilabel(app.GridLayout17_6);
            app.IGscaleonresidualvarianceEditFieldLabel_6.Text = 'Overall tightness';
            app.IGshapeonresidualvarianceEditFieldLabel_6 = uilabel(app.GridLayout17_6);
            app.IGshapeonresidualvarianceEditFieldLabel_6.Text = 'Autoregresive coefficient';
            app.LagdecayEditField_2Label = uilabel(app.GridLayout17_6);
            app.LagdecayEditField_2Label.Text = 'Lag decay';
            app.lambda3_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.lambda3_bvar.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.lambda3_bvar.Tag = 'lambda3';
            app.lambda3_bvar.Tooltip = {'lambda3'};
            app.lambda3_bvar.Value = 1;
            app.PriorExcel_bvar = uicheckbox(app.GridLayout17_6);
            app.PriorExcel_bvar.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.PriorExcel_bvar.Tag = 'PriorExcel';
            app.PriorExcel_bvar.Tooltip = {'PriorExcel'};
            app.PriorExcel_bvar.Text = 'Excel';
            app.ExogenousvariableEditFieldLabel = uilabel(app.GridLayout17_6);
            app.ExogenousvariableEditFieldLabel.Text = 'Exogenous variable';
            app.lambda4_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.lambda4_bvar.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.lambda4_bvar.Tag = 'lambda4';
            app.lambda4_bvar.Tooltip = {'lambda4'};
            app.lambda4_bvar.Value = 100;
            app.BlockexogenetyshrinkageEditFieldLabel = uilabel(app.GridLayout17_6);
            app.BlockexogenetyshrinkageEditFieldLabel.Text = 'Block exogenety shrinkage';
            app.lambda5_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.lambda5_bvar.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.lambda5_bvar.Tag = 'lambda5';
            app.lambda5_bvar.Enable = 'off';
            app.lambda5_bvar.Tooltip = {'lambda5'};
            app.lambda5_bvar.Value = 0.001;
            app.SumofcoefficienttightnessEditFieldLabel = uilabel(app.GridLayout17_6);
            app.SumofcoefficienttightnessEditFieldLabel.Text = 'Sum of coefficient tightness';
            app.lambda6_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.lambda6_bvar.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.lambda6_bvar.Tag = 'lambda6';
            app.lambda6_bvar.Enable = 'off';
            app.lambda6_bvar.Tooltip = {'lambda6'};
            app.lambda6_bvar.Value = 0.1;
            app.DummyinitialobservationtightnessLabel = uilabel(app.GridLayout17_6);
            app.DummyinitialobservationtightnessLabel.Text = 'Dummy initial observation tightness';
            app.lambda7_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.lambda7_bvar.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.lambda7_bvar.Tag = 'lambda7';
            app.lambda7_bvar.Enable = 'off';
            app.lambda7_bvar.Tooltip = {'lambda7'};
            app.lambda7_bvar.Value = 0.001;
            app.LongrunpriortightnessEditFieldLabel = uilabel(app.GridLayout17_6);
            app.LongrunpriortightnessEditFieldLabel.Text = 'Long-run prior tightness';
            app.lambda8_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.lambda8_bvar.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.lambda8_bvar.Tag = 'lambda8';
            app.lambda8_bvar.Enable = 'off';
            app.lambda8_bvar.Tooltip = {'lambda8'};
            app.lambda8_bvar.Value = 1;
            app.ExogenousvariablesSwitchLabel = uilabel(app.GridLayout17_6);
            app.ExogenousvariablesSwitchLabel.Text = 'Exogenous variables';
            app.priorsexogenous_bvar = uiswitch(app.GridLayout17_6, 'slider');
            app.priorsexogenous_bvar.Items = {'Excel', 'Default'};
            app.priorsexogenous_bvar.ItemsData = [1 0];
            app.priorsexogenous_bvar.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.priorsexogenous_bvar.Tag = 'priorsexogenous';
            app.priorsexogenous_bvar.Tooltip = {'priorsexogenous'};
            app.priorsexogenous_bvar.Value = 1;
            app.prior = uibuttongroup(app.BayesianGrid);
            app.prior.SelectionChangedFcn = createCallbackFcn(app, @priorSelectionChanged, true);
            app.prior.Title = 'Bayesian VARs Priors';
            app.MinnesotaButton = uiradiobutton(app.prior);
            app.MinnesotaButton.Tooltip = {'Minnesota'};
            app.MinnesotaButton.Text = 'Minnesota';
            app.NormalDiffuseButton = uiradiobutton(app.prior);
            app.NormalDiffuseButton.Tooltip = {'Normal Diffuse'};
            app.NormalDiffuseButton.Text = 'Normal Diffuse';
            app.DummyObservationsButton = uiradiobutton(app.prior);
            app.DummyObservationsButton.Tooltip = {'Dummy'};
            app.DummyObservationsButton.Text = 'Dummy Observations';
            app.NormalWishartButton = uiradiobutton(app.prior);
            app.NormalWishartButton.Tooltip = {'Nw_univariate'};
            app.NormalWishartButton.Text = 'Normal Wishart';
            app.IndependentNormalWishartButton = uiradiobutton(app.prior);
            app.IndependentNormalWishartButton.Tooltip = {'Inw_univariate'};
            app.IndependentNormalWishartButton.Text = 'Independent Normal Wishart';
            app.DeterministicButton = uiradiobutton(app.prior);
            app.DeterministicButton.Tooltip = {'Madj'};
            app.DeterministicButton.Text = 'Deterministic';
            app.DeterministicButton.Value = true;
            app.OLSTab = uitab(app.VARtypeTab);
            app.OLSTab.Title = 'OLS';
            app.PanelTab = uitab(app.VARtypeTab);
            app.PanelTab.Title = 'Panel';
            app.PanelGrid = uigridlayout(app.PanelTab);
            app.HyperparametersPanelpanel = uipanel(app.PanelGrid);
            app.HyperparametersPanelpanel.Title = 'Hyperparameters';
            app.GridLayout15 = uigridlayout(app.HyperparametersPanelpanel);
            app.v0_panel = uieditfield(app.GridLayout15, 'numeric');
            app.v0_panel.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.v0_panel.Tag = 'v0';
            app.v0_panel.Tooltip = {'v0'};
            app.v0_panel.Value = 0.001;
            app.s0_panel = uieditfield(app.GridLayout15, 'numeric');
            app.s0_panel.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.s0_panel.Tag = 's0';
            app.s0_panel.Tooltip = {'s0'};
            app.s0_panel.Value = 0.001;
            app.lambda4_panel = uieditfield(app.GridLayout15, 'numeric');
            app.lambda4_panel.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.lambda4_panel.Tag = 'lambda4';
            app.lambda4_panel.Tooltip = {'lambda4'};
            app.lambda4_panel.Value = 100;
            app.lambda3_panel = uieditfield(app.GridLayout15, 'numeric');
            app.lambda3_panel.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.lambda3_panel.Tag = 'lambda3';
            app.lambda3_panel.Tooltip = {'lambda3'};
            app.lambda3_panel.Value = 1;
            app.lambda2_panel = uieditfield(app.GridLayout15, 'numeric');
            app.lambda2_panel.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.lambda2_panel.Tag = 'lambda2';
            app.lambda2_panel.Tooltip = {'lambda2'};
            app.lambda2_panel.Value = 0.5;
            app.lambda1_panel = uieditfield(app.GridLayout15, 'numeric');
            app.lambda1_panel.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.lambda1_panel.Tag = 'lambda1';
            app.lambda1_panel.Tooltip = {'lambda1'};
            app.lambda1_panel.Value = 0.1;
            app.ar_panel = uieditfield(app.GridLayout15, 'numeric');
            app.ar_panel.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.ar_panel.Tag = 'ar';
            app.ar_panel.Tooltip = {'ar'};
            app.ar_panel.Value = 0.8;
            app.IGscaleonoveralltightnessEditFieldLabel = uilabel(app.GridLayout15);
            app.IGscaleonoveralltightnessEditFieldLabel.Text = 'IG scale on overall tightness';
            app.IGshapeonoveralltightnessEditFieldLabel = uilabel(app.GridLayout15);
            app.IGshapeonoveralltightnessEditFieldLabel.Text = 'IG shape on overall tightness';
            app.ExogenousvariabletightnessEditFieldLabel = uilabel(app.GridLayout15);
            app.ExogenousvariabletightnessEditFieldLabel.Text = 'Exogenous variable tightness';
            app.LagdecayEditFieldLabel = uilabel(app.GridLayout15);
            app.LagdecayEditFieldLabel.Text = 'Lag decay';
            app.CrossvariableweightingEditFieldLabel = uilabel(app.GridLayout15);
            app.CrossvariableweightingEditFieldLabel.Text = 'Cross-variable weighting';
            app.OveralltightnessEditFieldLabel = uilabel(app.GridLayout15);
            app.OveralltightnessEditFieldLabel.Text = 'Overall tightness';
            app.AutoregressivecoefficientEditFieldLabel = uilabel(app.GridLayout15);
            app.AutoregressivecoefficientEditFieldLabel.Text = 'Autoregressive coefficient';
            app.HyperparametersPanel = uipanel(app.PanelGrid);
            app.HyperparametersPanel.Title = 'Hyperparameters';
            app.GridLayout17 = uigridlayout(app.HyperparametersPanel);
            app.psi_panel = uieditfield(app.GridLayout17, 'numeric');
            app.psi_panel.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.psi_panel.Tag = 'psi';
            app.psi_panel.Tooltip = {'psi'};
            app.psi_panel.Value = 0.1;
            app.rho_panel = uieditfield(app.GridLayout17, 'numeric');
            app.rho_panel.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.rho_panel.Tag = 'rho';
            app.rho_panel.Tooltip = {'rho'};
            app.rho_panel.Value = 0.75;
            app.b0_panel = uieditfield(app.GridLayout17, 'numeric');
            app.b0_panel.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.b0_panel.Tag = 'b0';
            app.b0_panel.Tooltip = {'b0'};
            app.b0_panel.Value = 1;
            app.a0_panel = uieditfield(app.GridLayout17, 'numeric');
            app.a0_panel.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.a0_panel.Tag = 'a0';
            app.a0_panel.Tooltip = {'a0'};
            app.a0_panel.Value = 1000;
            app.gama_panel = uieditfield(app.GridLayout17, 'numeric');
            app.gama_panel.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.gama_panel.Tag = 'gama';
            app.gama_panel.Tooltip = {'gama'};
            app.gama_panel.Value = 0.85;
            app.delta0_panel = uieditfield(app.GridLayout17, 'numeric');
            app.delta0_panel.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.delta0_panel.Tag = 'delta0';
            app.delta0_panel.Tooltip = {'delta0'};
            app.delta0_panel.Value = 1;
            app.alpha0_panel = uieditfield(app.GridLayout17, 'numeric');
            app.alpha0_panel.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.alpha0_panel.Tag = 'alpha0';
            app.alpha0_panel.Tooltip = {'alpha0'};
            app.alpha0_panel.Value = 1000;
            app.VarianceonMetropolisdrawEditFieldLabel = uilabel(app.GridLayout17);
            app.VarianceonMetropolisdrawEditFieldLabel.Tooltip = {'psi'};
            app.VarianceonMetropolisdrawEditFieldLabel.Text = 'Variance on Metropolis draw';
            app.ARcoefficientonfactorEditFieldLabel = uilabel(app.GridLayout17);
            app.ARcoefficientonfactorEditFieldLabel.Tooltip = {'rho'};
            app.ARcoefficientonfactorEditFieldLabel.Text = 'AR coefficient on factor';
            app.IGscaleonfactorvarianceEditFieldLabel = uilabel(app.GridLayout17);
            app.IGscaleonfactorvarianceEditFieldLabel.Tooltip = {'b0'};
            app.IGscaleonfactorvarianceEditFieldLabel.Text = 'IG scale on factor variance';
            app.IGshapeonfactorvarianceEditFieldLabel = uilabel(app.GridLayout17);
            app.IGshapeonfactorvarianceEditFieldLabel.Tooltip = {'a0'};
            app.IGshapeonfactorvarianceEditFieldLabel.Text = 'IG shape on factor variance';
            app.ARcoefficientonresidualvarianceEditFieldLabel = uilabel(app.GridLayout17);
            app.ARcoefficientonresidualvarianceEditFieldLabel.Tooltip = {'gama'};
            app.ARcoefficientonresidualvarianceEditFieldLabel.Text = 'AR coefficient on residual variance';
            app.IGscaleonresidualvarianceEditFieldLabel = uilabel(app.GridLayout17);
            app.IGscaleonresidualvarianceEditFieldLabel.Tooltip = {'delta0'};
            app.IGscaleonresidualvarianceEditFieldLabel.Text = 'IG scale on residual variance';
            app.IGshapeonresidualvarianceEditFieldLabel = uilabel(app.GridLayout17);
            app.IGshapeonresidualvarianceEditFieldLabel.Tooltip = {'alpha0'};
            app.IGshapeonresidualvarianceEditFieldLabel.Text = 'IG shape on residual variance';
            app.EnterlistofunitsPanel = uipanel(app.PanelGrid);
            app.EnterlistofunitsPanel.Title = 'Enter list of units';
            app.GridLayout16 = uigridlayout(app.EnterlistofunitsPanel);
            app.unitnames_panel = uieditfield(app.GridLayout16, 'text');
            app.unitnames_panel.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.unitnames_panel.Tag = 'unitnames';
            app.unitnames_panel.Tooltip = {'unitnames'};
            app.Iterations_2 = uipanel(app.PanelGrid);
            app.Iterations_2.Title = 'Iterations';
            app.GridLayout18_5 = uigridlayout(app.Iterations_2);
            app.Bu_panel = uieditfield(app.GridLayout18_5, 'numeric');
            app.Bu_panel.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.Bu_panel.Tag = 'Bu';
            app.Bu_panel.Tooltip = {'Bu'};
            app.Bu_panel.Value = 1000;
            app.It_panel = uieditfield(app.GridLayout18_5, 'numeric');
            app.It_panel.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.It_panel.Tag = 'It';
            app.It_panel.Tooltip = {'It'};
            app.It_panel.Value = 2000;
            app.NumberofburniniterationsEditFieldLabel_5 = uilabel(app.GridLayout18_5);
            app.NumberofburniniterationsEditFieldLabel_5.Text = 'Number of burn-in iterations';
            app.TotalnumberofiterationsEditFieldLabel_5 = uilabel(app.GridLayout18_5);
            app.TotalnumberofiterationsEditFieldLabel_5.Text = 'Total number of iterations';
            app.panel = uibuttongroup(app.PanelGrid);
            app.panel.SelectionChangedFcn = createCallbackFcn(app, @panelSelectionChanged, true);
            app.panel.Title = 'Bayesian Panel VARs Priors';
            app.MeangroupEstimatorButton = uiradiobutton(app.panel);
            app.MeangroupEstimatorButton.Text = 'Mean group Estimator';
            app.MeangroupEstimatorButton.Value = true;
            app.RandomEffectZellnerHongButton = uiradiobutton(app.panel);
            app.RandomEffectZellnerHongButton.Text = 'Random Effect (Zellner-Hong)';
            app.StaticStructureFactorButton = uiradiobutton(app.panel);
            app.StaticStructureFactorButton.Text = 'Static Structure Factor';
            app.PooledEstimatorButton = uiradiobutton(app.panel);
            app.PooledEstimatorButton.Text = 'Pooled Estimator';
            app.RandomEffectHierarchicalButton = uiradiobutton(app.panel);
            app.RandomEffectHierarchicalButton.Text = 'Random Effect (Hierarchical)';
            app.DynamicStructureFactorButton = uiradiobutton(app.panel);
            app.DynamicStructureFactorButton.Text = 'Dynamic Structure Factor';
            app.StochasticVolatilityTab = uitab(app.VARtypeTab);
            app.StochasticVolatilityTab.Title = 'Stochastic Volatility';
            app.SVGrid = uigridlayout(app.StochasticVolatilityTab);
            app.HyperparametersstochasticPanel = uipanel(app.SVGrid);
            app.HyperparametersstochasticPanel.Title = 'Hyperparameters stochastic';
            app.GridLayout17_3 = uigridlayout(app.HyperparametersstochasticPanel);
            app.zeta0_sv = uieditfield(app.GridLayout17_3, 'numeric');
            app.zeta0_sv.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.zeta0_sv.Tag = 'zeta0';
            app.zeta0_sv.Tooltip = {'zeta0'};
            app.zeta0_sv.Value = 10000;
            app.gamma0_sv = uieditfield(app.GridLayout17_3, 'numeric');
            app.gamma0_sv.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.gamma0_sv.Tag = 'gamma0';
            app.gamma0_sv.Tooltip = {'gamma0'};
            app.delta0_sv = uieditfield(app.GridLayout17_3, 'numeric');
            app.delta0_sv.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.delta0_sv.Tag = 'delta0';
            app.delta0_sv.Tooltip = {'delta0'};
            app.delta0_sv.Value = 0.001;
            app.alpha0_sv = uieditfield(app.GridLayout17_3, 'numeric');
            app.alpha0_sv.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.alpha0_sv.Tag = 'alpha0';
            app.alpha0_sv.Tooltip = {'alpha0'};
            app.alpha0_sv.Value = 0.001;
            app.gamma_sv = uieditfield(app.GridLayout17_3, 'numeric');
            app.gamma_sv.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.gamma_sv.Tag = 'gamma';
            app.gamma_sv.Tooltip = {'gamma'};
            app.gamma_sv.Value = 1;
            app.IGscaleonfactorvarianceEditFieldLabel_3 = uilabel(app.GridLayout17_3);
            app.IGscaleonfactorvarianceEditFieldLabel_3.Tooltip = {'zeta0'};
            app.IGscaleonfactorvarianceEditFieldLabel_3.Text = 'Prior variance on inertia';
            app.IGshapeonfactorvarianceEditFieldLabel_3 = uilabel(app.GridLayout17_3);
            app.IGshapeonfactorvarianceEditFieldLabel_3.Tooltip = {'gamma0'};
            app.IGshapeonfactorvarianceEditFieldLabel_3.Text = 'Prior mean on inertia';
            app.ARcoefficientonresidualvarianceEditFieldLabel_3 = uilabel(app.GridLayout17_3);
            app.ARcoefficientonresidualvarianceEditFieldLabel_3.Tooltip = {'delta0'};
            app.ARcoefficientonresidualvarianceEditFieldLabel_3.Text = 'IG scale on residual variance';
            app.IGscaleonresidualvarianceEditFieldLabel_3 = uilabel(app.GridLayout17_3);
            app.IGscaleonresidualvarianceEditFieldLabel_3.Tooltip = {'alpha0'};
            app.IGscaleonresidualvarianceEditFieldLabel_3.Text = 'IG shape on residual variance';
            app.IGshapeonresidualvarianceEditFieldLabel_3 = uilabel(app.GridLayout17_3);
            app.IGshapeonresidualvarianceEditFieldLabel_3.Text = 'AR coefficient on residual variance';
            app.alltirf_sv = uicheckbox(app.GridLayout17_3);
            app.alltirf_sv.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.alltirf_sv.Tag = 'alltirf';
            app.alltirf_sv.Tooltip = {'alltirf'};
            app.alltirf_sv.Text = 'Time-varying IRFs';
            app.alltirf_sv.Value = true;
            app.stvol = uibuttongroup(app.SVGrid);
            app.stvol.SelectionChangedFcn = createCallbackFcn(app, @stvolSelectionChanged, true);
            app.stvol.Title = 'Stochastic Volatility';
            app.StandardButton = uiradiobutton(app.stvol);
            app.StandardButton.Text = 'Standard';
            app.RandomInertiaButton = uiradiobutton(app.stvol);
            app.RandomInertiaButton.Text = 'Random Inertia';
            app.LargeVARButton = uiradiobutton(app.stvol);
            app.LargeVARButton.Text = 'Large VAR';
            app.StochasticButton = uiradiobutton(app.stvol);
            app.StochasticButton.Text = 'Stochastic';
            app.StochasticButton.Value = true;
            app.Iterations_3 = uipanel(app.SVGrid);
            app.Iterations_3.Title = 'Iterations';
            app.GridLayout18_6 = uigridlayout(app.Iterations_3);
            app.Bu_sv = uieditfield(app.GridLayout18_6, 'numeric');
            app.Bu_sv.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.Bu_sv.Tag = 'Bu';
            app.Bu_sv.Tooltip = {'Bu'};
            app.Bu_sv.Value = 1000;
            app.It_sv = uieditfield(app.GridLayout18_6, 'numeric');
            app.It_sv.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.It_sv.Tag = 'It';
            app.It_sv.Tooltip = {'It'};
            app.It_sv.Value = 2000;
            app.NumberofburniniterationsEditFieldLabel_6 = uilabel(app.GridLayout18_6);
            app.NumberofburniniterationsEditFieldLabel_6.Text = 'Number of burn-in iterations:';
            app.TotalnumberofiterationsEditFieldLabel_6 = uilabel(app.GridLayout18_6);
            app.TotalnumberofiterationsEditFieldLabel_6.Text = 'Total number of iterations:';
            app.HyperparametersPanel_4 = uipanel(app.SVGrid);
            app.HyperparametersPanel_4.Title = 'Hyperparameters';
            app.GridLayout17_7 = uigridlayout(app.HyperparametersPanel_4);
            app.lambda2_sv = uieditfield(app.GridLayout17_7, 'numeric');
            app.lambda2_sv.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.lambda2_sv.Tag = 'lambda2';
            app.lambda2_sv.Tooltip = {'lambda2'};
            app.lambda2_sv.Value = 0.5;
            app.lambda1_sv = uieditfield(app.GridLayout17_7, 'numeric');
            app.lambda1_sv.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.lambda1_sv.Tag = 'lambda1';
            app.lambda1_sv.Tooltip = {'lambda1'};
            app.lambda1_sv.Value = 0.1;
            app.ar_sv = uieditfield(app.GridLayout17_7, 'numeric');
            app.ar_sv.Tag = 'ar';
            app.ar_sv.Tooltip = {'ar'};
            app.ar_sv.Value = 1;
            app.ARcoefficientonresidualvarianceEditFieldLabel_7 = uilabel(app.GridLayout17_7);
            app.ARcoefficientonresidualvarianceEditFieldLabel_7.Text = 'Cross-variable weighting';
            app.IGscaleonresidualvarianceEditFieldLabel_7 = uilabel(app.GridLayout17_7);
            app.IGscaleonresidualvarianceEditFieldLabel_7.Text = 'Overall tightness';
            app.IGshapeonresidualvarianceEditFieldLabel_7 = uilabel(app.GridLayout17_7);
            app.IGshapeonresidualvarianceEditFieldLabel_7.Text = 'Autoregresive coefficient';
            app.LagdecayEditField_2Label_2 = uilabel(app.GridLayout17_7);
            app.LagdecayEditField_2Label_2.Text = 'Lag decay';
            app.lambda3_sv = uieditfield(app.GridLayout17_7, 'numeric');
            app.lambda3_sv.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.lambda3_sv.Tag = 'lambda3';
            app.lambda3_sv.Tooltip = {'lambda3'};
            app.lambda3_sv.Value = 1;
            app.PriorExcel_sv = uicheckbox(app.GridLayout17_7);
            app.PriorExcel_sv.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.PriorExcel_sv.Tag = 'PriorExcel';
            app.PriorExcel_sv.Tooltip = {'PriorExcel'};
            app.PriorExcel_sv.Text = 'Excel';
            app.ExogenousvariableEditFieldLabel_2 = uilabel(app.GridLayout17_7);
            app.ExogenousvariableEditFieldLabel_2.Text = 'Exogenous variable';
            app.lambda4_sv = uieditfield(app.GridLayout17_7, 'numeric');
            app.lambda4_sv.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.lambda4_sv.Tag = 'lambda4';
            app.lambda4_sv.Tooltip = {'lambda4'};
            app.lambda4_sv.Value = 100;
            app.BlockexogenetyshrinkageEditFieldLabel_2 = uilabel(app.GridLayout17_7);
            app.BlockexogenetyshrinkageEditFieldLabel_2.Text = 'Block exogenety shrinkage';
            app.lambda5_sv = uieditfield(app.GridLayout17_7, 'numeric');
            app.lambda5_sv.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.lambda5_sv.Tag = 'lambda5';
            app.lambda5_sv.Enable = 'off';
            app.lambda5_sv.Tooltip = {'lambda5'};
            app.lambda5_sv.Value = 0.001;
            app.SumofcoefficienttightnessEditFieldLabel_2 = uilabel(app.GridLayout17_7);
            app.SumofcoefficienttightnessEditFieldLabel_2.Text = 'Sum of coefficient tightness';
            app.lambda6_sv = uieditfield(app.GridLayout17_7, 'numeric');
            app.lambda6_sv.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.lambda6_sv.Tag = 'lambda6';
            app.lambda6_sv.Enable = 'off';
            app.lambda6_sv.Tooltip = {'lambda6'};
            app.lambda6_sv.Value = 0.1;
            app.DummyinitialobservationtightnessLabel_2 = uilabel(app.GridLayout17_7);
            app.DummyinitialobservationtightnessLabel_2.Text = 'Dummy initial observation tightness';
            app.lambda7_sv = uieditfield(app.GridLayout17_7, 'numeric');
            app.lambda7_sv.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.lambda7_sv.Tag = 'lambda7';
            app.lambda7_sv.Enable = 'off';
            app.lambda7_sv.Tooltip = {'lambda7'};
            app.lambda7_sv.Value = 0.001;
            app.LongrunpriortightnessEditFieldLabel_2 = uilabel(app.GridLayout17_7);
            app.LongrunpriortightnessEditFieldLabel_2.Text = 'Long-run prior tightness';
            app.lambda8_sv = uieditfield(app.GridLayout17_7, 'numeric');
            app.lambda8_sv.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.lambda8_sv.Tag = 'lambda8';
            app.lambda8_sv.Enable = 'off';
            app.lambda8_sv.Tooltip = {'lambda8'};
            app.lambda8_sv.Value = 1;
            app.ExogenousvariablesSwitchLabel_2 = uilabel(app.GridLayout17_7);
            app.ExogenousvariablesSwitchLabel_2.Text = 'Exogenous variables';
            app.priorsexogenous_sv = uiswitch(app.GridLayout17_7, 'slider');
            app.priorsexogenous_sv.Items = {'Excel', 'Default'};
            app.priorsexogenous_sv.ItemsData = [1 0];
            app.priorsexogenous_sv.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.priorsexogenous_sv.Tag = 'priorsexogenous';
            app.priorsexogenous_sv.Tooltip = {'priorsexogenous'};
            app.priorsexogenous_sv.Value = 0;
            app.TimeVaryingTab = uitab(app.VARtypeTab);
            app.TimeVaryingTab.Title = 'Time Varying';
            app.TVPGrid = uigridlayout(app.TimeVaryingTab);
            app.HyperparameterstimevaryingPanel = uipanel(app.TVPGrid);
            app.HyperparameterstimevaryingPanel.Title = 'Hyperparameters time varying';
            app.GridLayout17_2 = uigridlayout(app.HyperparameterstimevaryingPanel);
            app.delta0_tvp = uieditfield(app.GridLayout17_2, 'numeric');
            app.delta0_tvp.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.delta0_tvp.Tag = 'delta0';
            app.delta0_tvp.Tooltip = {'delta0'};
            app.delta0_tvp.Value = 0.001;
            app.alpha0_tvp = uieditfield(app.GridLayout17_2, 'numeric');
            app.alpha0_tvp.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.alpha0_tvp.Tag = 'alpha0';
            app.alpha0_tvp.Tooltip = {'alpha0'};
            app.alpha0_tvp.Value = 0.001;
            app.gamma_tvp = uieditfield(app.GridLayout17_2, 'numeric');
            app.gamma_tvp.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.gamma_tvp.Tag = 'gamma';
            app.gamma_tvp.Tooltip = {'gamma'};
            app.gamma_tvp.Value = 0.85;
            app.ARcoefficientonresidualvarianceEditFieldLabel_2 = uilabel(app.GridLayout17_2);
            app.ARcoefficientonresidualvarianceEditFieldLabel_2.Text = 'IG scale on residual variance';
            app.IGscaleonresidualvarianceEditFieldLabel_2 = uilabel(app.GridLayout17_2);
            app.IGscaleonresidualvarianceEditFieldLabel_2.Text = 'IG shape on residual variance';
            app.IGshapeonresidualvarianceEditFieldLabel_2 = uilabel(app.GridLayout17_2);
            app.IGshapeonresidualvarianceEditFieldLabel_2.Text = 'AR coefficient on residual variance';
            app.alltirf_tvp = uicheckbox(app.GridLayout17_2);
            app.alltirf_tvp.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.alltirf_tvp.Tag = 'alltirf';
            app.alltirf_tvp.Tooltip = {'alltirf'};
            app.alltirf_tvp.Text = 'Time-varying IRFs';
            app.alltirf_tvp.Value = true;
            app.tvbvar = uibuttongroup(app.TVPGrid);
            app.tvbvar.SelectionChangedFcn = createCallbackFcn(app, @tvbvarSelectionChanged, true);
            app.tvbvar.Title = 'Time-varying';
            app.VARcoefficientsButton = uiradiobutton(app.tvbvar);
            app.VARcoefficientsButton.Text = 'VAR coefficients';
            app.GeneralButton = uiradiobutton(app.tvbvar);
            app.GeneralButton.Text = 'General';
            app.GeneralButton.Value = true;
            app.Iterations_4 = uipanel(app.TVPGrid);
            app.Iterations_4.Title = 'Iterations';
            app.GridLayout18_7 = uigridlayout(app.Iterations_4);
            app.Bu_tvp = uieditfield(app.GridLayout18_7, 'numeric');
            app.Bu_tvp.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.Bu_tvp.Tag = 'Bu';
            app.Bu_tvp.Tooltip = {'Bu'};
            app.Bu_tvp.Value = 1000;
            app.It_tvp = uieditfield(app.GridLayout18_7, 'numeric');
            app.It_tvp.ValueChangedFcn = createCallbackFcn(app, @setProp, true);
            app.It_tvp.Tag = 'It';
            app.It_tvp.Tooltip = {'It'};
            app.It_tvp.Value = 2000;
            app.NumberofburniniterationsEditFieldLabel_7 = uilabel(app.GridLayout18_7);
            app.NumberofburniniterationsEditFieldLabel_7.Text = 'Number of burn-in iterations';
            app.TotalnumberofiterationsEditFieldLabel_7 = uilabel(app.GridLayout18_7);
            app.TotalnumberofiterationsEditFieldLabel_7.Text = 'Total number of iterations:';
            app.MixedFrequencyTab = uitab(app.VARtypeTab);
            app.MixedFrequencyTab.Title = 'Mixed Frequency';
            app.APPLICATIONSTab = uitab(app.TabGroup);
            app.APPLICATIONSTab.Title = 'APPLICATIONS';
            app.GridLayout14 = uigridlayout(app.APPLICATIONSTab);
            app.Applicationoptions = uipanel(app.GridLayout14);
            app.Applicationoptions.Title = 'Application options';
            app.GridLayout22 = uigridlayout(app.Applicationoptions);
            app.favarHDplot = uicheckbox(app.GridLayout22);
            app.favarHDplot.ValueChangedFcn = createCallbackFcn(app, @favarHDplotValueChanged, true);
            app.favarHDplot.Text = '';
            app.favarFEVDplot = uicheckbox(app.GridLayout22);
            app.favarFEVDplot.ValueChangedFcn = createCallbackFcn(app, @favarFEVDplotValueChanged, true);
            app.favarFEVDplot.Text = '';
            app.favarIRFplot = uicheckbox(app.GridLayout22);
            app.favarIRFplot.ValueChangedFcn = createCallbackFcn(app, @favarIRFplotValueChanged, true);
            app.favarIRFplot.Tag = 'favar.';
            app.favarIRFplot.Text = '';
            app.FEVD_cp = uiswitch(app.GridLayout22, 'slider');
            app.FEVD_cp.Items = {'No', 'Yes'};
            app.FEVD_cp.ItemsData = [0 1];
            app.FEVD_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.FEVD_cp.Tag = 'FEVD';
            app.FEVD_cp.Tooltip = {'FEVD'};
            app.FEVD_cp.Value = 1;
            app.ForecasterrorvarianceSwitchLabel = uilabel(app.GridLayout22);
            app.ForecasterrorvarianceSwitchLabel.Text = 'Forecast error variance: ';
            app.CF_cp = uiswitch(app.GridLayout22, 'slider');
            app.CF_cp.Items = {'No', 'Yes'};
            app.CF_cp.ItemsData = [0 1];
            app.CF_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.CF_cp.Tag = 'CF';
            app.CF_cp.Tooltip = {'CF'};
            app.CF_cp.Value = 0;
            app.ConditionalforecastsSwitchLabel = uilabel(app.GridLayout22);
            app.ConditionalforecastsSwitchLabel.Text = ' Conditional forecasts:         ';
            app.HD_cp = uiswitch(app.GridLayout22, 'slider');
            app.HD_cp.Items = {'No', 'Yes'};
            app.HD_cp.ItemsData = [0 1];
            app.HD_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.HD_cp.Tag = 'HD';
            app.HD_cp.Tooltip = {'HD'};
            app.HD_cp.Value = 1;
            app.HistoricaldecompositionsSwitchLabel = uilabel(app.GridLayout22);
            app.HistoricaldecompositionsSwitchLabel.Text = ' Historical decompositions:';
            app.F_cp = uiswitch(app.GridLayout22, 'slider');
            app.F_cp.Items = {'No', 'Yes'};
            app.F_cp.ItemsData = [0 1];
            app.F_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.F_cp.Tag = 'F';
            app.F_cp.Tooltip = {'F'};
            app.F_cp.Value = 1;
            app.UnconditionalforecastsSwitchLabel = uilabel(app.GridLayout22);
            app.UnconditionalforecastsSwitchLabel.Text = 'Unconditional forecasts:';
            app.IRF_cp = uiswitch(app.GridLayout22, 'slider');
            app.IRF_cp.Items = {'No', 'Yes'};
            app.IRF_cp.ItemsData = [0 1];
            app.IRF_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.IRF_cp.Tag = 'IRF';
            app.IRF_cp.Tooltip = {'IRF'};
            app.IRF_cp.Value = 1;
            app.ImpulseresponsefunctionsSwitchLabel = uilabel(app.GridLayout22);
            app.ImpulseresponsefunctionsSwitchLabel.Text = 'Impulse response functions:';
            app.FAVARLabel = uilabel(app.GridLayout22);
            app.FAVARLabel.Text = 'FAVAR';
            app.Periodoptions = uipanel(app.GridLayout14);
            app.Periodoptions.Title = 'Period options';
            app.GridLayout21 = uigridlayout(app.Periodoptions);
            app.cband_cp = uieditfield(app.GridLayout21, 'numeric');
            app.cband_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.cband_cp.Tag = 'cband';
            app.cband_cp.Tooltip = {'cband'};
            app.cband_cp.Value = 0.67;
            app.VARcoefficientsEditFieldLabel = uilabel(app.GridLayout21);
            app.VARcoefficientsEditFieldLabel.Text = 'Credibility Intervals:';
            app.Fstartdate_cp = uieditfield(app.GridLayout21, 'text');
            app.Fstartdate_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.Fstartdate_cp.Tag = 'Fstartdate';
            app.Fstartdate_cp.Tooltip = {'Fstartdate'};
            app.Fstartdate_cp.Value = '2014q1';
            app.ForecastsStartdateEditFieldLabel = uilabel(app.GridLayout21);
            app.ForecastsStartdateEditFieldLabel.Text = 'Forecasts: Start date:';
            app.Fendsmpl_cp = uiswitch(app.GridLayout21, 'slider');
            app.Fendsmpl_cp.Items = {'No', 'Yes'};
            app.Fendsmpl_cp.ItemsData = [0 1];
            app.Fendsmpl_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.Fendsmpl_cp.Tag = 'Fendsmpl';
            app.Fendsmpl_cp.Tooltip = {'Fendsmpl'};
            app.Fendsmpl_cp.Value = 0;
            app.ForecastsafterlastsampleperiodSwitchLabel = uilabel(app.GridLayout21);
            app.ForecastsafterlastsampleperiodSwitchLabel.Text = 'Forecasts after last sample period:';
            app.IRFperiods_cp = uieditfield(app.GridLayout21, 'numeric');
            app.IRFperiods_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.IRFperiods_cp.Tag = 'IRFperiods';
            app.IRFperiods_cp.Tooltip = {'IRFperiods'};
            app.IRFperiods_cp.Value = 20;
            app.IRFperiodsEditFieldLabel = uilabel(app.GridLayout21);
            app.IRFperiodsEditFieldLabel.Text = 'IRF periods:';
            app.Fenddate_cp = uieditfield(app.GridLayout21, 'text');
            app.Fenddate_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.Fenddate_cp.Tag = 'Fenddate';
            app.Fenddate_cp.Tooltip = {'Fenddate'};
            app.Fenddate_cp.Value = '2016q4';
            app.ForecastsEnddateEditFieldLabel = uilabel(app.GridLayout21);
            app.ForecastsEnddateEditFieldLabel.Text = 'Forecasts: End date:';
            app.CFt = uibuttongroup(app.GridLayout14);
            app.CFt.SelectionChangedFcn = createCallbackFcn(app, @CFtSelectionChanged, true);
            app.CFt.Title = 'Types of conditional forecasts';
            app.Standardallshocks = uiradiobutton(app.CFt);
            app.Standardallshocks.Text = 'Standard (all shocks)';
            app.Standardallshocks.Value = true;
            app.Standardshockspecific = uiradiobutton(app.CFt);
            app.Standardshockspecific.Text = 'Standard (shock specific)';
            app.Tiltingmedian = uiradiobutton(app.CFt);
            app.Tiltingmedian.Text = 'Titling (median)';
            app.Tiltinginterval = uiradiobutton(app.CFt);
            app.Tiltinginterval.Text = 'Titling (interval)';
            app.Estimationoptions = uipanel(app.GridLayout14);
            app.Estimationoptions.Title = 'Estimation options:';
            app.GridLayout19 = uigridlayout(app.Estimationoptions);
            app.evaluation_size_cp = uieditfield(app.GridLayout19, 'numeric');
            app.evaluation_size_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.evaluation_size_cp.Tag = 'evaluation_size';
            app.evaluation_size_cp.Tooltip = {'evaluation_size'};
            app.evaluation_size_cp.Value = 0.5;
            app.EvaluationSizeEditFieldLabel = uilabel(app.GridLayout19);
            app.EvaluationSizeEditFieldLabel.Text = 'Evaluation Size:';
            app.window_size_cp = uieditfield(app.GridLayout19, 'numeric');
            app.window_size_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.window_size_cp.Tag = 'window_size';

            app.window_size_cp.Tooltip = {'window_size'};
            app.RollingWindow0forfullsampleEditFieldLabel = uilabel(app.GridLayout19);
            app.RollingWindow0forfullsampleEditFieldLabel.Text = 'Rolling Window (0 for full sample):';
            app.hstep_cp = uieditfield(app.GridLayout19, 'numeric');
            app.hstep_cp.ValueChangedFcn = createCallbackFcn(app, @setCommonProp, true);
            app.hstep_cp.Tag = 'hstep';
            app.hstep_cp.Tooltip = {'hstep'};
            app.hstep_cp.Value = 1;
            app.ForecaststepaheadevaluationsEditFieldLabel = uilabel(app.GridLayout19);
            app.ForecaststepaheadevaluationsEditFieldLabel.Text = 'Forecast step ahead evaluations:';
            app.Feval_cp = uiswitch(app.GridLayout19, 'slider');
            app.Feval_cp.Items = {'No', 'Yes'};
            app.Feval_cp.ItemsData = [0 1];
            app.Feval_cp.Tag = 'Feval';
            app.Feval_cp.Tooltip = {'Feval'};
            app.Feval_cp.Value = 1;
            app.ForecastevaluationsSwitchLabel = uilabel(app.GridLayout19);
            app.ForecastevaluationsSwitchLabel.Text = 'Forecast evaluations:';
            app.ProxyVars = uipanel(app.GridLayout14);
            app.ProxyVars.Title = 'Proxy SVAR options';
            app.GridLayout20 = uigridlayout(app.ProxyVars);
            app.CorrelInstrument = uieditfield(app.GridLayout20, 'text');
            app.CorrelInstrument.ValueChangedFcn = createCallbackFcn(app, @StructidentValueChanged, true);
            app.CorrelInstrument.Tag = 'CorrelInstrument';
            app.CorrelInstrument.Tooltip = {'CorrelInstrument'};
            app.CorrelInstrumentLabel = uilabel(app.GridLayout20);
            app.CorrelInstrumentLabel.Text = 'Correl Instrument';
            app.CorrelShock = uieditfield(app.GridLayout20, 'text');
            app.CorrelShock.ValueChangedFcn = createCallbackFcn(app, @StructidentValueChanged, true);
            app.CorrelShock.Tag = 'CorrelShock';
            app.CorrelShock.Tooltip = {'CorrelShock'};
            app.CorrelShockLabel = uilabel(app.GridLayout20);
            app.CorrelShockLabel.Text = 'Correl Shock';
            app.prior_type_proxy = uiswitch(app.GridLayout20, 'slider');
            app.prior_type_proxy.Items = {'No', 'Yes'};
            app.prior_type_proxy.ItemsData = [0 1];
            app.prior_type_proxy.ValueChangedFcn = createCallbackFcn(app, @StructidentValueChanged, true);
            app.prior_type_proxy.Tag = 'prior_type_proxy';
            app.prior_type_proxy.Tooltip = {'prior_type_proxy'};
            app.prior_type_proxy.Value = 1;
            app.HighrelevancepriorLabel = uilabel(app.GridLayout20);
            app.HighrelevancepriorLabel.Text = 'High relevance prior';
            app.prior_type_reduced_form = uiswitch(app.GridLayout20, 'slider');
            app.prior_type_reduced_form.Items = {'No', 'Yes'};
            app.prior_type_reduced_form.ItemsData = [0 1];
            app.prior_type_reduced_form.ValueChangedFcn = createCallbackFcn(app, @StructidentValueChanged, true);
            app.prior_type_reduced_form.Tag = 'prior_type_reduced_form';
            app.prior_type_reduced_form.Tooltip = {'prior_type_reduced_form'};
            app.prior_type_reduced_form.Value = 1;
            app.FlatreducedformpriorLabel = uilabel(app.GridLayout20);
            app.FlatreducedformpriorLabel.Text = 'Flat reduced form prior:';
            app.Instrument = uieditfield(app.GridLayout20, 'text');
            app.Instrument.ValueChangedFcn = createCallbackFcn(app, @StructidentValueChanged, true);
            app.Instrument.Tag = 'Instrument';
            app.Instrument.Tooltip = {'Instrument'};
            app.enddateIV = uieditfield(app.GridLayout20, 'text');
            app.enddateIV.ValueChangedFcn = createCallbackFcn(app, @StructidentValueChanged, true);
            app.enddateIV.Tag = 'enddateIV';
            app.enddateIV.Tooltip = {'enddateIV'};
            app.InstrumentEnddateLabel = uilabel(app.GridLayout20);
            app.InstrumentEnddateLabel.Text = 'Instrument: End date:';
            app.startdateIV = uieditfield(app.GridLayout20, 'text');
            app.startdateIV.ValueChangedFcn = createCallbackFcn(app, @StructidentValueChanged, true);
            app.startdateIV.Tag = 'startdateIV';
            app.startdateIV.Tooltip = {'startdateIV'};
            app.InstrumentstartdateLabel = uilabel(app.GridLayout20);
            app.InstrumentstartdateLabel.Text = 'Instrument: Start date:';
            app.InstrumentLabel = uilabel(app.GridLayout20);
            app.InstrumentLabel.Text = 'Instrument:';
            app.FAVARplotPanel = uipanel(app.GridLayout14);
            app.FAVARplotPanel.Title = 'FAVAR plot';
            app.PlotXLabel = uilabel(app.FAVARplotPanel);
            app.PlotXLabel.Text = 'Plot X';
            app.plotX = uieditfield(app.FAVARplotPanel, 'text');
            app.PlotXshockLabel = uilabel(app.FAVARplotPanel);
            app.PlotXshockLabel.Text = 'PlotX shock';
            app.plotXshock = uieditfield(app.FAVARplotPanel, 'text');
            app.IRFt = uibuttongroup(app.GridLayout14);
            app.IRFt.SelectionChangedFcn = createCallbackFcn(app, @IRFtSelectionChanged, true);
            app.IRFt.Tooltip = {'IRFt'};
            app.IRFt.Title = 'Structural identifications';
            app.Noidentification = uiradiobutton(app.IRFt);
            app.Noidentification.Text = 'None';
            app.Choleskifactorisation = uiradiobutton(app.IRFt);
            app.Choleskifactorisation.Text = 'Cholesky';
            app.Triangularfactorisation = uiradiobutton(app.IRFt);
            app.Triangularfactorisation.Text = 'Triangular';
            app.Signrestrictions = uiradiobutton(app.IRFt);
            app.Signrestrictions.Text = 'Sign restrictions';
            app.Signrestrictions.Value = true;
            app.Proxy = uiradiobutton(app.IRFt);
            app.Proxy.Text = 'Proxy SVAR';
            app.Proxysign = uiradiobutton(app.IRFt);
            app.Proxysign.Text = 'Sign and proxy';
            % Show the figure after all components are created
            app.BEARinterfaceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BEARapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BEARinterfaceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BEARinterfaceUIFigure)
        end
    end
end